classdef Combat_Potential_Forces_3p4_ExTrident < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombatPotentialComputationUIFigure  matlab.ui.Figure
        TerrainMenu                     matlab.ui.container.Menu
        MobilityEffectMenu              matlab.ui.container.Menu
        DefenseEffectMenu               matlab.ui.container.Menu
        WeatherMenu                     matlab.ui.container.Menu
        MobilityMenu                    matlab.ui.container.Menu
        AttackPostureMenu               matlab.ui.container.Menu
        ORBATMenu                       matlab.ui.container.Menu
        CommandersIntentionMenu         matlab.ui.container.Menu
        LargeScaleAmphibiousOperationMenu  matlab.ui.container.Menu
        SCFASinAOAMenu                  matlab.ui.container.Menu
        LandingAmphibiousForcesbeforeTimeMenu  matlab.ui.container.Menu
        AttackbyLFCAFCMenu              matlab.ui.container.Menu
        LogisticSupportMenu             matlab.ui.container.Menu
        SupportHeliborneOperationMenu   matlab.ui.container.Menu
        DefeatInsurgentGroupMenu        matlab.ui.container.Menu
        JTFObjectivesMenu               matlab.ui.container.Menu
        ImproveDefPosturesMenu          matlab.ui.container.Menu
        DestroySafeSancturiesofANEMenu  matlab.ui.container.Menu
        AccomplishTheMissionby1stAugMenu  matlab.ui.container.Menu
        StopFurtherReinforcementMenu    matlab.ui.container.Menu
        ASWPlanningMenu                 matlab.ui.container.Menu
        FASandSeaControlMenu            matlab.ui.container.Menu
        UNReslownfavourMenu             matlab.ui.container.Menu
        MissionMenu                     matlab.ui.container.Menu
        CoursesOfActionsMenu            matlab.ui.container.Menu
        ArmyMenu                        matlab.ui.container.Menu
        GainIntelligenceMenu            matlab.ui.container.Menu
        UAVMenu                         matlab.ui.container.Menu
        SatelliteImageryMenu            matlab.ui.container.Menu
        OtherSourcesMenu                matlab.ui.container.Menu
        EmploymentofNGSMenu             matlab.ui.container.Menu
        SurpriseMenu                    matlab.ui.container.Menu
        OpsinDepthareasMenu             matlab.ui.container.Menu
        InductMaxTroopsinFirstPhaseMenu  matlab.ui.container.Menu
        CaptureAirfieldPortLinesofCommMenu  matlab.ui.container.Menu
        NavyMenu                        matlab.ui.container.Menu
        CATFsupportLFCMenu              matlab.ui.container.Menu
        DeceptionMenu                   matlab.ui.container.Menu
        ASWSanitizationMenu             matlab.ui.container.Menu
        NancowryIslandsMenu             matlab.ui.container.Menu
        VishakapatanamHarbourMenu       matlab.ui.container.Menu
        BreakoutOpsMenu                 matlab.ui.container.Menu
        MultithreatscreenMenu           matlab.ui.container.Menu
        EstofSweptChannelMenu           matlab.ui.container.Menu
        AirforceMenu                    matlab.ui.container.Menu
        AirControlinAOAMenu             matlab.ui.container.Menu
        InfDominanceMenu                matlab.ui.container.Menu
        SupportLFMenu                   matlab.ui.container.Menu
        EnhanceCPMenu                   matlab.ui.container.Menu
        EstablishAirheadMenu            matlab.ui.container.Menu
        BackgroundMenu                  matlab.ui.container.Menu
        NARRATIVEMenu                   matlab.ui.container.Menu
        PresentSituationMenu            matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        REDDropDownLabel                matlab.ui.control.Label
        REDDropDown                     matlab.ui.control.DropDown
        BMLabel                         matlab.ui.control.Label
        RedTankEditField                matlab.ui.control.NumericEditField
        CMLabel                         matlab.ui.control.Label
        RedAPCEditField                 matlab.ui.control.NumericEditField
        AircraftShelterLabel            matlab.ui.control.Label
        RedLRAntiarmrEditField          matlab.ui.control.NumericEditField
        RunwaysLabel                    matlab.ui.control.Label
        RedSRAntiarmrEditField          matlab.ui.control.NumericEditField
        MortarEditFieldLabel            matlab.ui.control.Label
        RedMortarEditField              matlab.ui.control.NumericEditField
        ArtyEditFieldLabel              matlab.ui.control.Label
        RedArtyEditField                matlab.ui.control.NumericEditField
        HelicopterEditFieldLabel        matlab.ui.control.Label
        RedHelicopterEditField          matlab.ui.control.NumericEditField
        FighterEditFieldLabel           matlab.ui.control.Label
        RedFighterEditField             matlab.ui.control.NumericEditField
        GroundAttackEditFieldLabel      matlab.ui.control.Label
        RedGroundAttackEditField        matlab.ui.control.NumericEditField
        UAVEditFieldLabel               matlab.ui.control.Label
        RedUAVEditField                 matlab.ui.control.NumericEditField
        Red_Tank_score                  matlab.ui.control.NumericEditField
        Red_APC_score                   matlab.ui.control.NumericEditField
        Red_LR_score                    matlab.ui.control.NumericEditField
        Red_SR_score                    matlab.ui.control.NumericEditField
        Red_Mortar_score                matlab.ui.control.NumericEditField
        Red_Arty_score                  matlab.ui.control.NumericEditField
        Red_Helicopter_score            matlab.ui.control.NumericEditField
        Red_Fighter_score               matlab.ui.control.NumericEditField
        Red_GroundAttack_score          matlab.ui.control.NumericEditField
        Red_UAV_score                   matlab.ui.control.NumericEditField
        AverageScoresLabel              matlab.ui.control.Label
        Red_pers_score                  matlab.ui.control.NumericEditField
        PersEditFieldLabel              matlab.ui.control.Label
        RedPersEditField                matlab.ui.control.NumericEditField
        TotalCombatPotentialLabel       matlab.ui.control.Label
        aa                              matlab.ui.control.NumericEditField
        COMPUTEButton                   matlab.ui.control.Button
        TerrainDropDownLabel            matlab.ui.control.Label
        TerrainDropDown                 matlab.ui.control.DropDown
        red_EqNComp_score               matlab.ui.control.NumericEditField
        EqNavalCompoLabel               matlab.ui.control.Label
        red_EqNavalC                    matlab.ui.control.NumericEditField
        Image                           matlab.ui.control.Image
        PostureDropDownLabel            matlab.ui.control.Label
        PostureDropDown                 matlab.ui.control.DropDown
        CenterPanel                     matlab.ui.container.Panel
        BLUEDropDownLabel               matlab.ui.control.Label
        BLUEDropDown                    matlab.ui.control.DropDown
        BMEditFieldLabel                matlab.ui.control.Label
        BMEditField                     matlab.ui.control.NumericEditField
        CMEditFieldLabel                matlab.ui.control.Label
        CMEditField                     matlab.ui.control.NumericEditField
        AircraftShelterEditFieldLabel   matlab.ui.control.Label
        AircraftShelterEditField        matlab.ui.control.NumericEditField
        RunwaysEditFieldLabel           matlab.ui.control.Label
        RunwaysEditField                matlab.ui.control.NumericEditField
        MortarEditField_2Label          matlab.ui.control.Label
        MortarEditField_2               matlab.ui.control.NumericEditField
        ArtyEditField_2Label            matlab.ui.control.Label
        ArtyEditField_2                 matlab.ui.control.NumericEditField
        TerrainDropDown_2Label          matlab.ui.control.Label
        TerrainDropDown_2               matlab.ui.control.DropDown
        Tank_blue_score                 matlab.ui.control.NumericEditField
        APC_blue_score                  matlab.ui.control.NumericEditField
        LR_blue_score                   matlab.ui.control.NumericEditField
        SR_blue_score                   matlab.ui.control.NumericEditField
        Mortar_blue_score               matlab.ui.control.NumericEditField
        Arty_blue_score                 matlab.ui.control.NumericEditField
        AverageScoresLabel_2            matlab.ui.control.Label
        TotalCombatPotentialLabel_2     matlab.ui.control.Label
        COMPUTEButton_2                 matlab.ui.control.Button
        HelicopterEditField_2Label      matlab.ui.control.Label
        HelicopterEditField_2           matlab.ui.control.NumericEditField
        FighterEditField_2Label         matlab.ui.control.Label
        FighterEditField_2              matlab.ui.control.NumericEditField
        GroundAttackEditField_2Label    matlab.ui.control.Label
        GroundAttackEditField_2         matlab.ui.control.NumericEditField
        UAVEditField_2Label             matlab.ui.control.Label
        UAVEditField_2                  matlab.ui.control.NumericEditField
        PersEditField_2Label            matlab.ui.control.Label
        PersEditField_2                 matlab.ui.control.NumericEditField
        Helicopter_blue_score           matlab.ui.control.NumericEditField
        Fighter_blue_score              matlab.ui.control.NumericEditField
        GroundAttack_blue_score         matlab.ui.control.NumericEditField
        UAV_blue_score                  matlab.ui.control.NumericEditField
        Pers_blue_score                 matlab.ui.control.NumericEditField
        bb                              matlab.ui.control.NumericEditField
        blue_EqNComp_score              matlab.ui.control.NumericEditField
        EqNavalCompoLabel_2             matlab.ui.control.Label
        blue_EqNavalC                   matlab.ui.control.NumericEditField
        Image2                          matlab.ui.control.Image
        PostureDropDown_2Label          matlab.ui.control.Label
        PostureDropDown_2               matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        Label                           matlab.ui.control.Label
        ForceRatioREDBLUEButton         matlab.ui.control.Button
        FR                              matlab.ui.control.NumericEditField
        ComputeAttritionRatesButton     matlab.ui.control.Button
        outputA                         matlab.ui.control.NumericEditField
        outputB                         matlab.ui.control.NumericEditField
        A                               matlab.ui.control.Label
        B                               matlab.ui.control.Label
        DefenderUIAxes                  matlab.ui.control.UIAxes
        AttackerUIAxes                  matlab.ui.control.UIAxes
        BattleTimetEditFieldLabel       matlab.ui.control.Label
        BattleTimetEditField            matlab.ui.control.NumericEditField
        RedLossEditFieldLabel           matlab.ui.control.Label
        Redloss                         matlab.ui.control.NumericEditField
        BlueLossEditFieldLabel          matlab.ui.control.Label
        Blueloss                        matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        AnalysisButton                  matlab.ui.control.Button
        MapButton                       matlab.ui.control.Button
        fig3UIAxes                      matlab.ui.control.UIAxes
        fig4UIAxes                      matlab.ui.control.UIAxes
        ABLabel                         matlab.ui.control.Label
        ABRatio                         matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CallingApp;
         DialogApp    ;    
         currentvalue;% Dialog box appLine 129: Argument 'app' is unused. Should this method be Static?
         force_strength;
         vulnerability;
         red_vulnerability_multiplier;
         red_force_strength_multiplier=1;
           blue_vulnerability_multiplier;
         blue_force_strength_multiplier=1;

%           CurrentSize = 35;           % Surface sample size
%         CurrentColormap = 'Parula'; % Colormap
      
    end
    
  methods (Access = public)
        
        function updatevalue(app,x)
%               app.ABRatio.Value=x;
%             app.FirstDialogEditField.Value=x;
%             app.OKButton.Enable='on'
            app.COMPUTEButton.Enable='on';
            app.aa.Value=x
        end
  end
    
    methods (Access = public)
        
        function  update_posture_factor_matrix(app,posture_factor)
         
           
            app.force_strength=double(posture_factor(:,2))
            app.vulnerability=double(posture_factor(:,3))
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                app.CallingApp=mainapp;
        end

        % Value changed function: REDDropDown
        function REDDropDownValueChanged(app, event)
            value = app.REDDropDown.Value;
            if(strcmp(value,'6th Mechanized Infantry Division'))
                app.RedTankEditField.Value=50;
                app.RedAPCEditField.Value=100;
                app.RedLRAntiarmrEditField.Value=0;
                app.RedSRAntiarmrEditField.Value=100;
                app.RedMortarEditField.Value=1000;
                app.RedArtyEditField.Value=150;
                app.RedHelicopterEditField.Value=10;
                app.RedFighterEditField.Value=20;
                app.RedGroundAttackEditField.Value=20;
                app.RedUAVEditField.Value=10;
                app.RedPersEditField.Value=13000;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
                app.Image.ImageSource='MIDiv.jpg';
             
            end
              if(strcmp(value,'6th Armour Regiment'))
                app.RedTankEditField.Value=45;
                app.RedAPCEditField.Value=10;
                app.RedLRAntiarmrEditField.Value=5;
                app.RedSRAntiarmrEditField.Value=5;
                app.RedMortarEditField.Value=0;
                app.RedArtyEditField.Value=0;
                app.RedHelicopterEditField.Value=0;
                app.RedFighterEditField.Value=0;
                app.RedGroundAttackEditField.Value=0;
                app.RedUAVEditField.Value=0;
                app.RedPersEditField.Value=1200;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
                 app.Image.ImageSource='RedArmourRgt.jpg';
              end
               if(strcmp(value,'17th Infantry Regiment'))
                app.RedTankEditField.Value=0;
                app.RedAPCEditField.Value=50;
                app.RedLRAntiarmrEditField.Value=100;
                app.RedSRAntiarmrEditField.Value=100;
                app.RedMortarEditField.Value=1000;
                app.RedArtyEditField.Value=0;
                app.RedHelicopterEditField.Value=2;
                app.RedFighterEditField.Value=0;
                app.RedGroundAttackEditField.Value=0;
                app.RedUAVEditField.Value=50;
                app.RedPersEditField.Value=2800;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
                  app.Image.ImageSource='17infRgt.jpg';
               end
               if(strcmp(value,'18th Artillery Regiment'))
                app.RedTankEditField.Value=0;
                app.RedAPCEditField.Value=10;
                app.RedLRAntiarmrEditField.Value=100;
                app.RedSRAntiarmrEditField.Value=100;
                app.RedMortarEditField.Value=100;
                app.RedArtyEditField.Value=200;
                app.RedHelicopterEditField.Value=0;
                app.RedFighterEditField.Value=0;
                app.RedGroundAttackEditField.Value=0;
                app.RedUAVEditField.Value=5;
                app.RedPersEditField.Value=1100;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
                  app.Image.ImageSource='18ArtyRgt.jpg';
            end
                if(strcmp(value,'Air Defense Regiment'))
                app.RedTankEditField.Value=5;
                app.RedAPCEditField.Value=10;
                app.RedLRAntiarmrEditField.Value=0;
                app.RedSRAntiarmrEditField.Value=0;
                app.RedMortarEditField.Value=1000;
                app.RedArtyEditField.Value=0;
                app.RedHelicopterEditField.Value=0;
                app.RedFighterEditField.Value=0;
                app.RedGroundAttackEditField.Value=0;
                app.RedUAVEditField.Value=5;
                app.RedPersEditField.Value=1000;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
                app.Image.ImageSource='ADRgt.jpg';
            end
                if(strcmp(value,'Joint Defending Task Group Level1'))
                app.RedTankEditField.Value=30;
                app.RedAPCEditField.Value=300;
                app.RedLRAntiarmrEditField.Value=50;
                app.RedSRAntiarmrEditField.Value=50;
                app.RedMortarEditField.Value=500;
                app.RedArtyEditField.Value=50;
                app.RedHelicopterEditField.Value=10;
                app.RedFighterEditField.Value=8;
                app.RedGroundAttackEditField.Value=10;
                app.RedUAVEditField.Value=5;
                app.RedPersEditField.Value=2000;
                app.red_EqNavalC.Value=0;
                app.TerrainDropDown.Value='Open'
            end

        end

        % Value changed function: TerrainDropDown
        function TerrainDropDownValueChanged(app, event)
            value = app.TerrainDropDown.Value;
            if(strcmp(value,'Open'))
                app.RedTankEditField.Value=app.RedTankEditField.Value;
                app.RedTankEditField.Value=app.RedTankEditField.Value;
                app.RedAPCEditField.Value=app.RedAPCEditField.Value;
                app.RedLRAntiarmrEditField.Value=app.RedLRAntiarmrEditField.Value;
                app.RedSRAntiarmrEditField.Value=app.RedSRAntiarmrEditField.Value;
                app.RedMortarEditField.Value=app.RedMortarEditField.Value;
                app.RedArtyEditField.Value=app.RedArtyEditField.Value;
                app.RedHelicopterEditField.Value=app.RedHelicopterEditField.Value;
                app.RedFighterEditField.Value=app.RedFighterEditField.Value;
                app.RedGroundAttackEditField.Value=app.RedGroundAttackEditField.Value;
                app.RedUAVEditField.Value=app.RedUAVEditField.Value;
                app.RedPersEditField.Value=app.RedPersEditField.Value;

            end
             if(strcmp(value,'Urban'))
                app.RedTankEditField.Value=app.RedTankEditField.Value*.75;
                app.RedTankEditField.Value=app.RedTankEditField.Value*.75;
                app.RedAPCEditField.Value=app.RedAPCEditField.Value*.75;
                app.RedLRAntiarmrEditField.Value=app.RedLRAntiarmrEditField.Value;
                app.RedSRAntiarmrEditField.Value=app.RedSRAntiarmrEditField.Value;
                app.RedMortarEditField.Value=app.RedMortarEditField.Value;
                app.RedArtyEditField.Value=app.RedArtyEditField.Value;
                app.RedHelicopterEditField.Value=app.RedHelicopterEditField.Value*.75;
                app.RedFighterEditField.Value=app.RedFighterEditField.Value;
                app.RedGroundAttackEditField.Value=app.RedGroundAttackEditField.Value;
                app.RedUAVEditField.Value=app.RedUAVEditField.Value;
                app.RedPersEditField.Value=app.RedPersEditField.Value;


             end
              if(strcmp(value,'Rough'))
                app.RedTankEditField.Value=app.RedTankEditField.Value*.5;
                app.RedTankEditField.Value=app.RedTankEditField.Value*.5;
                app.RedAPCEditField.Value=app.RedAPCEditField.Value*.5;
                app.RedLRAntiarmrEditField.Value=app.RedLRAntiarmrEditField.Value;
                app.RedSRAntiarmrEditField.Value=app.RedSRAntiarmrEditField.Value;
                app.RedMortarEditField.Value=app.RedMortarEditField.Value;
                app.RedArtyEditField.Value=app.RedArtyEditField.Value;
                app.RedHelicopterEditField.Value=app.RedHelicopterEditField.Value*.5;
                app.RedFighterEditField.Value=app.RedFighterEditField.Value;
                app.RedGroundAttackEditField.Value=app.RedGroundAttackEditField.Value;
                app.RedUAVEditField.Value=app.RedUAVEditField.Value;
                app.RedPersEditField.Value=app.RedPersEditField.Value;

                
              end
               if(strcmp(value,'Mountain'))
                app.RedTankEditField.Value=app.RedTankEditField.Value*.25;
                app.RedTankEditField.Value=app.RedTankEditField.Value*.25;
                app.RedAPCEditField.Value=app.RedAPCEditField.Value*.25;
                app.RedLRAntiarmrEditField.Value=app.RedLRAntiarmrEditField.Value;
                app.RedSRAntiarmrEditField.Value=app.RedSRAntiarmrEditField.Value;
                app.RedMortarEditField.Value=app.RedMortarEditField.Value;
                app.RedArtyEditField.Value=app.RedArtyEditField.Value;
                app.RedHelicopterEditField.Value=app.RedHelicopterEditField.Value*.25;
                app.RedFighterEditField.Value=app.RedFighterEditField.Value;
                app.RedGroundAttackEditField.Value=app.RedGroundAttackEditField.Value;
                app.RedUAVEditField.Value=app.RedUAVEditField.Value;
                app.RedPersEditField.Value=app.RedPersEditField.Value;


             end
        end

        % Value changed function: BLUEDropDown
        function BLUEDropDownValueChanged(app, event)
            value = app.BLUEDropDown.Value;


            if(strcmp(value,'T-72 Tank Brigade'))
                app.BMEditField.Value=150;
                app.CMEditField.Value=50;
                app.AircraftShelterEditField.Value=0;
                app.RunwaysEditField.Value=0;
                app.MortarEditField_2.Value=0;
                app.ArtyEditField_2.Value=0;
                app.HelicopterEditField_2.Value=0;
                app.FighterEditField_2.Value=0;
                app.GroundAttackEditField_2.Value=0;
                app.UAVEditField_2.Value=0;
                app.PersEditField_2.Value=3000;
                app.blue_EqNavalC.Value=0;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='T72bgd.jpg'
            end
              if(strcmp(value,'8th Mountain Division'))
                app.BMEditField.Value=0;
                app.CMEditField.Value=5;
                app.AircraftShelterEditField.Value=1000;
                app.RunwaysEditField.Value=1000;
                app.MortarEditField_2.Value=1000;
                app.ArtyEditField_2.Value=0;
                app.HelicopterEditField_2.Value=2;
                app.FighterEditField_2.Value=0;
                app.GroundAttackEditField_2.Value=0;
                app.UAVEditField_2.Value=2;
                app.PersEditField_2.Value=15500;
                app.blue_EqNavalC.Value=0;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='8MountaininfDiv.jpg'
              end
               if(strcmp(value,'3rd Infantry Division'))
                app.BMEditField.Value=0;
                app.CMEditField.Value=5;
                app.AircraftShelterEditField.Value=800;
                app.RunwaysEditField.Value=800;
                app.MortarEditField_2.Value=800;
                app.ArtyEditField_2.Value=0;
                app.HelicopterEditField_2.Value=0;
                app.FighterEditField_2.Value=0;
                app.GroundAttackEditField_2.Value=0;
                app.UAVEditField_2.Value=0;
                app.PersEditField_2.Value=15500;
                app.blue_EqNavalC.Value=0;
                app.TerrainDropDown_2.Value='Open'
                 app.Image2.ImageSource='3InfDiv.jpg'
               end
               if(strcmp(value,'Leh AFS'))
                app.BMEditField.Value=350;
                app.CMEditField.Value=3300;
                app.AircraftShelterEditField.Value=3100;
                app.RunwaysEditField.Value=3100;
                app.MortarEditField_2.Value=310;
                app.ArtyEditField_2.Value=1350;
                app.HelicopterEditField_2.Value=130;
                app.FighterEditField_2.Value=8;
                app.GroundAttackEditField_2.Value=320;
                app.UAVEditField_2.Value=310;
                app.PersEditField_2.Value=13000;
                app.blue_EqNavalC.Value=0;
                app.TerrainDropDown_2.Value='Open'
                 app.Image2.ImageSource='LehAfs.jpg'
            end
                if(strcmp(value,'Joint Attacking Task Group Level3'))
                app.BMEditField.Value=50;
                app.CMEditField.Value=50;
                app.AircraftShelterEditField.Value=500;
                app.RunwaysEditField.Value=500;
                app.MortarEditField_2.Value=500;
                app.ArtyEditField_2.Value=0;
                app.HelicopterEditField_2.Value=25;
                app.FighterEditField_2.Value=20;
                app.GroundAttackEditField_2.Value=20;
                app.UAVEditField_2.Value=20;
                app.PersEditField_2.Value=15000;
                app.blue_EqNavalC.Value=0;
                app.TerrainDropDown_2.Value='Open'
            end
                
        end

        % Value changed function: TerrainDropDown_2
        function TerrainDropDown_2ValueChanged(app, event)
            value = app.TerrainDropDown_2.Value;
            if(strcmp(value,'Open'))
                app.BMEditField.Value=app.BMEditField.Value;
                app.CMEditField.Value=app.CMEditField.Value;
                app.AircraftShelterEditField.Value=app.AircraftShelterEditField.Value;
                app.RunwaysEditField.Value=app.RunwaysEditField.Value;
                app.MortarEditField_2.Value=app.MortarEditField_2.Value;
                app.ArtyEditField_2.Value=app.ArtyEditField_2.Value;
                app.HelicopterEditField_2.Value=app.HelicopterEditField_2.Value;
                app.FighterEditField_2.Value=app.FighterEditField_2.Value;
                app.GroundAttackEditField_2.Value=app.GroundAttackEditField_2.Value;
                app.UAVEditField_2.Value=app.UAVEditField_2.Value;
                app.PersEditField_2.Value=app.PersEditField_2.Value;

            end
             if(strcmp(value,'Urban'))
               app.BMEditField.Value=app.BMEditField.Value*.75;
                app.CMEditField.Value=app.CMEditField.Value*.75;
                app.AircraftShelterEditField.Value=app.AircraftShelterEditField.Value*.75;
                app.RunwaysEditField.Value=app.RunwaysEditField.Value;
                app.MortarEditField_2.Value=app.MortarEditField_2.Value;
                app.ArtyEditField_2.Value=app.ArtyEditField_2.Value;
                app.HelicopterEditField_2.Value=app.HelicopterEditField_2.Value*.75;
                app.FighterEditField_2.Value=app.FighterEditField_2.Value;
                app.GroundAttackEditField_2.Value=app.GroundAttackEditField_2.Value;
                app.UAVEditField_2.Value=app.UAVEditField_2.Value;
                app.PersEditField_2.Value=app.PersEditField_2.Value;


             end
              if(strcmp(value,'Rough'))
                 app.BMEditField.Value=app.BMEditField.Value*.5;
                app.CMEditField.Value=app.CMEditField.Value*.5;
                app.AircraftShelterEditField.Value=app.AircraftShelterEditField.Value*.5;
                app.RunwaysEditField.Value=app.RunwaysEditField.Value;
                app.MortarEditField_2.Value=app.MortarEditField_2.Value;
                app.ArtyEditField_2.Value=app.ArtyEditField_2.Value;
                app.HelicopterEditField_2.Value=app.HelicopterEditField_2.Value*.5;
                app.FighterEditField_2.Value=app.FighterEditField_2.Value;
                app.GroundAttackEditField_2.Value=app.GroundAttackEditField_2.Value;
                app.UAVEditField_2.Value=app.UAVEditField_2.Value;
                app.PersEditField_2.Value=app.PersEditField_2.Value;

                
              end
               if(strcmp(value,'Mountain'))
                app.BMEditField.Value=app.BMEditField.Value*.25;
                app.CMEditField.Value=app.CMEditField.Value*.25;
                app.AircraftShelterEditField.Value=app.AircraftShelterEditField.Value*.25;
                app.RunwaysEditField.Value=app.RunwaysEditField.Value;
                app.MortarEditField_2.Value=app.MortarEditField_2.Value;
                app.ArtyEditField_2.Value=app.ArtyEditField_2.Value;
                app.HelicopterEditField_2.Value=app.HelicopterEditField_2.Value*.25;
                app.FighterEditField_2.Value=app.FighterEditField_2.Value;
                app.GroundAttackEditField_2.Value=app.GroundAttackEditField_2.Value;
                app.UAVEditField_2.Value=app.UAVEditField_2.Value;
                app.PersEditField_2.Value=app.PersEditField_2.Value;


             end
        end

        % Callback function
        function RedToatlCombatScoreEditFieldValueChanged(app, event)
            value = app.RedToatlCombatScoreEditField.Value;
             
        end

        % Callback function
        function ComputeButtonPushed(app, event)
           
        end

        % Callback function
        function COMPUTEButtonPushed(app, event)
                   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CombatPotentialComputationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {715, 715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x', 518};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: COMPUTEButton_2
        function COMPUTEButton_2Pushed(app, event)
            app.bb.Value=sum(app.BMEditField.Value*app.Tank_blue_score.Value+...
                                                           app.CMEditField.Value*app.APC_blue_score.Value+...
                                                           app.AircraftShelterEditField.Value*app.LR_blue_score.Value+...
                                                           app.RunwaysEditField.Value*app.SR_blue_score.Value+...
                                                           app.MortarEditField_2.Value*app.Mortar_blue_score.Value+...
                                                           app.ArtyEditField_2.Value*app.Arty_blue_score.Value+...
                                                           app.HelicopterEditField_2.Value*app.Helicopter_blue_score.Value+...
                                                           app.FighterEditField_2.Value*app.Fighter_blue_score.Value+...
                                                           app.GroundAttackEditField_2.Value*app.GroundAttack_blue_score.Value+...
                                                           app.UAVEditField_2.Value*app.UAV_blue_score.Value+...
                                                           app.PersEditField_2.Value*app.Pers_blue_score.Value+...
                                                           app.blue_EqNavalC.Value*app.blue_EqNComp_score.Value)
                         app.bb.Value=app.bb.Value*app.blue_force_strength_multiplier;                              
        end

        % Button pushed function: COMPUTEButton
        function COMPUTEButtonPushed2(app, event)
            app.aa.Value=sum(app.RedTankEditField.Value*app.Red_Tank_score.Value+...
                                                           app.RedAPCEditField.Value*app.Red_APC_score.Value+...
                                                           app.RedLRAntiarmrEditField.Value*app.Red_LR_score.Value+...
                                                           app.RedSRAntiarmrEditField.Value*app.Red_SR_score.Value+...
                                                           app.RedMortarEditField.Value*app.Red_Mortar_score.Value+...
                                                           app.RedArtyEditField.Value*app.Red_Arty_score.Value+...
                                                           app.RedHelicopterEditField.Value*app.Red_Helicopter_score.Value+...
                                                           app.RedFighterEditField.Value*app.Red_Fighter_score.Value+...
                                                           app.RedGroundAttackEditField.Value*app.Red_GroundAttack_score.Value+...
                                                           app.RedUAVEditField.Value*app.Red_UAV_score.Value+...
                                                           app.RedPersEditField.Value*app.Red_pers_score.Value+...
                                                           app.red_EqNavalC.Value*app.red_EqNComp_score.Value)
            app.aa.Value=app.aa.Value*app.red_force_strength_multiplier;
        end

        % Button pushed function: ForceRatioREDBLUEButton
        function ForceRatioREDBLUEButtonPushed(app, event)
            app.FR.Value=app.aa.Value/app.bb.Value;
        end

        % Button pushed function: ComputeAttritionRatesButton
        function ComputeAttritionRatesButtonPushed(app, event)
            
            red_loss=app.Redloss.Value;
            blue_loss=app.Blueloss.Value; 
            x0=app.aa.Value;
            
%             xt=red_loss;
%             yt=blue_loss;
            if(red_loss<100)
              xt=x0*(1-(red_loss/100));
            else
              xt=x0*(.99999);
            end
%             
            y0=app.bb.Value;
            if(blue_loss<100)
                yt=y0*(1-(blue_loss/100));
            else
                yt=y0*(.999999);
            end


            F=(xt*sqrt(y0^2-yt^2)+yt*sqrt(x0^2-xt^2))/(x0*sqrt(y0^2-yt^2)+y0*sqrt(x0^2-xt^2));
            t=app.BattleTimetEditField.Value;

            app.outputA.Value=((sqrt(x0^2-xt^2))/(t*sqrt(y0^2-yt^2)))*log(F);
            app.outputB.Value=((sqrt(y0^2-yt^2))/(t*sqrt(x0^2-xt^2)))*log(F);
            A=app.outputA.Value;
            B=app.outputB.Value;
          app.ABRatio.Value=A/B;

xx=[];
yy=[];
FR=[];
count=0;
for i=1:.5:t
    x=x0*cosh(sqrt(A*B)*i)-y0*sqrt(A/B)*sinh(sqrt(A*B)*i);

    
    xx=[xx;x];
    y=y0*cosh(sqrt(A*B)*i)-x0*sqrt(B/A)*sinh(sqrt(A*B)*i);
    yy=[yy;y];
 plot(app.DefenderUIAxes,(1:.5:i)',xx,'r',(1:.5:i)',yy,'b')
  FR=[FR;x/y];
  count=count+1;
 
    if(FR(1)>FR(count))
               plot(app.AttackerUIAxes,(1:.5:i)',FR,'b')
                legend(app.AttackerUIAxes,{'Blue Wining'},'Location','Best')
            elseif(FR(1)==FR(count))
               plot(app.AttackerUIAxes,(1:.5:i)',FR,'g')
               legend(app.AttackerUIAxes,{'Draw'},'Location','Best')
            else
                plot(app.AttackerUIAxes,(1:.5:i)',FR,'r')
                legend(app.AttackerUIAxes,{'Red Wining'},'Location','Best')
            end
 pause(.1)


end
  
xx
yy
FR
% xx(1)=0;
for i=2:1:size(xx)
    cas_x(i)=xx(i-1)-xx(i);
    cas_y(i)=yy(i-1)-yy(i);
end
% cas'
temp=[xx,cas_x',yy,cas_y',FR]
%  plot(app.DefenderUIAxes,(1:.5:t)',xx,'r',(1:.5:t)',yy,'b')


%  legend(app.DefenderUIAxes,{'Blue','Red'},'Location','Best')
%             xlim(app.DefenderUIAxes,[1 t]); 
%             if(FR(1)>FR(end))
%                plot(app.AttackerUIAxes,(1:.5:t)',FR,'b')
%                 legend(app.AttackerUIAxes,{'Blue Wining'},'Location','Best')
%             elseif(FR(1)==FR(end))
%                plot(app.AttackerUIAxes,(1:.5:t)',FR,'g')
%                legend(app.AttackerUIAxes,{'Draw'},'Location','Best')
%             else
%                 plot(app.AttackerUIAxes,(1:.5:t)',FR,'r')
%                 legend(app.AttackerUIAxes,{'Red Wining'},'Location','Best')
%             end
%     legend(app.AttackerUIAxes,{'Attacker'},'Location','Best')
%             xlim(app.DefenderUIAxes,[1 t]); 
            
x=temp(:,1:4);
B=x(:,1);
dB=x(:,2);
R=x(:,3);
dR=x(:,4);
a=-15;
b=-15;
[P Q]=meshgrid(a:.099:15,b:.099:15);
[ka kb]=size(P);

 SSR_est=zeros(ka,kb);
 temp=zeros(ka,kb);
d_est=1;
 for k1=1:1:ka
     for l1=1:1:kb
         
        p=(a+.1*(k1-1));
        q=(b+.1*(l1-1));
       
 a_est=sum(dB)./sum((R.^p).*(B.^q));
        a_est=mean(a_est);
        
        b_est=sum(dB)./sum((R.^q).*(B.^p));
        b_est=mean(b_est);
        
            temp2=0; 
            temp1=0;
        for i=1:1:size(x)
            
            temp1=(dB(i)-a_est*(R(i)^p)*(B(i)^q))^2+(dR(i)-b_est*(1/d_est)*(B(i)^p)*(R(i).^q))^2 ;
           
          temp2=temp2+temp1;
        end
        SSR_est(k1,l1)=temp2;
     end
 end

mesh(app.fig4UIAxes,P,Q,SSR_est);

% figure,[C,h]=contourf(app.fig3UIAxes,Q,SSR_est);
contour(app.fig3UIAxes,P,Q,SSR_est);

% text_handle = clabel(C,h); 
         
        end

        % Close request function: CombatPotentialComputationUIFigure
        function CombatPotentialComputationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
%          app.AnalysisButton.Enable = 'off'; 
         blEq=sum(app.BMEditField.Value*app.Tank_blue_score.Value+...
                                                           app.CMEditField.Value*app.APC_blue_score.Value+...
                                                           app.AircraftShelterEditField.Value*app.LR_blue_score.Value+...
                                                           app.RunwaysEditField.Value*app.SR_blue_score.Value+...
                                                           app.MortarEditField_2.Value*app.Mortar_blue_score.Value+...
                                                           app.ArtyEditField_2.Value*app.Arty_blue_score.Value+...
                                                           app.PersEditField_2.Value*app.Pers_blue_score.Value)...
                                                           /app.Tank_blue_score.Value;
        baEq=sum(app.HelicopterEditField_2.Value*app.Helicopter_blue_score.Value+...
                                                           app.FighterEditField_2.Value*app.Fighter_blue_score.Value+...
                                                           app.GroundAttackEditField_2.Value*app.GroundAttack_blue_score.Value+...
                                                           app.UAVEditField_2.Value*app.UAV_blue_score.Value)...
                                                           /app.Fighter_blue_score.Value;
           bNEq=app.blue_EqNavalC.Value*app.blue_EqNComp_score.Value ;                                      
           bpers= app.PersEditField_2.Value*app.Pers_blue_score.Value;
          
           
           rlEq=sum(app.RedTankEditField.Value*app.Red_Tank_score.Value+...
                                                           app.RedAPCEditField.Value*app.Red_APC_score.Value+...
                                                           app.RedLRAntiarmrEditField.Value*app.Red_LR_score.Value+...
                                                           app.RedSRAntiarmrEditField.Value*app.Red_SR_score.Value+...
                                                           app.RedMortarEditField.Value*app.Red_Mortar_score.Value+...
                                                           app.RedArtyEditField.Value*app.Red_Arty_score.Value+...
                                                           app.RedPersEditField.Value*app.Red_pers_score.Value)...
                                                           /app.Red_Tank_score.Value
            raEq=sum(app.RedHelicopterEditField.Value*app.Red_Helicopter_score.Value+...
                                                           app.RedFighterEditField.Value*app.Red_Fighter_score.Value+...
                                                           app.RedGroundAttackEditField.Value*app.Red_GroundAttack_score.Value+...
                                                           app.RedUAVEditField.Value*app.Red_UAV_score.Value)...
                                                           /app.Red_Helicopter_score.Value
           rNEq=app.red_EqNavalC.Value*app.red_EqNComp_score.Value                                      
           rpers= app.RedPersEditField.Value*app.Red_pers_score.Value;


            app.DialogApp = Variable_resolution_model4(app,app.aa.Value,app.Redloss.Value,app.bb.Value,app.Blueloss.Value,app.BattleTimetEditField.Value,baEq,blEq,bNEq,raEq,rlEq,rNEq);
%             app.DialogApp = Variable_resolution_model4(app,app.aa.Value,app.Redloss.Value,app.bb.Value,app.Blueloss.Value,app.BattleTimetEditField.Value,10,20,30);
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            app.DialogApp=ExTrident_scenario(app)
%            if(strcmp(app.PostureDropDown.Value,"Attack") && ~strcmp(app.PostureDropDown_2.Value,"Attack"))
%             if(strcmp(app.REDDropDown.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDDropDown.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDDropDown.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDDropDown.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDDropDown.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%             end
%           elseif(~strcmp(app.PostureDropDown.Value,"Attack") && strcmp(app.PostureDropDown_2.Value,"Attack"))
%                if(strcmp(app.REDDropDown.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDDropDown.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDDropDown.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDDropDown.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDDropDown.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%                end
%           elseif(~strcmp(app.PostureDropDown.Value,"Attack") &&  ~strcmp(app.PostureDropDown_2.Value,"Attack"))
%               if(strcmp(app.REDDropDown.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDDropDown.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDDropDown.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDDropDown.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDDropDown.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%               end
%           elseif(strcmp(app.PostureDropDown.Value,"Attack") &&  strcmp(app.PostureDropDown_2.Value,"Attack"))
%               if(strcmp(app.REDDropDown.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDDropDown.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDDropDown.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDDropDown.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDDropDown.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUEDropDown.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUEDropDown.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUEDropDown.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%             end
%           end
        end

        % Menu selected function: AttackPostureMenu
        function AttackPostureMenuSelected(app, event)
            app.DialogApp=posture_factor(app)
        end

        % Value changed function: PostureDropDown
        function PostureDropDownValueChanged(app, event)
%             value = app.PostureDropDown.Value;
            u=app.force_strength;
            v=app.vulnerability  ;          
            if(strcmp(app.PostureDropDown.Value,"Attack"))
                app.red_force_strength_multiplier=u(1);
                app.red_vulnerability_multiplier=v(1);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Hasty)"))
                 app.red_force_strength_multiplier=u(2);
                 app.red_vulnerability_multiplier=v(2);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Prepared)"))
                 app.red_force_strength_multiplier=u(3);
                 app.red_vulnerability_multiplier=v(3);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Fortified)"))
                 app.red_force_strength_multiplier=u(4);
                 app.red_vulnerability_multiplier=v(4);
            elseif(strcmp(app.PostureDropDown.Value,"Withdrawal"))
                 app.red_force_strength_multiplier=u(5);
                 app.red_vulnerability_multiplier=v(5);
            elseif(strcmp(app.PostureDropDown.Value,"Delay"))
                 app.red_force_strength_multiplier=u(6);
                 app.red_vulnerability_multiplier=v(6);
            end
       
        end

        % Value changed function: PostureDropDown_2
        function PostureDropDown_2ValueChanged(app, event)
            value = app.PostureDropDown_2.Value;
  u=app.force_strength;
            v=app.vulnerability  ;          
            if(strcmp(app.PostureDropDown_2.Value,"Attack"))
                app.blue_force_strength_multiplier=u(1);
                app.blue_vulnerability_multiplier=v(1);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Hasty)"))
                 app.blue_force_strength_multiplier=u(2);
                 app.blue_vulnerability_multiplier=v(2);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Prepared)"))
                 app.blue_force_strength_multiplier=u(3);
                 app.blue_vulnerability_multiplier=v(3);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Fortified)"))
                 app.blue_force_strength_multiplier=u(4);
                 app.blue_vulnerability_multiplier=v(4);
            elseif(strcmp(app.PostureDropDown_2.Value,"Withdrawal"))
                 app.blue_force_strength_multiplier=u(5);
                 app.blue_vulnerability_multiplier=v(5);
            elseif(strcmp(app.PostureDropDown_2.Value,"Delay"))
                 app.blue_force_strength_multiplier=u(6);
                 app.blue_vulnerability_multiplier=v(6);
            end
       
        end

        % Menu selected function: ORBATMenu
        function ORBATMenuSelected(app, event)
            app.DialogApp=ORBAT(app);
        end

        % Menu selected function: BackgroundMenu
        function BackgroundMenuSelected(app, event)
            app.DialogApp=Background_extrident(app);
        end

        % Menu selected function: NARRATIVEMenu
        function NARRATIVEMenuSelected(app, event)
            app.DialogApp=NARRATIVE_extrident(app);
        end

        % Menu selected function: PresentSituationMenu
        function PresentSituationMenuSelected(app, event)
            app.DialogApp=PresentSituation_extrident(app);
        end

        % Menu selected function: MissionMenu
        function MissionMenuSelected(app, event)
            app.DialogApp=mission_extrident(app);
        end

        % Menu selected function: UAVMenu
        function UAVMenuSelected(app, event)
            app.DialogApp=UAV_intel(app);
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            
        end

        % Menu selected function: SatelliteImageryMenu
        function SatelliteImageryMenuSelected(app, event)
            app.DialogApp=sat_intell(app);
        end

        % Menu selected function: EmploymentofNGSMenu
        function EmploymentofNGSMenuSelected(app, event)
            app.DialogApp=emp_NGS(app);
        end

        % Menu selected function: SCFASinAOAMenu
        function SCFASinAOAMenuSelected(app, event)
            app.DialogApp=SC_FAS_AOA_map(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombatPotentialComputationUIFigure = uifigure('Visible', 'off');
            app.CombatPotentialComputationUIFigure.Name = 'Combat Potential Computation';
            app.CombatPotentialComputationUIFigure.CloseRequestFcn = createCallbackFcn(app, @CombatPotentialComputationUIFigureCloseRequest, true);
            app.CombatPotentialComputationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.TerrainMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.TerrainMenu.Text = 'Terrain';
            app.MobilityEffectMenu = uimenu(app.TerrainMenu);
            app.MobilityEffectMenu.Text = 'Mobility Effect';
            app.DefenseEffectMenu = uimenu(app.TerrainMenu);
            app.DefenseEffectMenu.Text = 'Defense Effect';
            app.WeatherMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.WeatherMenu.Text = 'Weather';
            app.MobilityMenu = uimenu(app.WeatherMenu);
            app.MobilityMenu.Text = 'Mobility';
            app.AttackPostureMenu = uimenu(app.WeatherMenu);
            app.AttackPostureMenu.MenuSelectedFcn = createCallbackFcn(app, @AttackPostureMenuSelected, true);
            app.AttackPostureMenu.Text = 'Attack Posture';
            app.ORBATMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.ORBATMenu.MenuSelectedFcn = createCallbackFcn(app, @ORBATMenuSelected, true);
            app.ORBATMenu.Text = 'ORBAT';
            app.CommandersIntentionMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.CommandersIntentionMenu.Text = 'Commanders Intention';
            app.LargeScaleAmphibiousOperationMenu = uimenu(app.CommandersIntentionMenu);
            app.LargeScaleAmphibiousOperationMenu.Text = 'Large Scale Amphibious Operation';
            app.SCFASinAOAMenu = uimenu(app.LargeScaleAmphibiousOperationMenu);
            app.SCFASinAOAMenu.MenuSelectedFcn = createCallbackFcn(app, @SCFASinAOAMenuSelected, true);
            app.SCFASinAOAMenu.Text = 'SC &FAS in AOA ';
            app.LandingAmphibiousForcesbeforeTimeMenu = uimenu(app.LargeScaleAmphibiousOperationMenu);
            app.LandingAmphibiousForcesbeforeTimeMenu.Text = 'Landing Amphibious Forces before Time';
            app.AttackbyLFCAFCMenu = uimenu(app.LargeScaleAmphibiousOperationMenu);
            app.AttackbyLFCAFCMenu.Text = 'Attack by LFC & AFC';
            app.LogisticSupportMenu = uimenu(app.LargeScaleAmphibiousOperationMenu);
            app.LogisticSupportMenu.Text = 'Logistic Support';
            app.SupportHeliborneOperationMenu = uimenu(app.LargeScaleAmphibiousOperationMenu);
            app.SupportHeliborneOperationMenu.Text = 'Support Heliborne Operation';
            app.DefeatInsurgentGroupMenu = uimenu(app.CommandersIntentionMenu);
            app.DefeatInsurgentGroupMenu.Text = 'Defeat Insurgent Group';
            app.JTFObjectivesMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.JTFObjectivesMenu.Text = 'JTF Objectives';
            app.ImproveDefPosturesMenu = uimenu(app.JTFObjectivesMenu);
            app.ImproveDefPosturesMenu.Text = 'Improve Def Postures';
            app.DestroySafeSancturiesofANEMenu = uimenu(app.JTFObjectivesMenu);
            app.DestroySafeSancturiesofANEMenu.Text = 'Destroy Safe Sancturies of ANE';
            app.AccomplishTheMissionby1stAugMenu = uimenu(app.JTFObjectivesMenu);
            app.AccomplishTheMissionby1stAugMenu.Text = 'Accomplish The Mission by 1st Aug';
            app.StopFurtherReinforcementMenu = uimenu(app.JTFObjectivesMenu);
            app.StopFurtherReinforcementMenu.Text = 'Stop Further Reinforcement';
            app.ASWPlanningMenu = uimenu(app.JTFObjectivesMenu);
            app.ASWPlanningMenu.Text = 'ASW Planning';
            app.FASandSeaControlMenu = uimenu(app.JTFObjectivesMenu);
            app.FASandSeaControlMenu.Text = 'FAS and Sea Control';
            app.UNReslownfavourMenu = uimenu(app.JTFObjectivesMenu);
            app.UNReslownfavourMenu.Text = 'UN Resl own favour';
            app.MissionMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.MissionMenu.MenuSelectedFcn = createCallbackFcn(app, @MissionMenuSelected, true);
            app.MissionMenu.Text = 'Mission';
            app.CoursesOfActionsMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.CoursesOfActionsMenu.Text = 'Courses Of Actions';
            app.ArmyMenu = uimenu(app.CoursesOfActionsMenu);
            app.ArmyMenu.Text = 'Army';
            app.GainIntelligenceMenu = uimenu(app.ArmyMenu);
            app.GainIntelligenceMenu.Text = 'Gain Intelligence';
            app.UAVMenu = uimenu(app.GainIntelligenceMenu);
            app.UAVMenu.MenuSelectedFcn = createCallbackFcn(app, @UAVMenuSelected, true);
            app.UAVMenu.Text = 'UAV';
            app.SatelliteImageryMenu = uimenu(app.GainIntelligenceMenu);
            app.SatelliteImageryMenu.MenuSelectedFcn = createCallbackFcn(app, @SatelliteImageryMenuSelected, true);
            app.SatelliteImageryMenu.Text = 'Satellite Imagery';
            app.OtherSourcesMenu = uimenu(app.GainIntelligenceMenu);
            app.OtherSourcesMenu.Text = 'Other Sources';
            app.EmploymentofNGSMenu = uimenu(app.ArmyMenu);
            app.EmploymentofNGSMenu.MenuSelectedFcn = createCallbackFcn(app, @EmploymentofNGSMenuSelected, true);
            app.EmploymentofNGSMenu.Text = 'Employment of NGS';
            app.SurpriseMenu = uimenu(app.ArmyMenu);
            app.SurpriseMenu.Text = 'Surprise';
            app.OpsinDepthareasMenu = uimenu(app.ArmyMenu);
            app.OpsinDepthareasMenu.Text = 'Ops in Depth areas';
            app.InductMaxTroopsinFirstPhaseMenu = uimenu(app.ArmyMenu);
            app.InductMaxTroopsinFirstPhaseMenu.Text = 'Induct Max Troops in First Phase';
            app.CaptureAirfieldPortLinesofCommMenu = uimenu(app.ArmyMenu);
            app.CaptureAirfieldPortLinesofCommMenu.Text = 'Capture Airfield, Port & Lines of Comm';
            app.NavyMenu = uimenu(app.CoursesOfActionsMenu);
            app.NavyMenu.Text = 'Navy';
            app.CATFsupportLFCMenu = uimenu(app.NavyMenu);
            app.CATFsupportLFCMenu.Text = 'CATF support LFC';
            app.DeceptionMenu = uimenu(app.NavyMenu);
            app.DeceptionMenu.Text = 'Deception';
            app.ASWSanitizationMenu = uimenu(app.NavyMenu);
            app.ASWSanitizationMenu.Text = 'ASW Sanitization';
            app.NancowryIslandsMenu = uimenu(app.ASWSanitizationMenu);
            app.NancowryIslandsMenu.Text = 'Nancowry Islands';
            app.VishakapatanamHarbourMenu = uimenu(app.ASWSanitizationMenu);
            app.VishakapatanamHarbourMenu.Text = 'Vishakapatanam Harbour';
            app.BreakoutOpsMenu = uimenu(app.NavyMenu);
            app.BreakoutOpsMenu.Text = 'Breakout Ops';
            app.MultithreatscreenMenu = uimenu(app.NavyMenu);
            app.MultithreatscreenMenu.Text = 'Multi threat screen';
            app.EstofSweptChannelMenu = uimenu(app.NavyMenu);
            app.EstofSweptChannelMenu.Text = 'Est of Swept Channel';
            app.AirforceMenu = uimenu(app.CoursesOfActionsMenu);
            app.AirforceMenu.Text = 'Airforce';
            app.AirControlinAOAMenu = uimenu(app.AirforceMenu);
            app.AirControlinAOAMenu.Text = 'Air Control in AOA';
            app.InfDominanceMenu = uimenu(app.AirforceMenu);
            app.InfDominanceMenu.Text = 'Inf Dominance';
            app.SupportLFMenu = uimenu(app.AirforceMenu);
            app.SupportLFMenu.Text = 'Support LF';
            app.EnhanceCPMenu = uimenu(app.AirforceMenu);
            app.EnhanceCPMenu.Text = 'Enhance CP';
            app.EstablishAirheadMenu = uimenu(app.AirforceMenu);
            app.EstablishAirheadMenu.Text = 'Establish Airhead';
            app.BackgroundMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.BackgroundMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundMenuSelected, true);
            app.BackgroundMenu.Text = 'Background';
            app.NARRATIVEMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.NARRATIVEMenu.MenuSelectedFcn = createCallbackFcn(app, @NARRATIVEMenuSelected, true);
            app.NARRATIVEMenu.Text = 'NARRATIVE';
            app.PresentSituationMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.PresentSituationMenu.MenuSelectedFcn = createCallbackFcn(app, @PresentSituationMenuSelected, true);
            app.PresentSituationMenu.Text = 'Present Situation';
            app.GridLayout = uigridlayout(app.CombatPotentialComputationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.REDDropDownLabel = uilabel(app.LeftPanel);
            app.REDDropDownLabel.Text = 'RED';
            app.REDDropDown = uidropdown(app.LeftPanel);
            app.REDDropDown.Items = {'6th Mechanized Infantry Division', '6th Armour Regiment', '17th Infantry Regiment', '18th Artillery Regiment', 'Air Defense Regiment', 'Joint Defending Task Group Level1'};
            app.REDDropDown.ValueChangedFcn = createCallbackFcn(app, @REDDropDownValueChanged, true);
            app.REDDropDown.Value = '6th Mechanized Infantry Division';
            app.BMLabel = uilabel(app.LeftPanel);
            app.BMLabel.Text = 'BM';
            app.RedTankEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedTankEditField.Value = 50;
            app.CMLabel = uilabel(app.LeftPanel);
            app.CMLabel.Text = 'CM';
            app.RedAPCEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedAPCEditField.Value = 100;
            app.AircraftShelterLabel = uilabel(app.LeftPanel);
            app.AircraftShelterLabel.Text = 'Aircraft Shelter';
            app.RedLRAntiarmrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RunwaysLabel = uilabel(app.LeftPanel);
            app.RunwaysLabel.Text = 'Runways';
            app.RedSRAntiarmrEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedSRAntiarmrEditField.Value = 100;
            app.MortarEditFieldLabel = uilabel(app.LeftPanel);
            app.MortarEditFieldLabel.Text = 'Mortar';
            app.RedMortarEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedMortarEditField.Value = 1000;
            app.ArtyEditFieldLabel = uilabel(app.LeftPanel);
            app.ArtyEditFieldLabel.Text = 'Arty';
            app.RedArtyEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedArtyEditField.Value = 150;
            app.HelicopterEditFieldLabel = uilabel(app.LeftPanel);
            app.HelicopterEditFieldLabel.Text = 'Helicopter';
            app.RedHelicopterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedHelicopterEditField.Value = 10;
            app.FighterEditFieldLabel = uilabel(app.LeftPanel);
            app.FighterEditFieldLabel.Text = 'Fighter';
            app.RedFighterEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedFighterEditField.Value = 20;
            app.GroundAttackEditFieldLabel = uilabel(app.LeftPanel);
            app.GroundAttackEditFieldLabel.Text = 'Ground Attack';
            app.RedGroundAttackEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedGroundAttackEditField.Value = 20;
            app.UAVEditFieldLabel = uilabel(app.LeftPanel);
            app.UAVEditFieldLabel.Text = 'UAV';
            app.RedUAVEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedUAVEditField.Value = 10;
            app.Red_Tank_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_Tank_score.Value = 4;
            app.Red_APC_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_APC_score.Value = 1;
            app.Red_LR_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_LR_score.Value = 1;
            app.Red_SR_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_SR_score.Value = 0.25;
            app.Red_Mortar_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_Mortar_score.Value = 0.15;
            app.Red_Arty_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_Arty_score.Value = 2.5;
            app.Red_Helicopter_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_Helicopter_score.Value = 2.5;
            app.Red_Fighter_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_Fighter_score.Value = 5;
            app.Red_GroundAttack_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_GroundAttack_score.Value = 3;
            app.Red_UAV_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_UAV_score.Value = 1;
            app.AverageScoresLabel = uilabel(app.LeftPanel);
            app.AverageScoresLabel.Text = 'Average Scores';
            app.Red_pers_score = uieditfield(app.LeftPanel, 'numeric');
            app.Red_pers_score.Value = 1;
            app.PersEditFieldLabel = uilabel(app.LeftPanel);
            app.PersEditFieldLabel.Text = 'Pers';
            app.RedPersEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RedPersEditField.ValueDisplayFormat = '%.0f';
            app.RedPersEditField.Value = 13000;
            app.TotalCombatPotentialLabel = uilabel(app.LeftPanel);
            app.TotalCombatPotentialLabel.Text = 'Total Combat Potential';
            app.aa = uieditfield(app.LeftPanel, 'numeric');
            app.aa.ValueDisplayFormat = '%.2f';
            app.COMPUTEButton = uibutton(app.LeftPanel, 'push');
            app.COMPUTEButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEButtonPushed2, true);
            app.COMPUTEButton.Text = 'COMPUTE';
            app.TerrainDropDownLabel = uilabel(app.LeftPanel);
            app.TerrainDropDownLabel.Text = 'Terrain';
            app.TerrainDropDown = uidropdown(app.LeftPanel);
            app.TerrainDropDown.Items = {'Open', 'Urban', 'Rough', 'Mountain'};
            app.TerrainDropDown.ValueChangedFcn = createCallbackFcn(app, @TerrainDropDownValueChanged, true);
            app.TerrainDropDown.Value = 'Open';
            app.red_EqNComp_score = uieditfield(app.LeftPanel, 'numeric');
            app.red_EqNComp_score.Value = 5;
            app.EqNavalCompoLabel = uilabel(app.LeftPanel);
            app.EqNavalCompoLabel.Text = 'Eq Naval Compo';
            app.red_EqNavalC = uieditfield(app.LeftPanel, 'numeric');
            app.red_EqNavalC.ValueDisplayFormat = '%.0f';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'MIDiv.jpg';
            app.PostureDropDownLabel = uilabel(app.LeftPanel);
            app.PostureDropDownLabel.Text = ' Posture';
            app.PostureDropDown = uidropdown(app.LeftPanel);
            app.PostureDropDown.Items = {'Attack', 'Defence (Hasty)', 'Defence (Prepared)', 'Defence (Fortified)', 'Withdrawal', 'Delay'};
            app.PostureDropDown.ValueChangedFcn = createCallbackFcn(app, @PostureDropDownValueChanged, true);
            app.PostureDropDown.Value = 'Attack';
            app.CenterPanel = uipanel(app.GridLayout);
            app.BLUEDropDownLabel = uilabel(app.CenterPanel);
            app.BLUEDropDownLabel.Text = 'BLUE';
            app.BLUEDropDown = uidropdown(app.CenterPanel);
            app.BLUEDropDown.Items = {'T-72 Tank Brigade', '8th Mountain Division', '3rd Infantry Division', 'Leh AFS', 'Joint Attacking Task Group Level3'};
            app.BLUEDropDown.ValueChangedFcn = createCallbackFcn(app, @BLUEDropDownValueChanged, true);
            app.BLUEDropDown.Value = 'T-72 Tank Brigade';
            app.BMEditFieldLabel = uilabel(app.CenterPanel);
            app.BMEditFieldLabel.Text = 'BM';
            app.BMEditField = uieditfield(app.CenterPanel, 'numeric');
            app.BMEditField.Value = 150;
            app.CMEditFieldLabel = uilabel(app.CenterPanel);
            app.CMEditFieldLabel.Text = 'CM';
            app.CMEditField = uieditfield(app.CenterPanel, 'numeric');
            app.CMEditField.Value = 50;
            app.AircraftShelterEditFieldLabel = uilabel(app.CenterPanel);
            app.AircraftShelterEditFieldLabel.Text = 'Aircraft Shelter';
            app.AircraftShelterEditField = uieditfield(app.CenterPanel, 'numeric');
            app.RunwaysEditFieldLabel = uilabel(app.CenterPanel);
            app.RunwaysEditFieldLabel.Text = 'Runways';
            app.RunwaysEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MortarEditField_2Label = uilabel(app.CenterPanel);
            app.MortarEditField_2Label.Text = 'Mortar';
            app.MortarEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.ArtyEditField_2Label = uilabel(app.CenterPanel);
            app.ArtyEditField_2Label.Text = 'Arty';
            app.ArtyEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.TerrainDropDown_2Label = uilabel(app.CenterPanel);
            app.TerrainDropDown_2Label.Text = 'Terrain';
            app.TerrainDropDown_2 = uidropdown(app.CenterPanel);
            app.TerrainDropDown_2.Items = {'Open', 'Urban', 'Rough', 'Mountain'};
            app.TerrainDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TerrainDropDown_2ValueChanged, true);
            app.TerrainDropDown_2.Value = 'Open';
            app.Tank_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Tank_blue_score.Value = 5;
            app.APC_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.APC_blue_score.Value = 1;
            app.LR_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.LR_blue_score.Value = 1.2;
            app.SR_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.SR_blue_score.Value = 3;
            app.Mortar_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Mortar_blue_score.Value = 0.18;
            app.Arty_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Arty_blue_score.Value = 3.5;
            app.AverageScoresLabel_2 = uilabel(app.CenterPanel);
            app.AverageScoresLabel_2.Text = 'Average Scores';
            app.TotalCombatPotentialLabel_2 = uilabel(app.CenterPanel);
            app.TotalCombatPotentialLabel_2.Text = 'Total Combat Potential';
            app.COMPUTEButton_2 = uibutton(app.CenterPanel, 'push');
            app.COMPUTEButton_2.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEButton_2Pushed, true);
            app.COMPUTEButton_2.Text = 'COMPUTE';
            app.HelicopterEditField_2Label = uilabel(app.CenterPanel);
            app.HelicopterEditField_2Label.Text = 'Helicopter';
            app.HelicopterEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.FighterEditField_2Label = uilabel(app.CenterPanel);
            app.FighterEditField_2Label.Text = 'Fighter';
            app.FighterEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.GroundAttackEditField_2Label = uilabel(app.CenterPanel);
            app.GroundAttackEditField_2Label.Text = 'Ground Attack';
            app.GroundAttackEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.UAVEditField_2Label = uilabel(app.CenterPanel);
            app.UAVEditField_2Label.Text = 'UAV';
            app.UAVEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.PersEditField_2Label = uilabel(app.CenterPanel);
            app.PersEditField_2Label.Text = 'Pers';
            app.PersEditField_2 = uieditfield(app.CenterPanel, 'numeric');
            app.PersEditField_2.ValueDisplayFormat = '%.0f';
            app.PersEditField_2.Value = 3000;
            app.Helicopter_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Helicopter_blue_score.Value = 2.8;
            app.Fighter_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Fighter_blue_score.Value = 6;
            app.GroundAttack_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.GroundAttack_blue_score.Value = 3.2;
            app.UAV_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.UAV_blue_score.Value = 1;
            app.Pers_blue_score = uieditfield(app.CenterPanel, 'numeric');
            app.Pers_blue_score.Value = 1;
            app.bb = uieditfield(app.CenterPanel, 'numeric');
            app.bb.ValueDisplayFormat = '%.2f';
            app.blue_EqNComp_score = uieditfield(app.CenterPanel, 'numeric');
            app.blue_EqNComp_score.Value = 5;
            app.EqNavalCompoLabel_2 = uilabel(app.CenterPanel);
            app.EqNavalCompoLabel_2.Text = 'Eq Naval Compo';
            app.blue_EqNavalC = uieditfield(app.CenterPanel, 'numeric');
            app.blue_EqNavalC.ValueDisplayFormat = '%.0f';
            app.Image2 = uiimage(app.CenterPanel);
            app.Image2.ImageSource = 'T72Bgd.jpg';
            app.PostureDropDown_2Label = uilabel(app.CenterPanel);
            app.PostureDropDown_2Label.Text = ' Posture';
            app.PostureDropDown_2 = uidropdown(app.CenterPanel);
            app.PostureDropDown_2.Items = {'Attack', 'Defence (Hasty)', 'Defence (Prepared)', 'Defence (Fortified)', 'Withdrawal', 'Delay'};
            app.PostureDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PostureDropDown_2ValueChanged, true);
            app.PostureDropDown_2.Value = 'Attack';
            app.RightPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '[Referance]:The strategic Postures of China and India, Frank O''Donnell and Alex Bollfrass, HARVARD Kennedy School, BELFER CENTER for Science and International Affairs, March 2020.';
            app.ForceRatioREDBLUEButton = uibutton(app.RightPanel, 'push');
            app.ForceRatioREDBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @ForceRatioREDBLUEButtonPushed, true);
            app.ForceRatioREDBLUEButton.Text = 'Force Ratio   (RED/BLUE)   =';
            app.FR = uieditfield(app.RightPanel, 'numeric');
            app.ComputeAttritionRatesButton = uibutton(app.RightPanel, 'push');
            app.ComputeAttritionRatesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAttritionRatesButtonPushed, true);
            app.ComputeAttritionRatesButton.Text = 'Compute Attrition Rates';
            app.outputA = uieditfield(app.RightPanel, 'numeric');
            app.outputA.ValueDisplayFormat = '%7.5f';
            app.outputA.Editable = 'off';
            app.outputB = uieditfield(app.RightPanel, 'numeric');
            app.outputB.ValueDisplayFormat = '%7.5f';
            app.outputB.Editable = 'off';
            app.A = uilabel(app.RightPanel);
            app.A.Text = 'RED Attrition Rate (A)';
            app.B = uilabel(app.RightPanel);
            app.B.Text = 'BLUE Attrition Rate (B)';
            app.DefenderUIAxes = uiaxes(app.RightPanel);
            title(app.DefenderUIAxes, 'Red verses Blue')
            xlabel(app.DefenderUIAxes, 'time(in min)')
            ylabel(app.DefenderUIAxes, 'Combat Power  ')
            app.DefenderUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.DefenderUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'};
            app.AttackerUIAxes = uiaxes(app.RightPanel);
            title(app.AttackerUIAxes, 'Force Ratio')
            xlabel(app.AttackerUIAxes, 'Time (in Min)')
            ylabel(app.AttackerUIAxes, 'Force Ratio (RED/BLUE)')
            app.AttackerUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.AttackerUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '1'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'};
            app.BattleTimetEditFieldLabel = uilabel(app.RightPanel);
            app.BattleTimetEditFieldLabel.Text = 'Battle Time (t)';
            app.BattleTimetEditField = uieditfield(app.RightPanel, 'numeric');
            app.BattleTimetEditField.Limits = [0 150];
            app.BattleTimetEditField.ValueDisplayFormat = '%.0f';
            app.BattleTimetEditField.Value = 14;
            app.RedLossEditFieldLabel = uilabel(app.RightPanel);
            app.RedLossEditFieldLabel.Text = 'Red Loss';
            app.Redloss = uieditfield(app.RightPanel, 'numeric');
            app.Redloss.Value = 90;
            app.BlueLossEditFieldLabel = uilabel(app.RightPanel);
            app.BlueLossEditFieldLabel.Text = 'Blue Loss';
            app.Blueloss = uieditfield(app.RightPanel, 'numeric');
            app.Blueloss.Value = 10;
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '%';
            app.AnalysisButton = uibutton(app.RightPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.MapButton = uibutton(app.RightPanel, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Text = 'Map';
            app.fig3UIAxes = uiaxes(app.RightPanel);
            title(app.fig3UIAxes, 'Contour Plot of SSR')
            xlabel(app.fig3UIAxes, 'p')
            ylabel(app.fig3UIAxes, 'q')
            app.fig4UIAxes = uiaxes(app.RightPanel);
            title(app.fig4UIAxes, '3D Surface of SSR')
            xlabel(app.fig4UIAxes, 'p')
            ylabel(app.fig4UIAxes, 'q')
            app.ABLabel = uilabel(app.RightPanel);
            app.ABLabel.Text = 'A/B  =';
            app.ABRatio = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.CombatPotentialComputationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Combat_Potential_Forces_3p4_ExTrident(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombatPotentialComputationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombatPotentialComputationUIFigure)
        end
    end
end