classdef Draw_Scenario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ARTYSUPPORTTOTHESCENARIOLabel  matlab.ui.control.Label
        INFVsINFButton                 matlab.ui.control.Button
        AIMPOINTButton_2               matlab.ui.control.Button
        MOVEARTYDIVButton              matlab.ui.control.Button
        DrawLineButton                 matlab.ui.control.Button
        DeployBLUEButton               matlab.ui.control.Button
        DeployREDButton                matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp; % Description
        gx; % Description
       Image;
       DialogApp;
    end
    
    properties (Access = private)
        Move_id % Description
    end
    
    properties (Access = public)
        perc % Description
         % Description
    end
    
    methods (Access = public)
        
        function update_id(app,x)
            app.Move_id=x;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;  
%             plot(app.UIAxes,10,10,'rs')
%             hold on
%             app.Image.ImageSource = 'INS.jpg';
%             app.Image.Position=[28,72, 10,10];
        end

        % Button pushed function: MOVEARTYDIVButton
        function MOVEARTYDIVButtonPushed(app, event)
% %% ************************************************
% %%              ARTY MOVEMENT
% %%*************************************************
%             
% %             [x,y]=ginput(1);
% %             plot(x,y,"MarkerFaceColor","r.")
            app.gx=geoaxes;
            latSeattle = 5.62;
            lonSeattle = 65.33;
            latAnchorage = 35.20;
            lonAnchorage = 97.9;
             geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%             geoplot(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage],'Color',[1 1 1])
            geobasemap(app.gx,"colorterrain");
            hold on
% %             temp1=[];
%             for i=1:1:2
%               temp =[   29.8026   76.5162
%                         25.2466   72.8451];
%             Div_lat=temp(i,1);
%             Div_lon=temp(i,2);
% %              temp=[temp;[Div_lat,Div_lon]];
% %             name="Div"+i;
% %             a=imread('INS.jpg');
% %             image(a,[Div_lat,Div_lon, 10,10]);
% %             hold on
%             geoscatter(Div_lat,Div_lon,100,'filled','square',"MarkerFaceColor","blue");
%             text(Div_lat,Div_lon,'  Arty Div')
% %             gtext("Arty Div"+i,'Color','blue','FontSize',10)
%             hold on
%             end
% %            temp
%             for j=1:1:5
%                    temp=[33.6184   74.7623
%                        30.9987   74.8031
%                        28.7709   73.8241
%                        27.7650   72.0293
%                        26.5310   70.5609];
%             Inf_Div_lat=temp(j,1);
%             Inf_Div_lon=temp(j,2);
% %                temp=[temp;[Inf_Div_lat,Inf_Div_lon]];
%             geoscatter(Inf_Div_lat,Inf_Div_lon,100,'filled',"square","MarkerFaceColor","cyan","MarkerEdgeColor","black");
%             text(Inf_Div_lat,Inf_Div_lon,'  Inf Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             
%             
%               
%             for k=1:1:5
%                  temp =[   31.9034   75.0478
%                            30.3673   74.2728
%                            29.4835   73.2530
%                            28.5203   72.6820
%                            27.2585   71.3767];
% 
%             Arm_Div_lat=temp(k,1);
%             Arm_Div_lon=temp(k,2);
% %             [Arm_Div_lat,Arm_Div_lon]=ginput(1);
% %                temp1=[temp1;[Arm_Div_lat,Arm_Div_lon]];
%             geoscatter(Arm_Div_lat,Arm_Div_lon,100,'filled',"square","MarkerFaceColor","green","MarkerEdgeColor","black");
%             text(Arm_Div_lat,Arm_Div_lon,'  Arm Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             temp1=[];
%             
%             for k=1:1:1
%                  temp =[   30.7887   73.5794];
% %  31.2083   73.0491
% 
%             Red_Arm_Div_lat=temp(k,1);
%             Red_Arm_Div_lon=temp(k,2);
% %             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
% %             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
%             geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
%             text(Red_Arm_Div_lat,Red_Arm_Div_lon,'  Arm Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             temp1
%             
%              for k=1:1:1
%                  temp =[   31.2083   73.0491];
% 
% 
%             Red_Inf_Div_lat=temp(k,1);
%             Red_Inf_Div_lon=temp(k,2);
% %             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
% %             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
%             geoscatter(Red_Inf_Div_lat,Red_Inf_Div_lon,100,'filled',"square","MarkerFaceColor","magenta","MarkerEdgeColor","black");
%             text(Red_Inf_Div_lat,Red_Inf_Div_lon,'  Inf Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             
%             
%             
%             
%             [Bat_lat,Bat_lon]=ginput(1);
%             geoscatter(Bat_lat,Bat_lon,100,'filled','^');
%             gtext('Arty Bat','Color','blue','FontSize',10)
%             hold on
%             
%             [lat,lon] = ginput(1);
%             hold on
%            
%              geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%             geoscatter(lat,lon,'filled','b')
%               gtext('Aim Point','Color','red','FontSize',10)
% %             tx = txsite('Name','Bat Loc','Latitude',Bat_lat,'Longitude',Bat_lon);
% %             rx = rxsite('Name','Aim Point','Latitude',lat,'Longitude',lon);
% %             dme = distance(tx,rx);
% %             gtext(strcat('Distance:',dme),'Color','blue','FontSize',10)
% %             hold on
% %             dim = [ .5 .5 .01 .01];
% %             annotation('rectangle',dim,'Color','red')
%             geoplot([Bat_lat lat],[Bat_lon lon],'g-*')
%             earthRadiusInMeters = 6371000;
%             distInMeters = distance(Bat_lat, Bat_lon,lat, lon, earthRadiusInMeters)
%             distInKilometers = distInMeters/1000;
% 
%             % arclen = distance('gc',[Bat_lat, Bat_lon],[lat,lon])
%              gtext("Distance:"+distInKilometers+"km",'Color','black','FontSize',10)
%              hold on
%             for i=1:1:30
%                 shots_x=(lat-0.1)+0.2*rand();
%                 shots_y=(lon-0.1)+0.2*rand();
%                 hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
% %                 geoscatter(shots_x,shots_y,'filled','r')
%                 geoplot(app.gx,shots_x,shots_y,'r.')
%                 hold on
%             end
%             geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
%             hold on
%             temp=[];
%             Red_Arm_Div_lat_initial=Red_Arm_Div_lat;
%             Red_Arm_Div_lon_initial=Red_Arm_Div_lon;
%            for i=1:1:30
%                dist_mult_lat=(lat-Red_Arm_Div_lat_initial)/30
%                dist_mult_lon=(lon-Red_Arm_Div_lon_initial)/30
%                temp=[temp;dist_mult_lat];
%                Red_Arm_Div_lat=Red_Arm_Div_lat+dist_mult_lat;
%                Red_Arm_Div_lon= Red_Arm_Div_lon+dist_mult_lon;
%                 geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
% %                geoplot(app.gx,Red_Arm_Div_lat,Red_Arm_Div_lon,'rs')
%                geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
% %                hold on
%                pause(1);
%            end
%           temp
            
 %%  *************************************
 %%            API1.mlapp
 %% **************************************
 load('resources.mat');
for i=1:1:200
    if(s(i).side==1)
        if(s(i).type==1)
            geoplot(s(i).Lat,s(i).Lon,'bo','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[0 0 1]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==2)
            geoplot(s(i).Lat,s(i).Lon,'bs','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[0 0 1]);
            hold on   
        elseif(s(i).type==3)
            geoplot(s(i).Lat,s(i).Lon,'b*','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[0 0 1 ]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'g^','MarkerFaceColor','green','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",3,"Color",[0 0 1 ]);
%             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        end
        
    elseif(s(i).side==2)
        if(s(i).type==1)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'ro','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[1 0 0]);
         drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==2)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'rs','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[1 0 0]);
            hold on   
        elseif(s(i).type==3)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[1 0 0]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'y^','MarkerFaceColor','yellow','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")  
        end
    end
end
[x,y]=ginput(1);
d1=(x-s(118).Lat)/30;
d2=(y-s(118).Lon)/30;
theta=rad2deg(atan(d2/d1));

 geoplot([s(118).Lat x],[s(118).Lon y],'g-*')
            earthRadiusInMeters = 6371000;
            distInMeters = distance(s(118).Lat, s(118).Lon,x,y,earthRadiusInMeters)
            distInKilometers = distInMeters/1000;

            % arclen = distance('gc',[Bat_lat, Bat_lon],[lat,lon])
             gtext("Distance:"+distInKilometers+"km",'Color','black','FontSize',10)
             hold on
time_required=round(distInKilometers/30); % @30 km /hour
for j=1:1:time_required
    for i=1:1:200
    if(s(i).side==1)
        if(s(i).type==1)
            geoplot(s(i).Lat,s(i).Lon,'bo','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[0 0 1]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==2)
            geoplot(s(i).Lat,s(i).Lon,'bs','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[0 0 1]);
            hold on   
        elseif(s(i).type==3)
            geoplot(s(i).Lat,s(i).Lon,'b*','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[0 0 1 ]);
             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1],"RotationAngle",theta)
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on  
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'g^','MarkerFaceColor','green','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",3,"Color",[0 0 1 ]);
%             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        end
        
    elseif(s(i).side==2)
        if(s(i).type==1)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'ro','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[1 0 0]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==2)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'rs','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[1 0 0]);
            hold on   
        elseif(s(i).type==3)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[1 0 0]);
            hold on 
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'y^','MarkerFaceColor','yellow','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")  
        end
    end
end
   
% h.X=s(118).Lat;
% h.Y=s(118).Lon;
s(118).Lat=s(118).Lat+d1;
    s(118).Lon=s(118).Lon+d2;
% refreshdata
% drawnow
pause(1)

%             h=plot(app.gx,s(118).Lat,s(118).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black');
%             h1=text(app.gx,s(118).Lat,s(118).Lon,"  Arty Div")
%             h2=drawcircle(app.gx,"Center",[s(118).Lat,s(118).Lon],"Radius",1,"Color",[0 0 1]);
%             h3=  drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1],"RotationAngle",theta)

            hold on
%   cla(h)
if(j==time_required)
    break;
end
  cla
  
end
% drawcircle(app.gx,"Center",[s(117).Lat,s(117).Lon],"Radius",10);
%% ************************************************
%%              ARTY SUPPORT
%%*************************************************
%             
% %             [x,y]=ginput(1);
% %             plot(x,y,"MarkerFaceColor","r.")
%             app.gx=geoaxes;
%             latSeattle = 5.62;
%             lonSeattle = 65.33;
%             latAnchorage = 35.20;
%             lonAnchorage = 97.9;
%              geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%             geoplot(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage],'Color',[1 1 1])
%             geobasemap(app.gx,"grayland");
%             hold on
% %             temp1=[];
%             for i=1:1:2
%               temp =[   29.8026   76.5162
%                         25.2466   72.8451];
%             Div_lat=temp(i,1);
%             Div_lon=temp(i,2);
% %              temp=[temp;[Div_lat,Div_lon]];
% %             name="Div"+i;
% %             a=imread('INS.jpg');
% %             image(a,[Div_lat,Div_lon, 10,10]);
% %             hold on
%             geoscatter(Div_lat,Div_lon,100,'filled','square',"MarkerFaceColor","blue");
%             text(Div_lat,Div_lon,'  Arty Div')
% %             gtext("Arty Div"+i,'Color','blue','FontSize',10)
%             hold on
%             end
% %            temp
%             for j=1:1:5
%                    temp=[33.6184   74.7623
%                        30.9987   74.8031
%                        28.7709   73.8241
%                        27.7650   72.0293
%                        26.5310   70.5609];
%             Inf_Div_lat=temp(j,1);
%             Inf_Div_lon=temp(j,2);
% %                temp=[temp;[Inf_Div_lat,Inf_Div_lon]];
%             geoscatter(Inf_Div_lat,Inf_Div_lon,100,'filled',"square","MarkerFaceColor","cyan","MarkerEdgeColor","black");
%             text(Inf_Div_lat,Inf_Div_lon,'  Inf Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             
%             
%               
%             for k=1:1:5
%                  temp =[   31.9034   75.0478
%                            30.3673   74.2728
%                            29.4835   73.2530
%                            28.5203   72.6820
%                            27.2585   71.3767];
% 
%             Arm_Div_lat=temp(k,1);
%             Arm_Div_lon=temp(k,2);
% %             [Arm_Div_lat,Arm_Div_lon]=ginput(1);
% %                temp1=[temp1;[Arm_Div_lat,Arm_Div_lon]];
%             geoscatter(Arm_Div_lat,Arm_Div_lon,100,'filled',"square","MarkerFaceColor","green","MarkerEdgeColor","black");
%             text(Arm_Div_lat,Arm_Div_lon,'  Arm Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             temp1=[];
%             
%             for k=1:1:1
%                  temp =[   30.7887   73.5794];
% %  31.2083   73.0491
% 
%             Red_Arm_Div_lat=temp(k,1);
%             Red_Arm_Div_lon=temp(k,2);
% %             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
% %             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
%             geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
%             text(Red_Arm_Div_lat,Red_Arm_Div_lon,'  Arm Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             temp1
%             
%              for k=1:1:1
%                  temp =[   31.2083   73.0491];
% 
% 
%             Red_Inf_Div_lat=temp(k,1);
%             Red_Inf_Div_lon=temp(k,2);
% %             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
% %             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
%             geoscatter(Red_Inf_Div_lat,Red_Inf_Div_lon,100,'filled',"square","MarkerFaceColor","magenta","MarkerEdgeColor","black");
%             text(Red_Inf_Div_lat,Red_Inf_Div_lon,'  Inf Div')
% %             gtext("Inf Div"+j,'Color','blue','FontSize',10)
%             hold on
%             end
%             
%             
%             
%             
%             [Bat_lat,Bat_lon]=ginput(1);
%             geoscatter(Bat_lat,Bat_lon,100,'filled','^');
%             gtext('Arty Bat','Color','blue','FontSize',10)
%             hold on
%             
%             [lat,lon] = ginput(1);
%             hold on
%            
%              geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%             geoscatter(lat,lon,'filled','b')
%               gtext('Aim Point','Color','red','FontSize',10)
% %             tx = txsite('Name','Bat Loc','Latitude',Bat_lat,'Longitude',Bat_lon);
% %             rx = rxsite('Name','Aim Point','Latitude',lat,'Longitude',lon);
% %             dme = distance(tx,rx);
% %             gtext(strcat('Distance:',dme),'Color','blue','FontSize',10)
% %             hold on
% %             dim = [ .5 .5 .01 .01];
% %             annotation('rectangle',dim,'Color','red')
%             geoplot([Bat_lat lat],[Bat_lon lon],'g-*')
%             earthRadiusInMeters = 6371000;
%             distInMeters = distance(Bat_lat, Bat_lon,lat, lon, earthRadiusInMeters)
%             distInKilometers = distInMeters/1000;
% 
%             % arclen = distance('gc',[Bat_lat, Bat_lon],[lat,lon])
%              gtext("Distance:"+distInKilometers+"km",'Color','black','FontSize',10)
%              hold on
%             for i=1:1:30
%                 shots_x=(lat-0.1)+0.2*rand();
%                 shots_y=(lon-0.1)+0.2*rand();
%                 hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
% %                 geoscatter(shots_x,shots_y,'filled','r')
%                 geoplot(app.gx,shots_x,shots_y,'r.')
%                 hold on
%             end
%             geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
%             hold on
%             temp=[];
%             Red_Arm_Div_lat_initial=Red_Arm_Div_lat;
%             Red_Arm_Div_lon_initial=Red_Arm_Div_lon;
%            for i=1:1:30
%                dist_mult_lat=(lat-Red_Arm_Div_lat_initial)/30
%                dist_mult_lon=(lon-Red_Arm_Div_lon_initial)/30
%                temp=[temp;dist_mult_lat];
%                Red_Arm_Div_lat=Red_Arm_Div_lat+dist_mult_lat;
%                Red_Arm_Div_lon= Red_Arm_Div_lon+dist_mult_lon;
%                 geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
% %                geoplot(app.gx,Red_Arm_Div_lat,Red_Arm_Div_lon,'rs')
%                geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
% %                hold on
%                pause(1);
%            end
%           temp
            
        end

        % Button pushed function: AIMPOINTButton_2
        function AIMPOINTButton_2Pushed(app, event)
            % %% ************************************************
% %%              ARTY SUPPORT
% %%*************************************************
            
%             [x,y]=ginput(1);
%             plot(x,y,"MarkerFaceColor","r.")
            app.gx=geoaxes;
            latSeattle = 5.62;
            lonSeattle = 65.33;
            latAnchorage = 35.20;
            lonAnchorage = 97.9;
             geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
            geoplot(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage],'Color',[1 1 1])
            geobasemap(app.gx,"colorterrain");
            hold on
%             temp1=[];
            for i=1:1:2
              temp =[   29.8026   76.5162
                        25.2466   72.8451];
            Div_lat=temp(i,1);
            Div_lon=temp(i,2);
%              temp=[temp;[Div_lat,Div_lon]];
%             name="Div"+i;
%             a=imread('INS.jpg');
%             image(a,[Div_lat,Div_lon, 10,10]);
%             hold on
            geoscatter(Div_lat,Div_lon,100,'filled','square',"MarkerFaceColor","blue");
            text(Div_lat,Div_lon,'  Arty Div')
%             gtext("Arty Div"+i,'Color','blue','FontSize',10)
            hold on
            end
%            temp
            for j=1:1:5
                   temp=[33.6184   74.7623
                       30.9987   74.8031
                       28.7709   73.8241
                       27.7650   72.0293
                       26.5310   70.5609];
            Inf_Div_lat=temp(j,1);
            Inf_Div_lon=temp(j,2);
%                temp=[temp;[Inf_Div_lat,Inf_Div_lon]];
            geoscatter(Inf_Div_lat,Inf_Div_lon,100,'filled',"square","MarkerFaceColor","cyan","MarkerEdgeColor","black");
            text(Inf_Div_lat,Inf_Div_lon,'  Inf Div')
%             gtext("Inf Div"+j,'Color','blue','FontSize',10)
            hold on
            end


            for k=1:1:5
                 temp =[   31.9034   75.0478
                           30.3673   74.2728
                           29.4835   73.2530
                           28.5203   72.6820
                           27.2585   71.3767];

            Arm_Div_lat=temp(k,1);
            Arm_Div_lon=temp(k,2);
%             [Arm_Div_lat,Arm_Div_lon]=ginput(1);
%                temp1=[temp1;[Arm_Div_lat,Arm_Div_lon]];
            geoscatter(Arm_Div_lat,Arm_Div_lon,100,'filled',"square","MarkerFaceColor","green","MarkerEdgeColor","black");
            text(Arm_Div_lat,Arm_Div_lon,'  Arm Div')
%             gtext("Inf Div"+j,'Color','blue','FontSize',10)
            hold on
            end
            temp1=[];
            
            for k=1:1:1
                 temp =[   30.7887   73.5794];
%  31.2083   73.0491

            Red_Arm_Div_lat=temp(k,1);
            Red_Arm_Div_lon=temp(k,2);
%             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
%             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
            geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
            text(Red_Arm_Div_lat,Red_Arm_Div_lon,'  Arm Div')
%             gtext("Inf Div"+j,'Color','blue','FontSize',10)
            hold on
            end
            temp1
            
             for k=1:1:1
                 temp =[   31.2083   73.0491];


            Red_Inf_Div_lat=temp(k,1);
            Red_Inf_Div_lon=temp(k,2);
%             [Red_Arm_Div_lat,Red_Arm_Div_lon]=ginput(1);
%             temp1=[temp1;[Red_Arm_Div_lat,Red_Arm_Div_lon]];
            geoscatter(Red_Inf_Div_lat,Red_Inf_Div_lon,100,'filled',"square","MarkerFaceColor","magenta","MarkerEdgeColor","black");
            text(Red_Inf_Div_lat,Red_Inf_Div_lon,'  Inf Div')
%             gtext("Inf Div"+j,'Color','blue','FontSize',10)
            hold on
            end


            [Bat_lat,Bat_lon]=ginput(1);
            geoscatter(Bat_lat,Bat_lon,100,'filled','^');
            gtext('Arty Bat','Color','blue','FontSize',10)
            hold on
            
            [lat,lon] = ginput(1);
            hold on
           
             geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
            geoscatter(lat,lon,'filled','b')
              gtext('Aim Point','Color','red','FontSize',10)
%             tx = txsite('Name','Bat Loc','Latitude',Bat_lat,'Longitude',Bat_lon);
%             rx = rxsite('Name','Aim Point','Latitude',lat,'Longitude',lon);
%             dme = distance(tx,rx);
%             gtext(strcat('Distance:',dme),'Color','blue','FontSize',10)
%             hold on
%             dim = [ .5 .5 .01 .01];
%             annotation('rectangle',dim,'Color','red')
            geoplot([Bat_lat lat],[Bat_lon lon],'g-*')
            earthRadiusInMeters = 6371000;
            distInMeters = distance(Bat_lat, Bat_lon,lat, lon, earthRadiusInMeters)
            distInKilometers = distInMeters/1000;

            % arclen = distance('gc',[Bat_lat, Bat_lon],[lat,lon])
             gtext("Distance:"+distInKilometers+"km",'Color','black','FontSize',10)
             hold on
            for i=1:1:30
                shots_x=(lat-0.1)+0.2*rand();
                shots_y=(lon-0.1)+0.2*rand();
                hold on
                 geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%                 geoscatter(shots_x,shots_y,'filled','r')
                geoplot(app.gx,shots_x,shots_y,'r.')
                hold on
            end
            geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
            hold on
            temp=[];
            Red_Arm_Div_lat_initial=Red_Arm_Div_lat;
            Red_Arm_Div_lon_initial=Red_Arm_Div_lon;
           for i=1:1:30
               dist_mult_lat=(lat-Red_Arm_Div_lat_initial)/30
               dist_mult_lon=(lon-Red_Arm_Div_lon_initial)/30
               temp=[temp;dist_mult_lat];
               Red_Arm_Div_lat=Red_Arm_Div_lat+dist_mult_lat;
               Red_Arm_Div_lon= Red_Arm_Div_lon+dist_mult_lon;
                geoscatter(Red_Arm_Div_lat,Red_Arm_Div_lon,100,'filled',"square","MarkerFaceColor","red","MarkerEdgeColor","black");
%                geoplot(app.gx,Red_Arm_Div_lat,Red_Arm_Div_lon,'rs')
               geoplot([Red_Arm_Div_lat lat],[Red_Arm_Div_lon lon],'r-*')
%                hold on
               pause(1);
           end
          temp
        end

        % Button pushed function: INFVsINFButton
        function INFVsINFButtonPushed(app, event)
% %% ************************************************
% %%              INF DIV vs INF DIV
% %%*************************************************

            app.gx=geoaxes;
            latSeattle = 30;
            lonSeattle = 73;
            latAnchorage = 32;
            lonAnchorage = 76;
             geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
            geobasemap(app.gx,"darkwater");
            hold on
load('resources.mat');
for i=1:1:200
    if(s(i).side==1)
        if(s(i).type==1)
            geoplot(s(i).Lat,s(i).Lon,'bo','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div"+"("+i+")")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[0 0 1]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
            for n=1:1:100
                pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%                 geoscatter(shots_x,shots_y,'filled','r')
                geoplot(app.gx,pers_x,pers_y,'b.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(s(i).Lat,s(i).Lon,'bs','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arm Div"+"("+i+")")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[0 0 1]);
            hold on   
        elseif(s(i).type==3)
            geoplot(s(i).Lat,s(i).Lon,'b*','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arty Div"+"("+i+")")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[0 0 1 ]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'g^','MarkerFaceColor','green','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base"+"("+i+")")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",3,"Color",[0 0 1 ]);
%             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        end
        
    elseif(s(i).side==2)
        if(s(i).type==1)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'ro','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div"+"("+i+")")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[1 0 0]);
         drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
            for n=1:1:100
              pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%                 geoscatter(shots_x,shots_y,'filled','r')
                geoplot(app.gx,pers_x,pers_y,'r.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'rs','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arm Div"+"("+i+")")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[1 0 0]);
            hold on   
        elseif(s(i).type==3)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arty Div"+"("+i+")")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[1 0 0]);
            drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1])
            hold on
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'y^','MarkerFaceColor','yellow','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base"+"("+i+")")  
        end
    end
end
[inf1,inf2]=ginput(1)
s1=["1. Name:"+s(117).Name, "2.Type (inf,Arm, Arty):"+s(117).type,"3.Side(Blue,Red):"+s(117).side,"4. Det Range(km)"+s(117).Det]
text(app.gx,inf1,inf2,s1,"Color","blue","BackgroundColor","white") 

    k=0;
    move_data=[];
    while(k<2)
        app.DialogApp=whict_unit_to_move(app);
        [inf1,inf2]=ginput(1)
        s1=["1. Name:"+s(app.Move_id).Name, "2.Type (inf,Arm, Arty):"+s(app.Move_id).type,"3.Side(Blue,Red):"+s(app.Move_id).side,"4. Det Range(km)"+s(app.Move_id).Det]
        text(app.gx,inf1,inf2,s1,"Color","red","BackgroundColor","white") 
        [x,y]=ginput(1);     
        geoplot([s(app.Move_id).Lat x],[s(app.Move_id).Lon y],'g-*')
                    earthRadiusInMeters = 6371000;
                    distInMeters = distance(s(app.Move_id).Lat, s(app.Move_id).Lon,x,y,earthRadiusInMeters)
                    distInKilometers = distInMeters/1000;
                    CV1=1;CV2=2;
                    penalty=CV1/CV2;
                    speed=30*penalty;
        time_required=round(distInKilometers/speed) % @30 km /hour
        d1=(x-s(app.Move_id).Lat)/time_required;
        d2=(y-s(app.Move_id).Lon)/time_required;
        theta=rad2deg(atan(d2/d1))
                    % arclen = distance('gc',[Bat_lat, Bat_lon],[lat,lon])
                     gtext("Distance:"+distInKilometers+"km"+""+theta,'Color','black','FontSize',10,"BackgroundColor","white")
                     hold on
                     move_data=[move_data;[k,app.Move_id,time_required,d1,d2,theta]]
                     k=k+1;   
    end
    

time_required1=min(move_data(:,3));


for j=1:1:time_required1
       temp=[];
    ar=[];
    for k=1:1:2
     s(move_data(k,2)).Lat=s(move_data(k,2)).Lat+move_data(k,4);
     s(move_data(k,2)).Lon=s(move_data(k,2)).Lon+move_data(k,5);
 
    for i=[117,move_data(k,2)]
    if(s(i).side==1)
        if(s(i).type==1)
            geoplot(s(i).Lat,s(i).Lon,'bo','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[0 0 1]);
            roi1=drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1],"RotationAngle",move_data(k,6))
            temp=[temp;roi1.Vertices]
            ar=[ar;roi1.Position]
            hold on
             for n=1:1:100
              pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%                 geoscatter(shots_x,shots_y,'filled','r')
                geoplot(app.gx,pers_x,pers_y,'r.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(s(i).Lat,s(i).Lon,'bs','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[0 0 1]);
            hold on   
        elseif(s(i).type==3)
            geoplot(s(i).Lat,s(i).Lon,'b*','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[0 0 1 ]);
             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1],"RotationAngle",move_data(k,6))
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on  
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'g^','MarkerFaceColor','green','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",3,"Color",[0 0 1 ]);
%             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1])
%             geobubble(s,s.Lat,s.Lon,s.Det)
            hold on   
        end
        
    elseif(s(i).side==2)
        if(s(i).type==1)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'ro','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[1 0 0]);
            roi2=drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1],"RotationAngle",move_data(k,6))
            hold on
            temp=[temp;roi2.Vertices]
             ar=[ar;roi2.Position]
             for n=1:1:100
            pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
%                  geolimits(app.gx,[latSeattle latAnchorage],[lonSeattle lonAnchorage])
%                 geoscatter(shots_x,shots_y,'filled','r')
                geoplot(app.gx,pers_x,pers_y,'r.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'rs','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arm Div")
%             drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.5,"Color",[1 0 0]);
            hold on   
        elseif(s(i).type==3)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[1 0 0]);
            hold on 
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'y^','MarkerFaceColor','yellow','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")  
        end
%         [inf2,inf3]=ginput(1);
% [xi,yi]=polyxpoly(roi1,roi2)
% gtext(app.gx,inf2,inf3,""+ xi,"Color","red","BackgroundColor","white") 
    end
% A=ar(1,:);
% drawrectangle(app.gx,'Position',A,"RotationAngle",move_data(k,6))
% hold on
% B=ar(2,:);
% drawrectangle(app.gx,'Position', B,"RotationAngle",move_data(k,6))
% hold on
% rectint(A,B)  

end
   
% h.X=s(118).Lat;
% h.Y=s(118).Lon;


%             h=plot(app.gx,s(118).Lat,s(118).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black');
%             h1=text(app.gx,s(118).Lat,s(118).Lon,"  Arty Div")
%             h2=drawcircle(app.gx,"Center",[s(118).Lat,s(118).Lon],"Radius",1,"Color",[0 0 1]);
%             h3=  drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1],"RotationAngle",move_data(k,6))

            hold on
%   cla(h)


%   cla


% poly1=[x1,y1]
% poly2=[x2,y2]
% A=ar(1,:);
% rectangle('Position',A)
% hold on
% B=ar(2,:);
% rectangle('Position', B)
% hold on
% rectint(A,B)

A=ar(1,:);
% drawrectangle(app.gx,'Position',A,"RotationAngle",move_data(k,6))
% hold on
B=ar(2,:);
% drawrectangle(app.gx,'Position', B,"RotationAngle",move_data(k,6))
% hold on
dd=rectint(A,B);
app.perc=(dd/.2*.1)*100;
% total_fighting_elements=10000;
% fighting_elements_in_combat=total_fighting_elements*perc;

x1=[temp(1:4,1);temp(1,1)];
y1=[temp(1:4,2);temp(1,2)];
x2=[temp(5:8,1);temp(5,1)];
y2=[temp(5:8,2);temp(5,2)];
[xi,yi]=polyxpoly(x1,y1,x2,y2)
 geoplot(app.gx,xi,yi,'gs','MarkerFaceColor','green','MarkerEdgeColor','black','MarkerSize',30)
 hold on

% refreshdata
% drawnow
pause(1)

            if(xi~=0)
            earthRadiusInMeters = 6371000;
            distInMeters = distance(xi(1),yi(1),xi(2),yi(2),earthRadiusInMeters)
            distInKilometers = distInMeters/1000;
%             density=fighting_elements_in_combat/distInKilometers;
            if(distInKilometers>0)
                app.DialogApp=msg(app,app.perc,distInKilometers);
                app.DialogApp=next_move(app,app.gx,s(move_data(k,2)).Lat,s(move_data(k,2)).Lon,s(117).Lat,s(117).Lon)
                break;
            end
            end


if(j==time_required1)
     app.DialogApp=next_move(app,app.gx,s(move_data(k,2)).Lat,s(move_data(k,2)).Lon,s(117).Lat,s(117).Lon)
    break;
end


%  v=[xi(1) yi(1); xi(2) yi(2); xi(3) yi(3); xi(4) yi(4)];
%  v=[[xi yi];[x1(1,:) y1(1,:)];[x2(1,:) y2(1,:)]];
%  drawpolygon('Position',v,'Color','green')
%  hold on
%  drawpolygon(xi,yi)
%  hold on
% drawcircle(app.gx,"Center",[s(117).Lat,s(117).Lon],"Radius",10);
%    [xii,yii]=intersect(poly1,poly2)    
%    geoplot(app.gx,xii,yii,'gs','MarkerFaceColor','green','MarkerEdgeColor','black','MarkerSize',30)
%  hold on

    
%     cla
    end
    
 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DeployREDButton = uibutton(app.UIFigure, 'push');
            app.DeployREDButton.Text = 'Deploy RED';
            app.DeployBLUEButton = uibutton(app.UIFigure, 'push');
            app.DeployBLUEButton.Text = 'Deploy BLUE';
            app.DrawLineButton = uibutton(app.UIFigure, 'push');
            app.DrawLineButton.Text = 'Draw Line';
            app.MOVEARTYDIVButton = uibutton(app.UIFigure, 'push');
            app.MOVEARTYDIVButton.ButtonPushedFcn = createCallbackFcn(app, @MOVEARTYDIVButtonPushed, true);
            app.MOVEARTYDIVButton.Text = 'MOVE ARTY DIV';
            app.AIMPOINTButton_2 = uibutton(app.UIFigure, 'push');
            app.AIMPOINTButton_2.ButtonPushedFcn = createCallbackFcn(app, @AIMPOINTButton_2Pushed, true);
            app.AIMPOINTButton_2.Text = 'AIM POINT';
            app.INFVsINFButton = uibutton(app.UIFigure, 'push');
            app.INFVsINFButton.ButtonPushedFcn = createCallbackFcn(app, @INFVsINFButtonPushed, true);
            app.INFVsINFButton.Text = 'INF Vs INF';
            app.ARTYSUPPORTTOTHESCENARIOLabel = uilabel(app.UIFigure);
            app.ARTYSUPPORTTOTHESCENARIOLabel.Text = 'ARTY SUPPORT TO THE SCENARIO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Draw_Scenario(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end