classdef FSM1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        OkButton                     matlab.ui.control.Button
        FSM                          matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel     matlab.ui.control.Label
        TrainingCohesivenessNationalityDropDown  matlab.ui.control.DropDown
        TrainingCohesivenessNationalityDropDownLabel  matlab.ui.control.Label
        ForceScoringMultiplierLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
       Property;
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Value changed function: 
        % TrainingCohesivenessNationalityDropDown
        function TrainingCohesivenessNationalityDropDownValueChanged(app, event)
            value = app.TrainingCohesivenessNationalityDropDown.Value;
            if(strcmp(value,"Low"))
                app.FSM.Value=0.7;
            elseif(strcmp(value,"Medium"))
                app.FSM.Value=0.8;
            elseif(strcmp(value,"High"))
                app.FSM.Value=1;
            end
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
           multiplier=app.FSM.Value;
           update_Force_Score_Multiplier(app.CallingApp,multiplier);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ForceScoringMultiplierLabel = uilabel(app.UIFigure);
            app.ForceScoringMultiplierLabel.Text = 'Force Scoring Multiplier';
            app.TrainingCohesivenessNationalityDropDownLabel = uilabel(app.UIFigure);
            app.TrainingCohesivenessNationalityDropDownLabel.Text = 'Training, Cohesiveness, Nationality';
            app.TrainingCohesivenessNationalityDropDown = uidropdown(app.UIFigure);
            app.TrainingCohesivenessNationalityDropDown.Items = {'Low', 'Medium', 'High'};
            app.TrainingCohesivenessNationalityDropDown.ValueChangedFcn = createCallbackFcn(app, @TrainingCohesivenessNationalityDropDownValueChanged, true);
            app.TrainingCohesivenessNationalityDropDown.Value = 'Low';
            app.MultiplierEditFieldLabel = uilabel(app.UIFigure);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.FSM = uieditfield(app.UIFigure, 'numeric');
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FSM1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end