classdef Multi_Resolution_Combat_Potential < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CombatPotentialComputationUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        REDResources                    matlab.ui.control.TextArea
        ResourcesLabel_2                matlab.ui.control.Label
        REDFrontageConstraintsEditField  matlab.ui.control.NumericEditField
        FrontageConstraintsEditField_2Label  matlab.ui.control.Label
        FrontageConstraintsEditFieldLabel_3  matlab.ui.control.Label
        REDBRIGADES                     matlab.ui.control.DropDown
        SUBUNITSLabel_2                 matlab.ui.control.Label
        REDTHEATER                      matlab.ui.control.DropDown
        THEATERDropDown_2Label          matlab.ui.control.Label
        REDDIVISIONS                    matlab.ui.control.DropDown
        DIVISIONSLabel                  matlab.ui.control.Label
        PostureDropDown                 matlab.ui.control.DropDown
        PostureDropDownLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TerrainDropDown                 matlab.ui.control.DropDown
        TerrainDropDownLabel            matlab.ui.control.Label
        COMPUTEButton                   matlab.ui.control.Button
        aa                              matlab.ui.control.NumericEditField
        TotalCombatPotentialLabel       matlab.ui.control.Label
        REDCORPS                        matlab.ui.control.DropDown
        CORPSDropDownLabel              matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        FrontageConstraintsEditFieldLabel_2  matlab.ui.control.Label
        FrontageConstraintsEditField    matlab.ui.control.NumericEditField
        FrontageConstraintsEditFieldLabel  matlab.ui.control.Label
        Resources                       matlab.ui.control.TextArea
        ResourcesLabel                  matlab.ui.control.Label
        BLUEBRIGADES                    matlab.ui.control.DropDown
        SUBUNITSLabel                   matlab.ui.control.Label
        BLUETHEATER                     matlab.ui.control.DropDown
        THEATERDropDownLabel            matlab.ui.control.Label
        BLUEDIVISIONS                   matlab.ui.control.DropDown
        DIVISIONSLabel_2                matlab.ui.control.Label
        PostureDropDown_2               matlab.ui.control.DropDown
        PostureDropDown_2Label          matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        bb                              matlab.ui.control.NumericEditField
        COMPUTEButton_2                 matlab.ui.control.Button
        TotalCombatPotentialLabel_2     matlab.ui.control.Label
        TerrainDropDown_2               matlab.ui.control.DropDown
        TerrainDropDown_2Label          matlab.ui.control.Label
        BLUECORPS                       matlab.ui.control.DropDown
        CORPSDropDown_2Label            matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        ABRatio                         matlab.ui.control.NumericEditField
        ABLabel                         matlab.ui.control.Label
        MapButton                       matlab.ui.control.Button
        AnalysisButton                  matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Blueloss                        matlab.ui.control.NumericEditField
        BlueLossEditFieldLabel          matlab.ui.control.Label
        Redloss                         matlab.ui.control.NumericEditField
        RedLossEditFieldLabel           matlab.ui.control.Label
        BattleTimetEditField            matlab.ui.control.NumericEditField
        BattleTimetEditFieldLabel       matlab.ui.control.Label
        B                               matlab.ui.control.Label
        A                               matlab.ui.control.Label
        outputB                         matlab.ui.control.NumericEditField
        outputA                         matlab.ui.control.NumericEditField
        ComputeAttritionRatesButton     matlab.ui.control.Button
        FR                              matlab.ui.control.NumericEditField
        ForceRatioREDBLUEButton         matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        fig4UIAxes                      matlab.ui.control.UIAxes
        fig3UIAxes                      matlab.ui.control.UIAxes
        AttackerUIAxes                  matlab.ui.control.UIAxes
        DefenderUIAxes                  matlab.ui.control.UIAxes
        TerrainMenu                     matlab.ui.container.Menu
        MobilityEffectMenu              matlab.ui.container.Menu
        DefensePostureEffectMenu        matlab.ui.container.Menu
        WeatherMenu                     matlab.ui.container.Menu
        MobilityMenu                    matlab.ui.container.Menu
        AttackPostureMenu               matlab.ui.container.Menu
        ArtilleryMenu                   matlab.ui.container.Menu
        AirMenu                         matlab.ui.container.Menu
        TankMenu                        matlab.ui.container.Menu
        IntangibleFactorMenu            matlab.ui.container.Menu
        CombatEffectivenessMenu         matlab.ui.container.Menu
        LeadershipMenu                  matlab.ui.container.Menu
        TrainingExperiencesMenu         matlab.ui.container.Menu
        MoraleMenu                      matlab.ui.container.Menu
        LogisticMenu                    matlab.ui.container.Menu
        TimeMenu                        matlab.ui.container.Menu
        SpaceMenu                       matlab.ui.container.Menu
        MomentumMenu                    matlab.ui.container.Menu
        IntelligenceMenu                matlab.ui.container.Menu
        TechnologyMenu                  matlab.ui.container.Menu
        InitiativeMenu                  matlab.ui.container.Menu
        AirDominanceMenu                matlab.ui.container.Menu
        AirSuperiorityMenu              matlab.ui.container.Menu
        MobilityEffectMenu_2            matlab.ui.container.Menu
        ArtilleryEffectMenu             matlab.ui.container.Menu
        AirEffectivenessEffectMenu      matlab.ui.container.Menu
        VulnerabilityEffectMenu         matlab.ui.container.Menu
        AirEqualityMenu                 matlab.ui.container.Menu
        AirInferiorityMenu              matlab.ui.container.Menu
        SituationMultipliersMenu        matlab.ui.container.Menu
        ForceScoreMultiplersMenu        matlab.ui.container.Menu
        SituationalCategoryMultiplierMenu  matlab.ui.container.Menu
        ShortageCategoryMultiplierMenu  matlab.ui.container.Menu
        MaritimeStrikeCapabilityMenu    matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        ScoresMenu                      matlab.ui.container.Menu
        AirComponentsMenu               matlab.ui.container.Menu
        LandComponentMenu               matlab.ui.container.Menu
        TankMenu_2                      matlab.ui.container.Menu
        NavalComponentMenu              matlab.ui.container.Menu
        QJMMenu                         matlab.ui.container.Menu
        NonMobileWeaponsMenu            matlab.ui.container.Menu
        MobileWeaponsMenu               matlab.ui.container.Menu
        CombatPowerMenu                 matlab.ui.container.Menu
        AirPowerMenu                    matlab.ui.container.Menu
        FighterMenu                     matlab.ui.container.Menu
        EscortMenu                      matlab.ui.container.Menu
        TransportACMenu                 matlab.ui.container.Menu
        HelicopterMenu                  matlab.ui.container.Menu
        LandPowerMenu                   matlab.ui.container.Menu
        SFMenu                          matlab.ui.container.Menu
        AmphibiousBrigadeMenu           matlab.ui.container.Menu
        NavalPowerMenu                  matlab.ui.container.Menu
        MaritimeFighterMenu             matlab.ui.container.Menu
        INSJALASWAMenu                  matlab.ui.container.Menu
        AiravatMenu                     matlab.ui.container.Menu
        GuldarMenu                      matlab.ui.container.Menu
        LCVMK4Menu                      matlab.ui.container.Menu
        MARCOSMenu                      matlab.ui.container.Menu
        CourseofActionMenu              matlab.ui.container.Menu
        CarrySFandMARCOSMenu            matlab.ui.container.Menu
        DrawScenarioMenu                matlab.ui.container.Menu
        ConflictPointMenu               matlab.ui.container.Menu
        SecuringtheMinicoyPortfromANEbySFandMARCOSMenu  matlab.ui.container.Menu
        MovingtowardsAirstirpewhichis12kmawayMenu  matlab.ui.container.Menu
        CombattofreetheairstripebySFMARCOSAMPHForceMenu  matlab.ui.container.Menu
        RemovingtheAAgunMenu            matlab.ui.container.Menu
        SecuringthePortandAirstripeMenu  matlab.ui.container.Menu
        FreetheHostagesandkilltheANEfromGovtResidenceMenu  matlab.ui.container.Menu
        IntelligenceMenu_2              matlab.ui.container.Menu
        LocationofPortAirstripeMenu     matlab.ui.container.Menu
        LocationofAAgunsMenu            matlab.ui.container.Menu
        CoordinationMenu                matlab.ui.container.Menu
        ScenarioDescriptionMenu         matlab.ui.container.Menu
        ObjOwnForcesPresentSituationMenu  matlab.ui.container.Menu
        CoursesofActionMenu             matlab.ui.container.Menu
        MapMenu                         matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        CallingApp;
         DialogApp    ;    
         currentvalue;% Dialog box appLine 129: Argument 'app' is unused. Should this method be Static?
         force_strength;
         vulnerability;
         red_vulnerability_multiplier=1;
         red_force_strength_multiplier=1;
           blue_vulnerability_multiplier=1;
         blue_force_strength_multiplier=1;
          leadership_red_vs_blue=1;
         training_exp_red_vs_blue=1;
         morale_red_vs_blue=1;
         logistic_red_vs_blue=1;
         AirSupMobility_red_vs_blue=1;
          AirSupArtillery_red_vs_blue=1; % Description
        current_Arty_blue_score=3.5; % Description
        FS_value=1;
        SiCM_mult=1;
        ShCM_mult=1;
        terrain_effect_on_mobility=1;
        terrain_effect_on_defense_posture=1;
        temp=1;
        temp1=1;

%           CurrentSize = 35;           % Surface sample size
%         CurrentColormap = 'Parula'; % Colormap
      
        BLue_Artillery; % Description
        Blue_Arty_score; % Description
       
       
                Blue_Pers=2500;
                Blue_Pers_Score=100;
                Blue_Assualt_rifle=0;
                Blue_MG=0;
                Blue_Pistol=0;
                Blue_shotguns=0;
                Blue_snipper_rifle=0;
                Blue_GL=0;
                Blue_RL=0;
                Blue_tank=0;
                Blue_APC=0;
                Blue_LCVC=0;
                Tank_blue_score;
                APC_blue_score;
               LR_blue_score=1;
               SR_blue_score=1;
               Mortar_blue_score=1;
               Arty_blue_score=1;
               Helicopter_blue_score=1;
               Fighter_blue_score=1;
               GroundAttack_blue_score=1;
              
               Pers_blue_score=1;
                                                          
       
      Red_Artillery;
        Red_Arty_score; % Description
       
       
                Red_Pers=2500;
                Red_Pers_Score=100;
                Red_Assualt_rifle=0;
                Red_MG=0;
                Red_Pistol=0;
                Red_shotguns=0;
                Red_snipper_rifle=0;
                Red_GL=0;
                Red_RL=0;
                Red_tank=0;
                Red_APC=0;
                Red_LCVC=0;
                Tank_red_score;
                APC_red_score;
               LR_red_score=1;
               SR_red_score=1;
               Mortar_red_score=1;
               Arty_red_score=1;
               Helicopter_red_score=1;
               Fighter_red_score=1;
               GroundAttack_red_score=1;
              
               Pers_red_score=1;
        
       
    end
    
  methods (Access = public)
        
        function updatevalue(app,x)
%               app.ABRatio.Value=x;
%             app.FirstDialogEditField.Value=x;
%             app.OKButton.Enable='on'
            app.COMPUTEButton.Enable='on';
           % app.aa.Value=x
        end
  end
    
    methods (Access = public)
        
        function  update_posture_factor_matrix(app,posture_factor)
         
           
            app.force_strength=double(posture_factor(:,2))
            app.vulnerability=double(posture_factor(:,3))
        end
    end
    
    methods (Access = public)
        function update_AirSupArtillery(app,AirSupArtillery_red_vs_blue)
%             app.AirSupArtillery_red_vs_blue=AirSupArtillery_red_vs_blue;
%                 app.Arty_blue_score.Value=app.Arty_blue_score.Value*AirSupArtillery_red_vs_blue;
%                 app.Blue_Arty_score.Value=app.Blue_Arty_score.Value*AirSupArtillery_red_vs_blue;
  app.AirSupArtillery_red_vs_blue=AirSupArtillery_red_vs_blue;
        end
        
        
    end


    methods (Access = public)
        
        function update_leadership(app,red_vs_blue)
            app.leadership_red_vs_blue=red_vs_blue;
            
            
        end
    end
    
    methods (Access = public)
        
        function update_training_exp(app,training_exp_red_vs_blue)
            app.training_exp_red_vs_blue=training_exp_red_vs_blue;
            
        end
    end
    methods (Access = public)
        
        function update_morale(app,morale_red_vs_blue)
            app.morale_red_vs_blue=morale_red_vs_blue;
            
        end
    end
     methods (Access = public)
        
        function update_logistic(app,logistic_red_vs_blue)
            app.logistic_red_vs_blue=logistic_red_vs_blue;
            
        end
     end
      methods (Access = public)
        
        function update_AirSupMobility(app,AirSupMobility_red_vs_blue)
            app.AirSupMobility_red_vs_blue=AirSupMobility_red_vs_blue;
            
        end
      end
      
    
    methods (Access = public)
        
        function update_Force_Score_Multiplier(app,FSM_value)
            app.FS_value=FSM_value;
        end
    end
    methods (Access=public)
        function update_Situational_Category_Multiplier(app,multiplier);
                app.SiCM_mult=multiplier;
        end
    end
    methods (Access=public)
        function update_Shortage_Category_Multiplier(app,multiplier);
            app.ShCM_mult=multiplier;
        end
    end
    
    methods (Access = public)
        
        function update_terrain_effect_on_mobility_Multiplier(app,multiplier);
            app.terrain_effect_on_mobility=multiplier;
        end
    end
    
    methods (Access = public)
        
        function update_terrain_effect_on_defense_posture_Multiplier(app,multiplier);
            app.terrain_effect_on_defense_posture=multiplier;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
                app.CallingApp=mainapp;
        end

        % Value changed function: REDCORPS
        function REDCORPSValueChanged(app, event)
            value = app.REDCORPS.Value;
            if(strcmp(value,'STRATEGIC FORCE COMMAND'))
            app.REDDIVISIONS.Items={'21 Arty Div(Pano Aquil)','22 Arty Div (Sargodha)'};
%                  app.Red_Assualt_rifle.Value=10;
%                 app.Red_MG.Value=50;
%                 app.Red_Pistol.Value=50;
%                 app.Red_shotguns.Value=0;
%                 app.Red_snipper_rifle.Value=5;
%                 app.Red_GL.Value=5;
%                 app.Red_RL.Value=5;
%                 app.Red_tank.Value=0;
%                 app.Red_APC.Value=0;
%                 app.Red_Artillery.Value=0;
%                 app.Red_LCVC.Value=5;
%                 app.Red_Pers.Value=50;
%                 app.TerrainDropDown_2.Value='Open'
               % app.Image2.ImageSource='INS.jpg'
            end


% 'III CORPS(DIMAPUR)','IV CORPS','XXXIII CORPS(SILIGURI)','XVII CORPS(PANAGARH)'


              if(strcmp(value,'IV CORPS'))
                   app.BLUEDIVISIONS.Items={'1 Arm Div','14 RAPID','22 Inf Div','474 Eng bde', '612 Mech Inf Bde'};
                app.Blue_Assualt_rifle.Value=0;
                app.Blue_MG.Value=5;
                app.Blue_Pistol.Value=1000;
                app.Blue_shotguns.Value=1000;
                app.Blue_snipper_rifle.Value=1000;
                app.Blue_GL.Value=0;
                app.Blue_RL.Value=2;
                app.Blue_tank.Value=0;
                app.Blue_APC.Value=0;
                app.BLue_Artillery.Value=2;
                app.Blue_LCVC.Value=15500;
                app.Blue_Pers.Value=0;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='Airavat.jpg'
              end
              if(strcmp(value,'II CORPS(AMBALA)'))
                   app.BLUEDIVISIONS.Items={'40 Arty Div (AMBALA)','1 Arm Div (PATIALA)','14 RAPID(DEHRADUN)','22 Inf Div','474 Eng Bde' '612 Mech AD Bde'};
                   app.BLUEBRIGADES.Items={''};
              end
               if(strcmp(value,'IX CORPS'))
                   app.BLUEDIVISIONS.Items={'26 Inf Div(JAMMU)','29 Inf Div(PATHANKOT)','2 Ind Arm Bde','3 Ind Arm Bde'};
%                    app.BLUEBRIGADES.Items={'2 Ind Arm Bde','3 Ind Arm Bde'};
               end
               if(strcmp(value,'XI CORPS'))
                   app.BLUEDIVISIONS.Items={'7 Inf Div(FIROZPUR)','9 Inf Div(MEERUT)','15 Inf Div(AMRITSAR)','23 Ind Arm Bde','55 Mech Ind Arm Bde'};
%                    app.BLUEBRIGADES.Items={'23 Ind Arm Bde','55 Mech Ind Arm Bde'};
               end

               if(strcmp(value,'III Corps'))
                    app.BLUEDIVISIONS.Items={'2 Mtn Div','57 Mtn Div','56 Inf Div'};
                app.Blue_Assualt_rifle.Value=0;
                app.Blue_MG.Value=5;
                app.Blue_Pistol.Value=600;
                app.Blue_shotguns.Value=600;
                app.Blue_snipper_rifle.Value=20;
                app.Blue_GL.Value=10;
                app.Blue_RL.Value=10;
                app.Blue_tank.Value=150;
                app.Blue_APC.Value=50;
                app.BLue_Artillery.Value=0;
                app.Blue_LCVC.Value=10;
                app.Blue_Pers.Value=600;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='T72bgd.jpg'
               end
               

             if(strcmp(value,'IV Corps'))
                    app.BLUEDIVISIONS.Items={'71 Mtn Div','5 Mtn Div','21 Mtn Div'};
                app.Blue_Assualt_rifle.Value=100;
                app.Blue_MG.Value=100;
                app.Blue_Pistol.Value=100;
                app.Blue_shotguns.Value=5;
                app.Blue_snipper_rifle.Value=5;
                app.Blue_GL.Value=5;
                app.Blue_RL.Value=5;
                app.Blue_tank.Value=0;
                app.Blue_APC.Value=0;
                app.BLue_Artillery.Value=0;
                app.Blue_LCVC.Value=0;
                app.Blue_Pers.Value=100;
                app.TerrainDropDown_2.Value='Open'
                 app.Image2.ImageSource='SF.jpg'
            end


            if(strcmp(value,'Corps I'))
              app.REDDIVISIONS.Items={'A','B'};
                app.Red_Assualt_rifle.Value=30;
                app.Red_MG.Value=30;
                app.Red_Pistol.Value=60;
                app.Red_Shotguns.Value=10;
                app.Red_Snipper_rifle.Value=2;
                app.Red_GL.Value=5;
                app.Red_RL.Value=5;
                app.Red_AA.Value=4;
                app.Red_Hostages.Value=0;
                app.Red_Tank.Value=0;
                app.Red_APC.Value=0;
                app.Red_Artillery.Value=0;
                app.Red_Pers.Value=60;
                app.TerrainDropDown.Value='Open'
                app.Image.ImageSource='ANEatPort.jpg';
             
            end
              if(strcmp(value,'Corps II'))
                   app.REDDIVISIONS.Items={'C','D'};
                app.Red_Assualt_rifle.Value=30;
                app.Red_MG.Value=30;
                app.Red_Pistol.Value=60;
                app.Red_Shotguns.Value=10;
                app.Red_Snipper_rifle.Value=2;
                app.Red_GL.Value=5;
                app.Red_RL.Value=5;
                app.Red_AA.Value=4;
                app.Red_Hostages.Value=0;
                app.Red_Tank.Value=0;
                app.Red_APC.Value=0;
                app.Red_Artillery.Value=0;
                app.Red_Pers.Value=60;
                app.TerrainDropDown.Value='Open'
                 app.Image.ImageSource='ANEatAirStripe.jpg';
              end
               if(strcmp(value,'ANE at AA gunbase'))
                app.Red_Assualt_rifle.Value=30;
                app.Red_MG.Value=30;
                app.Red_Pistol.Value=60;
                app.Red_Shotguns.Value=10;
                app.Red_Snipper_rifle.Value=0;
                app.Red_GL.Value=5;
                app.Red_RL.Value=5;
                app.Red_AA.Value=12;
                app.Red_Hostages.Value=0;
                app.Red_Tank.Value=0;
                app.Red_APC.Value=0;
                app.Red_Artillery.Value=0;
                app.Red_Pers.Value=60;
                app.TerrainDropDown.Value='Open'
                  app.Image.ImageSource='ANEatAA.jpg';
               end
               if(strcmp(value,'ANE at Govt Residence'))
                app.Red_Assualt_rifle.Value=60;
                app.Red_MG.Value=60;
                app.Red_Pistol.Value=120;
                app.Red_Shotguns.Value=10;
                app.Red_Snipper_rifle.Value=10;
                app.Red_GL.Value=10;
                app.Red_RL.Value=10;
                app.Red_AA.Value=12;
                app.Red_Hostages.Value=10000;
                app.Red_Tank.Value=0;
                app.Red_APC.Value=0;
                app.Red_Artillery.Value=0;
                app.Red_Pers.Value=120;
                app.TerrainDropDown.Value='Open'
                  app.Image.ImageSource='ANEatGovt.jpg';
               end

        end

        % Value changed function: TerrainDropDown
        function TerrainDropDownValueChanged(app, event)
            value = app.TerrainDropDown.Value;
            if(strcmp(value,'Open'))
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value;
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value;
                app.Red_MG.Value=app.Red_MG.Value;
                app.Red_Pistol.Value=app.Red_Pistol.Value;
                app.Red_Shotguns.Value=app.Red_Shotguns.Value;
                app.Red_Snipper_rifle.Value=app.Red_Snipper_rifle.Value;
                app.Red_GL.Value=app.Red_GL.Value;
                app.Red_RL.Value=app.Red_RL.Value;
                app.Red_Hostages.Value=app.Red_Hostages.Value;
                app.Red_Tank.Value=app.Red_Tank.Value;
                app.Red_APC.Value=app.Red_APC.Value;
                app.Red_Artillery.Value=app.Red_Artillery.Value;

            end
             if(strcmp(value,'Urban'))
%                 app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.75;
%                 app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.75;
%                 app.Red_MG.Value=app.Red_MG.Value*.75;
%                 app.Red_Pistol.Value=app.Red_Pistol.Value;
%                 app.Red_Shotguns.Value=app.Red_Shotguns.Value;
%                 app.Red_Snipper_rifle.Value=app.Red_Snipper_rifle.Value;
%                 app.Red_GL.Value=app.Red_GL.Value;
%                 app.Red_RL.Value=app.Red_RL.Value*.75;
%                 app.Red_Hostages.Value=app.Red_Hostages.Value;
%                 app.Red_Tank.Value=app.Red_Tank.Value;
%                 app.Red_APC.Value=app.Red_APC.Value;
%                 app.Red_Artillery.Value=app.Red_Artillery.Value;


             end
              if(strcmp(value,'Rough'))
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.5;
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.5;
                app.Red_MG.Value=app.Red_MG.Value*.5;
                app.Red_Pistol.Value=app.Red_Pistol.Value;
                app.Red_Shotguns.Value=app.Red_Shotguns.Value;
                app.Red_Snipper_rifle.Value=app.Red_Snipper_rifle.Value;
                app.Red_GL.Value=app.Red_GL.Value;
                app.Red_RL.Value=app.Red_RL.Value*.5;
                app.Red_Hostages.Value=app.Red_Hostages.Value;
                app.Red_Tank.Value=app.Red_Tank.Value;
                app.Red_APC.Value=app.Red_APC.Value;
                app.Red_Artillery.Value=app.Red_Artillery.Value;

                
              end
               if(strcmp(value,'Mountain'))
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.25;
                app.Red_Assualt_rifle.Value=app.Red_Assualt_rifle.Value*.25;
                app.Red_MG.Value=app.Red_MG.Value*.25;
                app.Red_Pistol.Value=app.Red_Pistol.Value;
                app.Red_Shotguns.Value=app.Red_Shotguns.Value;
                app.Red_Snipper_rifle.Value=app.Red_Snipper_rifle.Value;
                app.Red_GL.Value=app.Red_GL.Value;
                app.Red_RL.Value=app.Red_RL.Value*.25;
                app.Red_Hostages.Value=app.Red_Hostages.Value;
                app.Red_Tank.Value=app.Red_Tank.Value;
                app.Red_APC.Value=app.Red_APC.Value;
                app.Red_Artillery.Value=app.Red_Artillery.Value;


             end
        end

        % Value changed function: BLUECORPS
        function BLUECORPSValueChanged(app, event)
            value = app.BLUECORPS.Value;
             if(strcmp(value,'III CORPS(DIMAPUR)'))
            app.BLUEDIVISIONS.Items={'2 Mtn Div','57 Mtn Div','56 Inf Div'};
                 app.Blue_Assualt_rifle.Value=10;
                app.Blue_MG.Value=50;
                app.Blue_Pistol.Value=50;
                app.Blue_shotguns.Value=0;
                app.Blue_snipper_rifle.Value=5;
                app.Blue_GL.Value=5;
                app.Blue_RL.Value=5;
                app.Blue_tank.Value=0;
                app.Blue_APC.Value=0;
                app.BLue_Artillery.Value=0;
                app.Blue_LCVC.Value=5;
                app.Blue_Pers.Value=50;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='INS.jpg'
            end


% 'III CORPS(DIMAPUR)','IV CORPS','XXXIII CORPS(SILIGURI)','XVII CORPS(PANAGARH)'


              if(strcmp(value,'IV CORPS'))
                   app.BLUEDIVISIONS.Items={'1 Arm Div','14 RAPID','22 Inf Div','474 Eng bde', '612 Mech Inf Bde'};
                app.Blue_Assualt_rifle.Value=0;
                app.Blue_MG.Value=5;
                app.Blue_Pistol.Value=1000;
                app.Blue_shotguns.Value=1000;
                app.Blue_snipper_rifle.Value=1000;
                app.Blue_GL.Value=0;
                app.Blue_RL.Value=2;
                app.Blue_tank.Value=0;
                app.Blue_APC.Value=0;
                app.BLue_Artillery.Value=2;
                app.Blue_LCVC.Value=15500;
                app.Blue_Pers.Value=0;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='Airavat.jpg'
              end
              if(strcmp(value,'II CORPS(AMBALA)'))
                   app.BLUEDIVISIONS.Items={'40 Arty Div (AMBALA)','1 Arm Div (PATIALA)','14 RAPID(DEHRADUN)','22 Inf Div','474 Eng Bde' '612 Mech AD Bde'};
                   app.BLUEBRIGADES.Items={''};
              end
               if(strcmp(value,'IX CORPS'))
                   app.BLUEDIVISIONS.Items={'26 Inf Div(JAMMU)','29 Inf Div(PATHANKOT)','2 Ind Arm Bde','3 Ind Arm Bde'};
%                    app.BLUEBRIGADES.Items={'2 Ind Arm Bde','3 Ind Arm Bde'};
               end
               if(strcmp(value,'XI CORPS'))
                   app.BLUEDIVISIONS.Items={'7 Inf Div(FIROZPUR)','9 Inf Div(MEERUT)','15 Inf Div(AMRITSAR)','23 Ind Arm Bde','55 Mech Ind Arm Bde'};
%                    app.BLUEBRIGADES.Items={'23 Ind Arm Bde','55 Mech Ind Arm Bde'};
               end

               if(strcmp(value,'III Corps'))
                    app.BLUEDIVISIONS.Items={'2 Mtn Div','57 Mtn Div','56 Inf Div'};
                app.Blue_Assualt_rifle.Value=0;
                app.Blue_MG.Value=5;
                app.Blue_Pistol.Value=600;
                app.Blue_shotguns.Value=600;
                app.Blue_snipper_rifle.Value=20;
                app.Blue_GL.Value=10;
                app.Blue_RL.Value=10;
                app.Blue_tank.Value=150;
                app.Blue_APC.Value=50;
                app.BLue_Artillery.Value=0;
                app.Blue_LCVC.Value=10;
                app.Blue_Pers.Value=600;
                app.TerrainDropDown_2.Value='Open'
                app.Image2.ImageSource='T72bgd.jpg'
               end
               

             if(strcmp(value,'IV Corps'))
                    app.BLUEDIVISIONS.Items={'71 Mtn Div','5 Mtn Div','21 Mtn Div'};
                app.Blue_Assualt_rifle.Value=100;
                app.Blue_MG.Value=100;
                app.Blue_Pistol.Value=100;
                app.Blue_shotguns.Value=5;
                app.Blue_snipper_rifle.Value=5;
                app.Blue_GL.Value=5;
                app.Blue_RL.Value=5;
                app.Blue_tank.Value=0;
                app.Blue_APC.Value=0;
                app.BLue_Artillery.Value=0;
                app.Blue_LCVC.Value=0;
                app.Blue_Pers.Value=100;
                app.TerrainDropDown_2.Value='Open'
                 app.Image2.ImageSource='SF.jpg'
            end
          
     
        end

        % Value changed function: TerrainDropDown_2
        function TerrainDropDown_2ValueChanged(app, event)
            value = app.TerrainDropDown_2.Value;
            if(strcmp(value,'Open'))
                app.Blue_Assualt_rifle.Value=app.Blue_Assualt_rifle.Value;
                app.Blue_MG.Value=app.Blue_MG.Value;
                app.Blue_Pistol.Value=app.Blue_Pistol.Value;
                app.Blue_shotguns.Value=app.Blue_shotguns.Value;
                app.Blue_snipper_rifle.Value=app.Blue_snipper_rifle.Value;
                app.Blue_GL.Value=app.Blue_GL.Value;
                app.Blue_RL.Value=app.Blue_RL.Value;
                app.Blue_tank.Value=app.Blue_tank.Value;
                app.Blue_APC.Value=app.Blue_APC.Value;
                app.BLue_Artillery.Value=app.BLue_Artillery.Value;
                app.Blue_LCVC.Value=app.Blue_LCVC.Value;

            end
             if(strcmp(value,'Urban'))
%                app.Blue_Assualt_rifle.Value=app.Blue_Assualt_rifle.Value*0.75;
%                 app.Blue_MG.Value=app.Blue_MG.Value*.75;
%                 app.Blue_Pistol.Value=app.Blue_Pistol.Value*.75;
%                 app.Blue_shotguns.Value=app.Blue_shotguns.Value;
%                 app.Blue_snipper_rifle.Value=app.Blue_snipper_rifle.Value;
%                 app.Blue_GL.Value=app.Blue_GL.Value;
%                 app.Blue_RL.Value=app.Blue_RL.Value*.75;
%                 app.Blue_tank.Value=app.Blue_tank.Value;
%                 app.Blue_APC.Value=app.Blue_APC.Value;
                app.BLue_Artillery.Value=app.BLue_Artillery.Value;
%                 app.Blue_LCVC.Value=app.Blue_LCVC.Value;


             end
              if(strcmp(value,'Rough'))
                 app.Blue_Assualt_rifle.Value=app.Blue_Assualt_rifle.Value*0.5;
                app.Blue_MG.Value=app.Blue_MG.Value*.5;
                app.Blue_Pistol.Value=app.Blue_Pistol.Value*.5;
                app.Blue_shotguns.Value=app.Blue_shotguns.Value;
                app.Blue_snipper_rifle.Value=app.Blue_snipper_rifle.Value;
                app.Blue_GL.Value=app.Blue_GL.Value;
                app.Blue_RL.Value=app.Blue_RL.Value*.5;
                app.Blue_tank.Value=app.Blue_tank.Value;
                app.Blue_APC.Value=app.Blue_APC.Value;
                app.BLue_Artillery.Value=app.BLue_Artillery.Value;
                app.Blue_LCVC.Value=app.Blue_LCVC.Value;

                
              end
               if(strcmp(value,'Mountain'))
                app.Blue_Assualt_rifle.Value=app.Blue_Assualt_rifle.Value*0.25;
                app.Blue_MG.Value=app.Blue_MG.Value*.25;
                app.Blue_Pistol.Value=app.Blue_Pistol.Value*.25;
                app.Blue_shotguns.Value=app.Blue_shotguns.Value;
                app.Blue_snipper_rifle.Value=app.Blue_snipper_rifle.Value;
                app.Blue_GL.Value=app.Blue_GL.Value;
                app.Blue_RL.Value=app.Blue_RL.Value*.25;
                app.Blue_tank.Value=app.Blue_tank.Value;
                app.Blue_APC.Value=app.Blue_APC.Value;
                app.BLue_Artillery.Value=app.BLue_Artillery.Value;
                app.Blue_LCVC.Value=app.Blue_LCVC.Value;


                end
        end

        % Callback function
        function RedToatlCombatScoreEditFieldValueChanged(app, event)
            value = app.RedToatlCombatScoreEditField.Value;
             
        end

        % Callback function
        function ComputeButtonPushed(app, event)
           
        end

        % Callback function
        function COMPUTEButtonPushed(app, event)
                   
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.CombatPotentialComputationUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {849, 849, 849};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {849, 849};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {321, '1x', 493};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: COMPUTEButton_2
        function COMPUTEButton_2Pushed(app, event)
%             app.bb.Value=sum(app.Blue_Assualt_rifle*app.Tank_blue_score+...
%                                                            app.Blue_MG*app.APC_blue_score+...
%                                                            app.Blue_Pistol*app.LR_blue_score+...
%                                                            app.Blue_shotguns*app.SR_blue_score+...
%                                                            app.Blue_snipper_rifle*app.Mortar_blue_score+...
%                                                            app.Blue_GL*app.Arty_blue_score+...
%                                                            app.Blue_RL*app.Helicopter_blue_score+...
%                                                            app.Blue_tank*app.Fighter_blue_score+...
%                                                            app.Blue_APC*app.GroundAttack_blue_score+...
%                                                            app.BLue_Artillery*app.Blue_Arty_score+...
%                                                            app.Blue_LCVC*app.Pers_blue_score+...
%                                                            app.Blue_Pers*app.Blue_Pers_Score);
  
  app.temp=sum(app.BLue_Artillery*app.Blue_Arty_score+app.Blue_Pers*app.Blue_Pers_Score);
                         app.bb.Value=app.temp*app.FS_value*app.SiCM_mult*app.ShCM_mult*app.blue_force_strength_multiplier...
                                      *app.blue_vulnerability_multiplier*app.terrain_effect_on_mobility*...
                                      app.terrain_effect_on_defense_posture;                              
        end

        % Button pushed function: COMPUTEButton
        function COMPUTEButtonPushed2(app, event)
%             app.aa.Value=sum(app.Red_Assualt_rifle.Value*app.Red_Assualt_rifle_score.Value+...
%                                                            app.Red_MG.Value*app.Red_MG_score.Value+...
%                                                            app.Red_Pistol.Value*app.Red_Pistol_score.Value+...
%                                                            app.Red_Shotguns.Value*app.Red_Shotgun_score.Value+...
%                                                            app.Red_Snipper_rifle.Value*app.Red_Snipper_rifle_score.Value+...
%                                                            app.Red_GL.Value*app.Red_GL_score.Value+...
%                                                            app.Red_RL.Value*app.Red_RL_score.Value+...
%                                                            app.Red_Hostages.Value*app.Red_Hostages_score.Value+...
%                                                            app.Red_Tank.Value*app.Red_FighterAC_score.Value+...
%                                                            app.Red_APC.Value*app.Red_EscortAC_score.Value+...
%                                                            app.Red_Artillery.Value*app.Red_Helicopter_score.Value+...
%                                                            app.Red_Pers.Value*app.Red_Pers_score.Value);
             app.temp1=sum(app.Red_Artillery*app.Red_Arty_score+app.Red_Pers*app.Red_Pers_Score);
                         app.aa.Value=app.temp1*app.FS_value*app.SiCM_mult*app.ShCM_mult*app.red_force_strength_multiplier...
                                      *app.red_vulnerability_multiplier*app.terrain_effect_on_mobility*...
                                      app.terrain_effect_on_defense_posture;       
%             app.aa.Value=app.aa.Value*app.red_force_strength_multiplier*app.red_vulnerability_multiplier;
        end

        % Button pushed function: ForceRatioREDBLUEButton
        function ForceRatioREDBLUEButtonPushed(app, event)
            app.FR.Value=app.aa.Value/app.bb.Value;
        end

        % Button pushed function: ComputeAttritionRatesButton
        function ComputeAttritionRatesButtonPushed(app, event)
            
            red_loss=app.Redloss.Value;
            blue_loss=app.Blueloss.Value; 
            x0=app.aa.Value;
            
%             xt=red_loss;
%             yt=blue_loss;
            if(red_loss<100)
              xt=x0*(1-(red_loss/100));
            else
              xt=x0*(.99999);
            end
%             
            y0=app.bb.Value;
            if(blue_loss<100)
                yt=y0*(1-(blue_loss/100));
            else
                yt=y0*(.999999);
            end


            F=(xt*sqrt(y0^2-yt^2)+yt*sqrt(x0^2-xt^2))/(x0*sqrt(y0^2-yt^2)+y0*sqrt(x0^2-xt^2));
            t=app.BattleTimetEditField.Value;

            app.outputA.Value=((sqrt(x0^2-xt^2))/(t*sqrt(y0^2-yt^2)))*log(F);
            app.outputB.Value=((sqrt(y0^2-yt^2))/(t*sqrt(x0^2-xt^2)))*log(F);
            A=app.outputA.Value;
            B=app.outputB.Value;
          app.ABRatio.Value=A/B;

xx=[];
yy=[];
FR=[];
count=0;
for i=1:.5:t
    x=x0*cosh(sqrt(A*B)*i)-y0*sqrt(A/B)*sinh(sqrt(A*B)*i);

    
    xx=[xx;x];
    y=y0*cosh(sqrt(A*B)*i)-x0*sqrt(B/A)*sinh(sqrt(A*B)*i);
    yy=[yy;y];
 plot(app.DefenderUIAxes,(1:.5:i)',xx,'r',(1:.5:i)',yy,'b')
  FR=[FR;x/y];
  count=count+1;
 
    if(FR(1)>FR(count))
               plot(app.AttackerUIAxes,(1:.5:i)',FR,'b')
                legend(app.AttackerUIAxes,{'Blue Wining'},'Location','Best')
            elseif(FR(1)==FR(count))
               plot(app.AttackerUIAxes,(1:.5:i)',FR,'g')
               legend(app.AttackerUIAxes,{'Draw'},'Location','Best')
            else
                plot(app.AttackerUIAxes,(1:.5:i)',FR,'r')
                legend(app.AttackerUIAxes,{'Red Wining'},'Location','Best')
            end
 pause(.1)


end
  
xx
yy
FR
% xx(1)=0;
for i=2:1:size(xx)
    cas_x(i)=xx(i-1)-xx(i);
    cas_y(i)=yy(i-1)-yy(i);
end
% cas'
temp=[xx,cas_x',yy,cas_y',FR]
%  plot(app.DefenderUIAxes,(1:.5:t)',xx,'r',(1:.5:t)',yy,'b')


%  legend(app.DefenderUIAxes,{'Blue','Red'},'Location','Best')
%             xlim(app.DefenderUIAxes,[1 t]); 
%             if(FR(1)>FR(end))
%                plot(app.AttackerUIAxes,(1:.5:t)',FR,'b')
%                 legend(app.AttackerUIAxes,{'Blue Wining'},'Location','Best')
%             elseif(FR(1)==FR(end))
%                plot(app.AttackerUIAxes,(1:.5:t)',FR,'g')
%                legend(app.AttackerUIAxes,{'Draw'},'Location','Best')
%             else
%                 plot(app.AttackerUIAxes,(1:.5:t)',FR,'r')
%                 legend(app.AttackerUIAxes,{'Red Wining'},'Location','Best')
%             end
%     legend(app.AttackerUIAxes,{'Attacker'},'Location','Best')
%             xlim(app.DefenderUIAxes,[1 t]); 
            
x=temp(:,1:4);
B=x(:,1);
dB=x(:,2);
R=x(:,3);
dR=x(:,4);
a=-15;
b=-15;
[P Q]=meshgrid(a:.099:15,b:.099:15);
[ka kb]=size(P);

 SSR_est=zeros(ka,kb);
 temp=zeros(ka,kb);
d_est=1;
 for k1=1:1:ka
     for l1=1:1:kb
         
        p=(a+.1*(k1-1));
        q=(b+.1*(l1-1));
       
 a_est=sum(dB)./sum((R.^p).*(B.^q));
        a_est=mean(a_est);
        
        b_est=sum(dB)./sum((R.^q).*(B.^p));
        b_est=mean(b_est);
        
            temp2=0; 
            temp1=0;
        for i=1:1:size(x)
            
            temp1=(dB(i)-a_est*(R(i)^p)*(B(i)^q))^2+(dR(i)-b_est*(1/d_est)*(B(i)^p)*(R(i).^q))^2 ;
           
          temp2=temp2+temp1;
        end
        SSR_est(k1,l1)=temp2;
     end
 end

mesh(app.fig4UIAxes,P,Q,SSR_est);

% figure,[C,h]=contourf(app.fig3UIAxes,Q,SSR_est);
contour(app.fig3UIAxes,P,Q,SSR_est);

% text_handle = clabel(C,h); 
         
        end

        % Close request function: CombatPotentialComputationUIFigure
        function CombatPotentialComputationUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
%          app.AnalysisButton.Enable = 'off'; 
         blEq=sum(app.Blue_Assualt_rifle.Value*app.Tank_blue_score.Value+...
                                                           app.Blue_MG.Value*app.APC_blue_score.Value+...
                                                           app.Blue_Pistol.Value*app.LR_blue_score.Value+...
                                                           app.Blue_shotguns.Value*app.SR_blue_score.Value+...
                                                           app.Blue_snipper_rifle.Value*app.Mortar_blue_score.Value+...
                                                           app.Blue_GL.Value*app.Arty_blue_score.Value+...
                                                           app.Blue_LCVC.Value*app.Pers_blue_score.Value)...
                                                           /app.Tank_blue_score.Value;
        baEq=sum(app.Blue_RL.Value*app.Helicopter_blue_score.Value+...
                                                           app.Blue_tank.Value*app.Fighter_blue_score.Value+...
                                                           app.Blue_APC.Value*app.GroundAttack_blue_score.Value+...
                                                           app.BLue_Artillery.Value*app.Blue_Arty_score.Value)...
                                                           /app.Fighter_blue_score.Value;
           bNEq=app.Blue_Pers.Value*app.Blue_Pers_Score.Value ;                                      
           bpers= app.Blue_LCVC.Value*app.Pers_blue_score.Value;
          
           
           rlEq=sum(app.Red_Assualt_rifle.Value*app.Red_Assualt_rifle_score.Value+...
                                                           app.Red_MG.Value*app.Red_MG_score.Value+...
                                                           app.Red_Pistol.Value*app.Red_Pistol_score.Value+...
                                                           app.Red_Shotguns.Value*app.Red_Shotgun_score.Value+...
                                                           app.Red_Snipper_rifle.Value*app.Red_Snipper_rifle_score.Value+...
                                                           app.Red_GL.Value*app.Red_GL_score.Value+...
                                                           app.Red_Artillery.Value*app.Red_Helicopter_score.Value)...
                                                           /app.Red_Assualt_rifle_score.Value
            raEq=sum(app.Red_RL.Value*app.Red_RL_score.Value+...
                                                           app.Red_Hostages.Value*app.Red_Hostages_score.Value+...
                                                           app.Red_Tank.Value*app.Red_FighterAC_score.Value+...
                                                           app.Red_APC.Value*app.Red_EscortAC_score.Value)...
                                                           /app.Red_RL_score.Value
           rNEq=app.Red_Pers.Value*app.Red_Pers_score.Value                                      
           rpers= app.Red_Artillery.Value*app.Red_Helicopter_score.Value;


            app.DialogApp = Variable_resolution_model4(app,app.aa.Value,app.Redloss.Value,app.bb.Value,app.Blueloss.Value,app.BattleTimetEditField.Value,baEq,blEq,bNEq,raEq,rlEq,rNEq);
%             app.DialogApp = Variable_resolution_model4(app,app.aa.Value,app.Redloss.Value,app.bb.Value,app.Blueloss.Value,app.BattleTimetEditField.Value,10,20,30);
        end

        % Button pushed function: MapButton
        function MapButtonPushed(app, event)
            app.DialogApp=minicoy_island_scenario(app)
%            if(strcmp(app.PostureDropDown.Value,"Attack") && ~strcmp(app.PostureDropDown_2.Value,"Attack"))
%             if(strcmp(app.REDCORPS.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDCORPS.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDCORPS.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDCORPS.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDCORPS.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map2(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%             end
%           elseif(~strcmp(app.PostureDropDown.Value,"Attack") && strcmp(app.PostureDropDown_2.Value,"Attack"))
%                if(strcmp(app.REDCORPS.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDCORPS.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDCORPS.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDCORPS.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDCORPS.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map3(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%                end
%           elseif(~strcmp(app.PostureDropDown.Value,"Attack") &&  ~strcmp(app.PostureDropDown_2.Value,"Attack"))
%               if(strcmp(app.REDCORPS.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDCORPS.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDCORPS.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDCORPS.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDCORPS.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map4(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%               end
%           elseif(strcmp(app.PostureDropDown.Value,"Attack") &&  strcmp(app.PostureDropDown_2.Value,"Attack"))
%               if(strcmp(app.REDCORPS.Value,'6th Mechanized Infantry Division'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'MIDiv.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%                   
%                elseif(strcmp(app.REDCORPS.Value,'6th Armour Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'RedArmourRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end  
%                     
%             elseif(strcmp(app.REDCORPS.Value,'17th Infantry Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'17InfRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             elseif(strcmp(app.REDCORPS.Value,'18th Artillery Regiment'))
%                 if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'18ArtyRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                 end
%             elseif(strcmp(app.REDCORPS.Value,'Air Defense Regiment'))
%                if(strcmp(app.BLUECORPS.Value,'T-72 Tank Brigade'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','T72Bgd.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'8th Mountain Division'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','8MountainInfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);
%                elseif(strcmp(app.BLUECORPS.Value,'3rd Infantry Division'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','3InfDiv.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                elseif(strcmp(app.BLUECORPS.Value,'Leh AFS'))
%                     app.DialogApp=scenario_map5(app,'ADRgt.jpg','LehAfs.jpg',app.BattleTimetEditField.Value,app.Redloss.Value,app.Blueloss.Value);    
%                else
%                      app.DialogApp=scenario_map1();    
%                end
%             else
%                 app.DialogApp=scenario_map1(); 
%             end
%           end
        end

        % Menu selected function: ArtilleryMenu
        function ArtilleryMenuSelected(app, event)
           app.DialogApp=weather_effect(app)
        
        end

        % Value changed function: PostureDropDown
        function PostureDropDownValueChanged(app, event)
%             value = app.PostureDropDown.Value;
  app.DialogApp=posture_factor(app);
            u=app.force_strength;
            v=app.vulnerability  ;          
            if(strcmp(app.PostureDropDown.Value,"Attack"))
                app.red_force_strength_multiplier=u(1);
                app.red_vulnerability_multiplier=v(1);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Hasty)"))
                 app.red_force_strength_multiplier=u(2);
                 app.red_vulnerability_multiplier=v(2);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Prepared)"))
                 app.red_force_strength_multiplier=u(3);
                 app.red_vulnerability_multiplier=v(3);
            elseif(strcmp(app.PostureDropDown.Value,"Defence (Fortified)"))
                 app.red_force_strength_multiplier=u(4);
                 app.red_vulnerability_multiplier=v(4);
            elseif(strcmp(app.PostureDropDown.Value,"Withdrawal"))
                 app.red_force_strength_multiplier=u(5);
                 app.red_vulnerability_multiplier=v(5);
            elseif(strcmp(app.PostureDropDown.Value,"Delay"))
                 app.red_force_strength_multiplier=u(6);
                 app.red_vulnerability_multiplier=v(6);
            end
       
        end

        % Value changed function: PostureDropDown_2
        function PostureDropDown_2ValueChanged(app, event)
%             value = app.PostureDropDown_2.Value;
            app.DialogApp=posture_factor(app);
  u=app.force_strength;
            v=app.vulnerability  ;          
            if(strcmp(app.PostureDropDown_2.Value,"Attack"))
                app.blue_force_strength_multiplier=u(1);
                app.blue_vulnerability_multiplier=v(1);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Hasty)"))
                 app.blue_force_strength_multiplier=u(2);
                 app.blue_vulnerability_multiplier=v(2);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Prepared)"))
                 app.blue_force_strength_multiplier=u(3);
                 app.blue_vulnerability_multiplier=v(3);
            elseif(strcmp(app.PostureDropDown_2.Value,"Defence (Fortified)"))
                 app.blue_force_strength_multiplier=u(4);
                 app.blue_vulnerability_multiplier=v(4);
            elseif(strcmp(app.PostureDropDown_2.Value,"Withdrawal"))
                 app.blue_force_strength_multiplier=u(5);
                 app.blue_vulnerability_multiplier=v(5);
            elseif(strcmp(app.PostureDropDown_2.Value,"Delay"))
                 app.blue_force_strength_multiplier=u(6);
                 app.blue_vulnerability_multiplier=v(6);
            end
       
        end

        % Menu selected function: CarrySFandMARCOSMenu
        function CarrySFandMARCOSMenuSelected(app, event)
            app.DialogApp=COA1_minicoy(app)
        end

        % Menu selected function: MobilityEffectMenu
        function MobilityEffectMenuSelected(app, event)
             app.DialogApp=terrain_effect_on_mbility(app);
        end

        % Menu selected function: DefensePostureEffectMenu
        function DefensePostureEffectMenuSelected(app, event)
             app.DialogApp=terrain_effect_on_defense_posture(app);
        end

        % Menu selected function: MobilityMenu
        function MobilityMenuSelected(app, event)
            app.DialogApp=weather_effect(app);
        end

        % Menu selected function: AttackPostureMenu
        function AttackPostureMenuSelected(app, event)
            app.DialogApp=posture_factor(app);
        end

        % Menu selected function: AirMenu
        function AirMenuSelected(app, event)
            app.DialogApp=weather_effect(app);
        end

        % Menu selected function: TankMenu
        function TankMenuSelected(app, event)
            app.DialogApp=weather_effect(app);
        end

        % Menu selected function: NonMobileWeaponsMenu
        function NonMobileWeaponsMenuSelected(app, event)
             app.DialogApp=QJM_nonmobile(app);
        end

        % Menu selected function: MobileWeaponsMenu
        function MobileWeaponsMenuSelected(app, event)
            app.DialogApp=QJM_mobile(app);
        end

        % Menu selected function: MobilityEffectMenu_2
        function MobilityEffectMenu_2Selected(app, event)
             app.DialogApp=AirSupMobility(app);
             
        end

        % Menu selected function: ArtilleryEffectMenu
        function ArtilleryEffectMenuSelected(app, event)
             app.DialogApp=AirSupArtillery(app);
        end

        % Menu selected function: ObjOwnForcesPresentSituationMenu
        function ObjOwnForcesPresentSituationMenuSelected(app, event)
            app.DialogApp=minicoy_obj(app);
        end

        % Menu selected function: CoursesofActionMenu
        function CoursesofActionMenuSelected(app, event)
            app.DialogApp=minicoy_COA(app);
        end

        % Menu selected function: MapMenu
        function MapMenuSelected(app, event)
            app.DialogApp=minicoy_island_scenario_description(app)
        end

        % Value changed function: BLUETHEATER
        function BLUETHEATERValueChanged(app, event)
            value = app.BLUETHEATER.Value;
            if(strcmp(value,'CENTRAL THEATER'))
             app.BLUECORPS.Items={'Central Command'};                
             app.BLUEDIVISIONS.Items={'50 Ind Parachute Bde (Agra)','6 Mtn Div (Bareily'};
             app.BLUEBRIGADES.Items={''}
            elseif(strcmp(value,'EASTERN THEATER'))
             app.BLUECORPS.Items={'III CORPS(DIMAPUR)','IV CORPS','XXXIII CORPS(SILIGURI)','XVII CORPS(PANAGARH)'}; 
              app.BLUEDIVISIONS.Items={''};
             app.BLUEBRIGADES.Items={''}
%              value1=app.BLUECORPS.Value;
%              if(strcmp(value1,'III CORPS(DIMAPUR)'))
%                  app.BLUEDIVISIONS.Items={'2 Mtn Div','57 Mtn Div','56 Inf Div'};
%                  value2=app.BLUEDIVISIONS.Value;
%                  if(strcmp(value2,'2 Mtn Div'))
%                      app.BLUEBRIGADES.Items={'Bde1','Bde2','Bde3'};
%                  elseif(strcmp(value2,'57 Mtn Div'))
%                      app.BLUEBRIGADES.Items={'Bde4','Bde5','Bde6'};
%                  elseif(strcmp(value2,'56 Inf Div'))
%                      app.BLUEBRIGADES.Items={'Bde7','Bde8','Bde9'};
%                  end
%              end
            elseif(strcmp(value,'WESTERN THEATER'))
             app.BLUECORPS.Items={'II CORPS(AMBALA)','IX CORPS','XI CORPS'}; 
              app.BLUEDIVISIONS.Items={''};
             app.BLUEBRIGADES.Items={''}
             end
            
        end

        % Value changed function: BLUEDIVISIONS
        function BLUEDIVISIONSValueChanged(app, event)
            value = app.BLUEDIVISIONS.Value;
                 if(strcmp(value,'2 Mtn Div'))
                     app.BLUEBRIGADES.Items={'Bde1','Bde2','Bde3'};
                 elseif(strcmp(value,'57 Mtn Div'))
                     app.BLUEBRIGADES.Items={'Bde4','Bde5','Bde6'};
                 elseif(strcmp(value,'56 Inf Div'))
                     app.BLUEBRIGADES.Items={'Bde7','Bde8','Bde9'};
                 elseif(strcmp(value,'40 Arty Div (AMBALA)'))
                  app.BLUEBRIGADES.Items={'1x Med Rgt-130mm M-46 or 155 mm Bofors','3x Field Rgt-105 IFG','1x Light Rgt-120 mm Mortars','551 Rocket(SMERCH) Rgt'};
                     
                 end
        end

        % Menu selected function: ForceScoreMultiplersMenu
        function ForceScoreMultiplersMenuSelected(app, event)
             app.DialogApp=FSM1(app);
        end

        % Menu selected function: SituationalCategoryMultiplierMenu
        function SituationalCategoryMultiplierMenuSelected(app, event)
             app.DialogApp=SiCM(app);
        end

        % Menu selected function: ShortageCategoryMultiplierMenu
        function ShortageCategoryMultiplierMenuSelected(app, event)
            app.DialogApp=ShCM(app);
        end

        % Value changed function: BLUEBRIGADES
        function BLUEBRIGADESValueChanged(app, event)
            value = app.BLUEBRIGADES.Value;
            app.DialogApp=SiCM(app);
            app.DialogApp=FSM1(app);
            app.DialogApp=ShCM(app);
            if(strcmp(value,'1x Med Rgt-130mm M-46 or 155 mm Bofors'))
                app.FrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image2.ImageSource='1x Med Rgt-130 mm&150 mm.jpg'
                app.BLue_Artillery=3*6; %% 3 BATTERIES WITH 6 ARTY GUNS
                app.Blue_Arty_score=0.9;    %% pp 27 of correlation of forces
                app.Blue_Pers=2500;
                app.Blue_Pers_Score=1;
                app.Blue_Assualt_rifle=0;
                app.Blue_MG=0;
                app.Blue_Pistol=0;
                app.Blue_shotguns=0;
                app.Blue_snipper_rifle=0;
                app.Blue_GL=0;
                app.Blue_RL=0;
                app.Blue_tank=0;
                app.Blue_APC=0;
                app.Blue_LCVC=0;
                app.Resources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.BLue_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Blue_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Blue_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Blue_MG));...
                                                               strcat('Pistol =',num2str(app.Blue_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Blue_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Blue_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Blue_GL));...
                                                               strcat('RL =',num2str(app.Blue_RL));...
                                                               strcat('Tanks =',num2str(app.Blue_tank));...
                                                               strcat('APC =',num2str(app.Blue_APC));...
                                                               strcat('LCVC =',num2str(app.Blue_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };
%                                                           
            
                elseif(strcmp(value,'3x Field Rgt-105 IFG'))
                app.FrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image2.ImageSource='3xFieldRgt-105IFG.jpg'
                app.BLue_Artillery=3*(3*6); %% 3 BATTERIES WITH 6 ARTY GUNS, 3 multiplier for 3 Field Rgt 
                app.Blue_Arty_score=0.5;    %% pp 27 correlation of forces
                app.Blue_Pers=3*2500;
                app.Blue_Pers_Score=1;
                app.Blue_Assualt_rifle=0;
                app.Blue_MG=0;
                app.Blue_Pistol=0;
                app.Blue_shotguns=0;
                app.Blue_snipper_rifle=0;
                app.Blue_GL=0;
                app.Blue_RL=0;
                app.Blue_tank=0;
                app.Blue_APC=0;
                app.Blue_LCVC=0;
                app.Resources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.BLue_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Blue_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Blue_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Blue_MG));...
                                                               strcat('Pistol =',num2str(app.Blue_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Blue_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Blue_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Blue_GL));...
                                                               strcat('RL =',num2str(app.Blue_RL));...
                                                               strcat('Tanks =',num2str(app.Blue_tank));...
                                                               strcat('APC =',num2str(app.Blue_APC));...
                                                               strcat('LCVC =',num2str(app.Blue_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };
%                                                           
                
           elseif(strcmp(value,'1x Light Rgt-120 mm Mortars'))
                app.FrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image2.ImageSource='1x Light Rgt-120 mm Mortars.jpg'
                app.BLue_Artillery=3*6; %% 3 BATTERIES WITH 6 ARTY GUNS, 
                app.Blue_Arty_score=0.30;    %% pp 27 correlation of forces
                app.Blue_Pers=2500;
                app.Blue_Pers_Score=1;
                app.Blue_Assualt_rifle=0;
                app.Blue_MG=0;
                app.Blue_Pistol=0;
                app.Blue_shotguns=0;
                app.Blue_snipper_rifle=0;
                app.Blue_GL=0;
                app.Blue_RL=0;
                app.Blue_tank=0;
                app.Blue_APC=0;
                app.Blue_LCVC=0;
                app.Resources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.BLue_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Blue_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Blue_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Blue_MG));...
                                                               strcat('Pistol =',num2str(app.Blue_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Blue_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Blue_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Blue_GL));...
                                                               strcat('RL =',num2str(app.Blue_RL));...
                                                               strcat('Tanks =',num2str(app.Blue_tank));...
                                                               strcat('APC =',num2str(app.Blue_APC));...
                                                               strcat('LCVC =',num2str(app.Blue_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };
                elseif(strcmp(value,'551 Rocket(SMERCH) Rgt'))
                app.FrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image2.ImageSource='551 Rocket(SMERCH) Rgt.jpg'
                app.BLue_Artillery=3*6; %% 3 BATTERIES WITH 6 ARTY GUNS, 
                app.Blue_Arty_score=425;    %% pp B-20 (or pp 81) correlation of forces
                app.Blue_Pers=2500;
                app.Blue_Pers_Score=1;
                app.Blue_Assualt_rifle=0;
                app.Blue_MG=0;
                app.Blue_Pistol=0;
                app.Blue_shotguns=0;
                app.Blue_snipper_rifle=0;
                app.Blue_GL=0;
                app.Blue_RL=0;
                app.Blue_tank=0;
                app.Blue_APC=0;
                app.Blue_LCVC=0;
                app.Resources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.BLue_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Blue_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Blue_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Blue_MG));...
                                                               strcat('Pistol =',num2str(app.Blue_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Blue_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Blue_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Blue_GL));...
                                                               strcat('RL =',num2str(app.Blue_RL));...
                                                               strcat('Tanks =',num2str(app.Blue_tank));...
                                                               strcat('APC =',num2str(app.Blue_APC));...
                                                               strcat('LCVC =',num2str(app.Blue_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };
            end
        end

        % Menu selected function: LeadershipMenu
        function LeadershipMenuSelected(app, event)
              app.DialogApp=leadership(app);
        end

        % Menu selected function: TrainingExperiencesMenu
        function TrainingExperiencesMenuSelected(app, event)
            app.DialogApp=training_exp(app);
        end

        % Menu selected function: MoraleMenu
        function MoraleMenuSelected(app, event)
             app.DialogApp=morale(app);
        end

        % Menu selected function: LogisticMenu
        function LogisticMenuSelected(app, event)
             app.DialogApp=logistic(app);
        end

        % Menu selected function: TankMenu_2
        function TankMenu_2Selected(app, event)
            app.DialogApp=score_tank_dropdown(app);
        end

        % Value changed function: REDTHEATER
        function REDTHEATERValueChanged(app, event)
            value = app.REDTHEATER.Value;
            if(strcmp(value,'THEATER 1'))
             app.REDCORPS.Items={'Central Command'};                
             app.REDDIVISIONS.Items={'50 Ind Parachute Bde (Agra)','6 Mtn Div (Bareily'};
             app.REDBRIGADES.Items={''}
            elseif(strcmp(value,'THEATER 2'))
             app.REDCORPS.Items={'III CORPS(DIMAPUR)','IV CORPS','XXXIII CORPS(SILIGURI)','XVII CORPS(PANAGARH)'}; 
              app.REDDIVISIONS.Items={''};
             app.REDBRIGADES.Items={''}
            elseif(strcmp(value,'STRATEGIC THEATER'))
             app.REDCORPS.Items={'STRATEGIC FORCE COMMAND','CORPS I','CORPS II'}; 
              app.REDDIVISIONS.Items={''}; %{'21 Arty Div(Pano Aquil)','22 Arty Div (Sargodha)'};
             app.REDBRIGADES.Items={''};
             end
        end

        % Value changed function: REDDIVISIONS
        function REDDIVISIONSValueChanged(app, event)
            value = app.REDDIVISIONS.Value;
                 if(strcmp(value,'2 Mtn Div'))
                     app.REDBRIGADES.Items={'Bde1','Bde2','Bde3'};
                 elseif(strcmp(value,'57 Mtn Div'))
                     app.REDBRIGADES.Items={'Bde4','Bde5','Bde6'};
                 elseif(strcmp(value,'22 Arty Div (Sargodha)'))
                     app.REDBRIGADES.Items={'1x Med Rgt-203 mm M-115 or 155 mm Panta','3x Field Rgt-105 mm M101','1x Light Rgt-122 mm Type 54-1','MLRS A-100 Rgt'};
                 elseif(strcmp(value,'21 Arty Div(Pano Aquil)'))
                  app.REDBRIGADES.Items={'1x Med Rgt-203mm M-110 or 155 mm M109','3x Field Rgt-105 mm Mod 56','1x Light Rgt-122 mm Type 86','MLRS A-100 Rgt'};
                     
                 end
            
        end

        % Value changed function: REDBRIGADES
        function REDBRIGADESValueChanged(app, event)
            value = app.REDBRIGADES.Value;
            app.DialogApp=SiCM(app);
            app.DialogApp=FSM1(app);
            app.DialogApp=ShCM(app);
            if(strcmp(value,'1x Med Rgt-203 mm M-115 or 155 mm Panta'))
                app.REDFrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image.ImageSource='1x Med Rgt-203 mm M-115 or 155 mm Panta.jpg'
                app.Red_Artillery=3*6; %% 3 BATTERIES WITH 6 ARTY GUNS
                app.Red_Arty_score=0.9;    %% pp 27 of correlation of forces
                app.Red_Pers=2500;
                app.Red_Pers_Score=1;
                app.Red_Assualt_rifle=0;
                app.Red_MG=0;
                app.Red_Pistol=0;
                app.Red_shotguns=0;
                app.Red_snipper_rifle=0;
                app.Red_GL=0;
                app.Red_RL=0;
                app.Red_tank=0;
                app.Red_APC=0;
                app.Red_LCVC=0;
                app.REDResources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.Red_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Red_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Red_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Red_MG));...
                                                               strcat('Pistol =',num2str(app.Red_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Red_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Red_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Red_GL));...
                                                               strcat('RL =',num2str(app.Red_RL));...
                                                               strcat('Tanks =',num2str(app.Red_tank));...
                                                               strcat('APC =',num2str(app.Red_APC));...
                                                               strcat('LCVC =',num2str(app.Red_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };
                
                elseif(strcmp(value,'3x Field Rgt-105 mm M101'))
                app.REDFrontageConstraintsEditField.Value=app.ShCM_mult*100;
                app.Image.ImageSource='3x Field Rgt-105 mm M101.jpg'
                app.Red_Artillery=3*(3*6); %% 3 BATTERIES WITH 6 ARTY GUNS
                app.Red_Arty_score=0.9;    %% pp 27 of correlation of forces
                app.Red_Pers=7500;
                app.Red_Pers_Score=1;
                app.Red_Assualt_rifle=0;
                app.Red_MG=0;
                app.Red_Pistol=0;
                app.Red_shotguns=0;
                app.Red_snipper_rifle=0;
                app.Red_GL=0;
                app.Red_RL=0;
                app.Red_tank=0;
                app.Red_APC=0;
                app.Red_LCVC=0;
                app.REDResources.Value ={ 'Reources';'---------------';strcat('Arty=',num2str(app.Red_Artillery)); ...
                                                              strcat('Pers=',num2str(app.Red_Pers));...
                                                              strcat('Assualt Rifle=',num2str(app.Red_Assualt_rifle));...
                                                              strcat('Machine Guns =',num2str(app.Red_MG));...
                                                               strcat('Pistol =',num2str(app.Red_Pistol));...
                                                               strcat('Shot Guns =',num2str(app.Red_shotguns));...
                                                               strcat('Sniper Rifle =',num2str(app.Red_snipper_rifle));...
                                                               strcat('GL =',num2str(app.Red_GL));...
                                                               strcat('RL =',num2str(app.Red_RL));...
                                                               strcat('Tanks =',num2str(app.Red_tank));...
                                                               strcat('APC =',num2str(app.Red_APC));...
                                                               strcat('LCVC =',num2str(app.Red_LCVC));...
                                                               strcat('FSM=', num2str(app.FS_value));...
                                                               strcat('SiCM=', num2str(app.SiCM_mult));...
                                                                strcat('ShCM=', num2str(app.ShCM_mult));...
                                                                strcat('terrain effect on mobility multiplier=', num2str(app.terrain_effect_on_mobility));...
                                                                 strcat('terrain effect on def Posture multiplier=', num2str(app.terrain_effect_on_defense_posture));...
                                                               };                                            
            end
        end

        % Value changed function: Resources
        function ResourcesValueChanged(app, event)
            value = app.Resources.Value;
            
        end

        % Menu selected function: DrawScenarioMenu
        function DrawScenarioMenuSelected(app, event)
            app.DialogApp=Draw_Scenario(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CombatPotentialComputationUIFigure = uifigure('Visible', 'off');
            app.CombatPotentialComputationUIFigure.Name = 'Combat Potential Computation';
            app.CombatPotentialComputationUIFigure.CloseRequestFcn = createCallbackFcn(app, @CombatPotentialComputationUIFigureCloseRequest, true);
            app.CombatPotentialComputationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.CombatPotentialComputationUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CORPSDropDownLabel = uilabel(app.LeftPanel);
            app.CORPSDropDownLabel.Text = 'CORPS';
            app.REDCORPS = uidropdown(app.LeftPanel);
            app.REDCORPS.Items = {};
            app.REDCORPS.ValueChangedFcn = createCallbackFcn(app, @REDCORPSValueChanged, true);
            app.REDCORPS.Value = {};
            app.TotalCombatPotentialLabel = uilabel(app.LeftPanel);
            app.TotalCombatPotentialLabel.Text = 'Total Combat Potential';
            app.aa = uieditfield(app.LeftPanel, 'numeric');
            app.aa.ValueDisplayFormat = '%.2f';
            app.COMPUTEButton = uibutton(app.LeftPanel, 'push');
            app.COMPUTEButton.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEButtonPushed2, true);
            app.COMPUTEButton.Text = 'COMPUTE';
            app.TerrainDropDownLabel = uilabel(app.LeftPanel);
            app.TerrainDropDownLabel.Text = 'Terrain';
            app.TerrainDropDown = uidropdown(app.LeftPanel);
            app.TerrainDropDown.Items = {'Open', 'Urban', 'Rough', 'Mountain'};
            app.TerrainDropDown.ValueChangedFcn = createCallbackFcn(app, @TerrainDropDownValueChanged, true);
            app.TerrainDropDown.Value = 'Open';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'MIDiv.jpg';
            app.PostureDropDownLabel = uilabel(app.LeftPanel);
            app.PostureDropDownLabel.Text = ' Posture';
            app.PostureDropDown = uidropdown(app.LeftPanel);
            app.PostureDropDown.Items = {'Attack', 'Defence (Hasty)', 'Defence (Prepared)', 'Defence (Fortified)', 'Withdrawal', 'Delay'};
            app.PostureDropDown.ValueChangedFcn = createCallbackFcn(app, @PostureDropDownValueChanged, true);
            app.PostureDropDown.Value = 'Attack';
            app.DIVISIONSLabel = uilabel(app.LeftPanel);
            app.DIVISIONSLabel.Text = 'DIVISIONS';
            app.REDDIVISIONS = uidropdown(app.LeftPanel);
            app.REDDIVISIONS.Items = {};
            app.REDDIVISIONS.ValueChangedFcn = createCallbackFcn(app, @REDDIVISIONSValueChanged, true);
            app.REDDIVISIONS.Value = {};
            app.THEATERDropDown_2Label = uilabel(app.LeftPanel);
            app.THEATERDropDown_2Label.Text = 'THEATER';
            app.REDTHEATER = uidropdown(app.LeftPanel);
            app.REDTHEATER.Items = {'STRATEGIC THEATER', 'THEATER 1', 'THEATER 2'};
            app.REDTHEATER.ValueChangedFcn = createCallbackFcn(app, @REDTHEATERValueChanged, true);
            app.REDTHEATER.Value = 'STRATEGIC THEATER';
            app.SUBUNITSLabel_2 = uilabel(app.LeftPanel);
            app.SUBUNITSLabel_2.Text = 'SUB-UNITS';
            app.REDBRIGADES = uidropdown(app.LeftPanel);
            app.REDBRIGADES.Items = {};
            app.REDBRIGADES.ValueChangedFcn = createCallbackFcn(app, @REDBRIGADESValueChanged, true);
            app.REDBRIGADES.Value = {};
            app.FrontageConstraintsEditFieldLabel_3 = uilabel(app.LeftPanel);
            app.FrontageConstraintsEditFieldLabel_3.Text = 'Wpns/KM';
            app.FrontageConstraintsEditField_2Label = uilabel(app.LeftPanel);
            app.FrontageConstraintsEditField_2Label.Text = 'Frontage Constraints';
            app.REDFrontageConstraintsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ResourcesLabel_2 = uilabel(app.LeftPanel);
            app.ResourcesLabel_2.Text = 'Resources';
            app.REDResources = uitextarea(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.CORPSDropDown_2Label = uilabel(app.CenterPanel);
            app.CORPSDropDown_2Label.Text = 'CORPS';
            app.BLUECORPS = uidropdown(app.CenterPanel);
            app.BLUECORPS.Items = {'I Corps', 'II Corps', 'III Corps', 'IV Corps'};
            app.BLUECORPS.ValueChangedFcn = createCallbackFcn(app, @BLUECORPSValueChanged, true);
            app.BLUECORPS.Value = 'I Corps';
            app.TerrainDropDown_2Label = uilabel(app.CenterPanel);
            app.TerrainDropDown_2Label.Text = 'Terrain';
            app.TerrainDropDown_2 = uidropdown(app.CenterPanel);
            app.TerrainDropDown_2.Items = {'Open', 'Urban', 'Rough', 'Mountain'};
            app.TerrainDropDown_2.ValueChangedFcn = createCallbackFcn(app, @TerrainDropDown_2ValueChanged, true);
            app.TerrainDropDown_2.Value = 'Open';
            app.TotalCombatPotentialLabel_2 = uilabel(app.CenterPanel);
            app.TotalCombatPotentialLabel_2.Text = 'Total Combat Potential';
            app.COMPUTEButton_2 = uibutton(app.CenterPanel, 'push');
            app.COMPUTEButton_2.ButtonPushedFcn = createCallbackFcn(app, @COMPUTEButton_2Pushed, true);
            app.COMPUTEButton_2.Text = 'COMPUTE';
            app.bb = uieditfield(app.CenterPanel, 'numeric');
            app.bb.ValueDisplayFormat = '%.2f';
            app.Image2 = uiimage(app.CenterPanel);
            app.Image2.ImageSource = 'T72Bgd.jpg';
            app.PostureDropDown_2Label = uilabel(app.CenterPanel);
            app.PostureDropDown_2Label.Text = ' Posture';
            app.PostureDropDown_2 = uidropdown(app.CenterPanel);
            app.PostureDropDown_2.Items = {'Attack', 'Defence (Hasty)', 'Defence (Prepared)', 'Defence (Fortified)', 'Withdrawal', 'Delay'};
            app.PostureDropDown_2.ValueChangedFcn = createCallbackFcn(app, @PostureDropDown_2ValueChanged, true);
            app.PostureDropDown_2.Value = 'Attack';
            app.DIVISIONSLabel_2 = uilabel(app.CenterPanel);
            app.DIVISIONSLabel_2.Text = 'DIVISIONS';
            app.BLUEDIVISIONS = uidropdown(app.CenterPanel);
            app.BLUEDIVISIONS.Items = {};
            app.BLUEDIVISIONS.ValueChangedFcn = createCallbackFcn(app, @BLUEDIVISIONSValueChanged, true);
            app.BLUEDIVISIONS.Value = {};
            app.THEATERDropDownLabel = uilabel(app.CenterPanel);
            app.THEATERDropDownLabel.Text = 'THEATER';
            app.BLUETHEATER = uidropdown(app.CenterPanel);
            app.BLUETHEATER.Items = {'CENTRAL THEATER', 'EASTERN THEATER', 'WESTERN THEATER'};
            app.BLUETHEATER.ValueChangedFcn = createCallbackFcn(app, @BLUETHEATERValueChanged, true);
            app.BLUETHEATER.Value = 'EASTERN THEATER';
            app.SUBUNITSLabel = uilabel(app.CenterPanel);
            app.SUBUNITSLabel.Text = 'SUB-UNITS';
            app.BLUEBRIGADES = uidropdown(app.CenterPanel);
            app.BLUEBRIGADES.Items = {};
            app.BLUEBRIGADES.ValueChangedFcn = createCallbackFcn(app, @BLUEBRIGADESValueChanged, true);
            app.BLUEBRIGADES.Value = {};
            app.ResourcesLabel = uilabel(app.CenterPanel);
            app.ResourcesLabel.Text = 'Resources';
            app.Resources = uitextarea(app.CenterPanel);
            app.Resources.ValueChangedFcn = createCallbackFcn(app, @ResourcesValueChanged, true);
            app.FrontageConstraintsEditFieldLabel = uilabel(app.CenterPanel);
            app.FrontageConstraintsEditFieldLabel.Text = 'Frontage Constraints';
            app.FrontageConstraintsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FrontageConstraintsEditFieldLabel_2 = uilabel(app.CenterPanel);
            app.FrontageConstraintsEditFieldLabel_2.Text = 'Wpns/KM';
            app.RightPanel = uipanel(app.GridLayout);
            app.DefenderUIAxes = uiaxes(app.RightPanel);
            title(app.DefenderUIAxes, 'Red verses Blue')
            xlabel(app.DefenderUIAxes, 'time(in min)')
            ylabel(app.DefenderUIAxes, 'Combat Power  ')
            app.DefenderUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60];
            app.DefenderUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'; '51'; '52'; '53'; '54'; '55'; '56'; '57'; '58'; '59'; '60'};
            app.AttackerUIAxes = uiaxes(app.RightPanel);
            title(app.AttackerUIAxes, 'Force Ratio')
            xlabel(app.AttackerUIAxes, 'Time (in Min)')
            ylabel(app.AttackerUIAxes, 'Force Ratio (RED/BLUE)')
            app.AttackerUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.AttackerUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '1'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'; '16'; '17'; '18'; '19'; '20'; '21'; '22'; '23'; '24'; '25'; '26'; '27'; '28'; '29'; '30'; '31'; '32'; '33'; '34'; '35'; '36'; '37'; '38'; '39'; '40'; '41'; '42'; '43'; '44'; '45'; '46'; '47'; '48'; '49'; '50'};
            app.fig3UIAxes = uiaxes(app.RightPanel);
            title(app.fig3UIAxes, 'Contour Plot of SSR')
            xlabel(app.fig3UIAxes, 'p')
            ylabel(app.fig3UIAxes, 'q')
            app.fig4UIAxes = uiaxes(app.RightPanel);
            title(app.fig4UIAxes, '3D Surface of SSR')
            xlabel(app.fig4UIAxes, 'p')
            ylabel(app.fig4UIAxes, 'q')
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '[Referance]:The strategic Postures of China and India, Frank O''Donnell and Alex Bollfrass, HARVARD Kennedy School, BELFER CENTER for Science and International Affairs, March 2020.';
            app.ForceRatioREDBLUEButton = uibutton(app.RightPanel, 'push');
            app.ForceRatioREDBLUEButton.ButtonPushedFcn = createCallbackFcn(app, @ForceRatioREDBLUEButtonPushed, true);
            app.ForceRatioREDBLUEButton.Text = 'Force Ratio   (RED/BLUE)   =';
            app.FR = uieditfield(app.RightPanel, 'numeric');
            app.ComputeAttritionRatesButton = uibutton(app.RightPanel, 'push');
            app.ComputeAttritionRatesButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeAttritionRatesButtonPushed, true);
            app.ComputeAttritionRatesButton.Text = 'Compute Attrition Rates';
            app.outputA = uieditfield(app.RightPanel, 'numeric');
            app.outputA.ValueDisplayFormat = '%7.5f';
            app.outputA.Editable = 'off';
            app.outputB = uieditfield(app.RightPanel, 'numeric');
            app.outputB.ValueDisplayFormat = '%7.5f';
            app.outputB.Editable = 'off';
            app.A = uilabel(app.RightPanel);
            app.A.Text = 'RED Attrition Rate (A)';
            app.B = uilabel(app.RightPanel);
            app.B.Text = 'BLUE Attrition Rate (B)';
            app.BattleTimetEditFieldLabel = uilabel(app.RightPanel);
            app.BattleTimetEditFieldLabel.Text = 'Battle Time (t)';
            app.BattleTimetEditField = uieditfield(app.RightPanel, 'numeric');
            app.BattleTimetEditField.Limits = [0 150];
            app.BattleTimetEditField.ValueDisplayFormat = '%.0f';
            app.BattleTimetEditField.Value = 14;
            app.RedLossEditFieldLabel = uilabel(app.RightPanel);
            app.RedLossEditFieldLabel.Text = 'Red Loss';
            app.Redloss = uieditfield(app.RightPanel, 'numeric');
            app.Redloss.Value = 90;
            app.BlueLossEditFieldLabel = uilabel(app.RightPanel);
            app.BlueLossEditFieldLabel.Text = 'Blue Loss';
            app.Blueloss = uieditfield(app.RightPanel, 'numeric');
            app.Blueloss.Value = 10;
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '%';
            app.AnalysisButton = uibutton(app.RightPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.MapButton = uibutton(app.RightPanel, 'push');
            app.MapButton.ButtonPushedFcn = createCallbackFcn(app, @MapButtonPushed, true);
            app.MapButton.Text = 'Map';
            app.ABLabel = uilabel(app.RightPanel);
            app.ABLabel.Text = 'A/B  =';
            app.ABRatio = uieditfield(app.RightPanel, 'numeric');
            app.TerrainMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.TerrainMenu.Text = 'Terrain';
            app.MobilityEffectMenu = uimenu(app.TerrainMenu);
            app.MobilityEffectMenu.MenuSelectedFcn = createCallbackFcn(app, @MobilityEffectMenuSelected, true);
            app.MobilityEffectMenu.Text = 'Mobility Effect';
            app.DefensePostureEffectMenu = uimenu(app.TerrainMenu);
            app.DefensePostureEffectMenu.MenuSelectedFcn = createCallbackFcn(app, @DefensePostureEffectMenuSelected, true);
            app.DefensePostureEffectMenu.Text = 'Defense Posture Effect';
            app.WeatherMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.WeatherMenu.Text = 'Weather';
            app.MobilityMenu = uimenu(app.WeatherMenu);
            app.MobilityMenu.MenuSelectedFcn = createCallbackFcn(app, @MobilityMenuSelected, true);
            app.MobilityMenu.Text = 'Mobility';
            app.AttackPostureMenu = uimenu(app.WeatherMenu);
            app.AttackPostureMenu.MenuSelectedFcn = createCallbackFcn(app, @AttackPostureMenuSelected, true);
            app.AttackPostureMenu.Text = 'Attack Posture';
            app.ArtilleryMenu = uimenu(app.WeatherMenu);
            app.ArtilleryMenu.MenuSelectedFcn = createCallbackFcn(app, @ArtilleryMenuSelected, true);
            app.ArtilleryMenu.Text = 'Artillery';
            app.AirMenu = uimenu(app.WeatherMenu);
            app.AirMenu.MenuSelectedFcn = createCallbackFcn(app, @AirMenuSelected, true);
            app.AirMenu.Text = 'Air';
            app.TankMenu = uimenu(app.WeatherMenu);
            app.TankMenu.MenuSelectedFcn = createCallbackFcn(app, @TankMenuSelected, true);
            app.TankMenu.Text = 'Tank';
            app.IntangibleFactorMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.IntangibleFactorMenu.Text = 'Intangible Factor';
            app.CombatEffectivenessMenu = uimenu(app.IntangibleFactorMenu);
            app.CombatEffectivenessMenu.Text = 'Combat Effectiveness';
            app.LeadershipMenu = uimenu(app.CombatEffectivenessMenu);
            app.LeadershipMenu.MenuSelectedFcn = createCallbackFcn(app, @LeadershipMenuSelected, true);
            app.LeadershipMenu.Text = 'Leadership';
            app.TrainingExperiencesMenu = uimenu(app.CombatEffectivenessMenu);
            app.TrainingExperiencesMenu.MenuSelectedFcn = createCallbackFcn(app, @TrainingExperiencesMenuSelected, true);
            app.TrainingExperiencesMenu.Text = 'Training Experiences';
            app.MoraleMenu = uimenu(app.CombatEffectivenessMenu);
            app.MoraleMenu.MenuSelectedFcn = createCallbackFcn(app, @MoraleMenuSelected, true);
            app.MoraleMenu.Text = 'Morale';
            app.LogisticMenu = uimenu(app.CombatEffectivenessMenu);
            app.LogisticMenu.MenuSelectedFcn = createCallbackFcn(app, @LogisticMenuSelected, true);
            app.LogisticMenu.Text = 'Logistic';
            app.TimeMenu = uimenu(app.CombatEffectivenessMenu);
            app.TimeMenu.Text = 'Time';
            app.SpaceMenu = uimenu(app.CombatEffectivenessMenu);
            app.SpaceMenu.Text = 'Space';
            app.MomentumMenu = uimenu(app.CombatEffectivenessMenu);
            app.MomentumMenu.Text = 'Momentum';
            app.IntelligenceMenu = uimenu(app.CombatEffectivenessMenu);
            app.IntelligenceMenu.Text = 'Intelligence';
            app.TechnologyMenu = uimenu(app.CombatEffectivenessMenu);
            app.TechnologyMenu.Text = 'Technology';
            app.InitiativeMenu = uimenu(app.CombatEffectivenessMenu);
            app.InitiativeMenu.Text = 'Initiative';
            app.AirDominanceMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.AirDominanceMenu.Text = 'Air Dominance';
            app.AirSuperiorityMenu = uimenu(app.AirDominanceMenu);
            app.AirSuperiorityMenu.Text = 'Air Superiority';
            app.MobilityEffectMenu_2 = uimenu(app.AirSuperiorityMenu);
            app.MobilityEffectMenu_2.MenuSelectedFcn = createCallbackFcn(app, @MobilityEffectMenu_2Selected, true);
            app.MobilityEffectMenu_2.Text = 'Mobility Effect';
            app.ArtilleryEffectMenu = uimenu(app.AirSuperiorityMenu);
            app.ArtilleryEffectMenu.MenuSelectedFcn = createCallbackFcn(app, @ArtilleryEffectMenuSelected, true);
            app.ArtilleryEffectMenu.Text = 'Artillery Effect';
            app.AirEffectivenessEffectMenu = uimenu(app.AirSuperiorityMenu);
            app.AirEffectivenessEffectMenu.Text = 'Air Effectiveness Effect';
            app.VulnerabilityEffectMenu = uimenu(app.AirSuperiorityMenu);
            app.VulnerabilityEffectMenu.Text = 'Vulnerability Effect';
            app.AirEqualityMenu = uimenu(app.AirDominanceMenu);
            app.AirEqualityMenu.Text = 'Air Equality';
            app.AirInferiorityMenu = uimenu(app.AirDominanceMenu);
            app.AirInferiorityMenu.Text = 'Air Inferiority';
            app.SituationMultipliersMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.SituationMultipliersMenu.Text = 'Situation Multipliers';
            app.ForceScoreMultiplersMenu = uimenu(app.SituationMultipliersMenu);
            app.ForceScoreMultiplersMenu.MenuSelectedFcn = createCallbackFcn(app, @ForceScoreMultiplersMenuSelected, true);
            app.ForceScoreMultiplersMenu.Text = 'Force Score Multiplers';
            app.SituationalCategoryMultiplierMenu = uimenu(app.SituationMultipliersMenu);
            app.SituationalCategoryMultiplierMenu.MenuSelectedFcn = createCallbackFcn(app, @SituationalCategoryMultiplierMenuSelected, true);
            app.SituationalCategoryMultiplierMenu.Text = 'Situational Category Multiplier';
            app.ShortageCategoryMultiplierMenu = uimenu(app.SituationMultipliersMenu);
            app.ShortageCategoryMultiplierMenu.MenuSelectedFcn = createCallbackFcn(app, @ShortageCategoryMultiplierMenuSelected, true);
            app.ShortageCategoryMultiplierMenu.Text = 'Shortage Category Multiplier';
            app.MaritimeStrikeCapabilityMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.MaritimeStrikeCapabilityMenu.Text = 'Maritime Strike Capability';
            app.Menu = uimenu(app.MaritimeStrikeCapabilityMenu);
            app.Menu.Text = 'Menu';
            app.ScoresMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.ScoresMenu.Text = 'Scores';
            app.AirComponentsMenu = uimenu(app.ScoresMenu);
            app.AirComponentsMenu.Text = 'Air Components';
            app.LandComponentMenu = uimenu(app.ScoresMenu);
            app.LandComponentMenu.Text = 'Land Component';
            app.TankMenu_2 = uimenu(app.LandComponentMenu);
            app.TankMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TankMenu_2Selected, true);
            app.TankMenu_2.Text = 'Tank';
            app.NavalComponentMenu = uimenu(app.ScoresMenu);
            app.NavalComponentMenu.Text = 'Naval Component';
            app.QJMMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.QJMMenu.Text = 'QJM';
            app.NonMobileWeaponsMenu = uimenu(app.QJMMenu);
            app.NonMobileWeaponsMenu.MenuSelectedFcn = createCallbackFcn(app, @NonMobileWeaponsMenuSelected, true);
            app.NonMobileWeaponsMenu.Text = 'Non-Mobile Weapons';
            app.MobileWeaponsMenu = uimenu(app.QJMMenu);
            app.MobileWeaponsMenu.MenuSelectedFcn = createCallbackFcn(app, @MobileWeaponsMenuSelected, true);
            app.MobileWeaponsMenu.Text = 'Mobile Weapons';
            app.CombatPowerMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.CombatPowerMenu.Text = 'Combat Power';
            app.AirPowerMenu = uimenu(app.CombatPowerMenu);
            app.AirPowerMenu.Text = 'Air Power';
            app.FighterMenu = uimenu(app.AirPowerMenu);
            app.FighterMenu.Text = 'Fighter';
            app.EscortMenu = uimenu(app.AirPowerMenu);
            app.EscortMenu.Text = 'Escort';
            app.TransportACMenu = uimenu(app.AirPowerMenu);
            app.TransportACMenu.Text = 'Transport AC';
            app.HelicopterMenu = uimenu(app.AirPowerMenu);
            app.HelicopterMenu.Text = 'Helicopter';
            app.LandPowerMenu = uimenu(app.CombatPowerMenu);
            app.LandPowerMenu.Text = 'Land Power';
            app.SFMenu = uimenu(app.LandPowerMenu);
            app.SFMenu.Text = 'SF';
            app.AmphibiousBrigadeMenu = uimenu(app.LandPowerMenu);
            app.AmphibiousBrigadeMenu.Text = 'Amphibious Brigade';
            app.NavalPowerMenu = uimenu(app.CombatPowerMenu);
            app.NavalPowerMenu.Text = 'Naval Power';
            app.MaritimeFighterMenu = uimenu(app.NavalPowerMenu);
            app.MaritimeFighterMenu.Text = 'Maritime Fighter';
            app.INSJALASWAMenu = uimenu(app.NavalPowerMenu);
            app.INSJALASWAMenu.Text = 'INS JALASWA';
            app.AiravatMenu = uimenu(app.NavalPowerMenu);
            app.AiravatMenu.Text = 'Airavat';
            app.GuldarMenu = uimenu(app.NavalPowerMenu);
            app.GuldarMenu.Text = 'Guldar';
            app.LCVMK4Menu = uimenu(app.NavalPowerMenu);
            app.LCVMK4Menu.Text = 'LCV MK4';
            app.MARCOSMenu = uimenu(app.NavalPowerMenu);
            app.MARCOSMenu.Text = 'MARCOS';
            app.CourseofActionMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.CourseofActionMenu.Text = 'Course of Action';
            app.CarrySFandMARCOSMenu = uimenu(app.CourseofActionMenu);
            app.CarrySFandMARCOSMenu.MenuSelectedFcn = createCallbackFcn(app, @CarrySFandMARCOSMenuSelected, true);
            app.CarrySFandMARCOSMenu.Text = 'Carry SF and MARCOS';
            app.DrawScenarioMenu = uimenu(app.CourseofActionMenu);
            app.DrawScenarioMenu.MenuSelectedFcn = createCallbackFcn(app, @DrawScenarioMenuSelected, true);
            app.DrawScenarioMenu.Text = 'Draw Scenario';
            app.ConflictPointMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.ConflictPointMenu.Text = 'Conflict Point';
            app.SecuringtheMinicoyPortfromANEbySFandMARCOSMenu = uimenu(app.ConflictPointMenu);
            app.SecuringtheMinicoyPortfromANEbySFandMARCOSMenu.Text = 'Securing the Minicoy Port from ANE by SF and MARCOS';
            app.MovingtowardsAirstirpewhichis12kmawayMenu = uimenu(app.ConflictPointMenu);
            app.MovingtowardsAirstirpewhichis12kmawayMenu.Text = 'Moving towards Airstirpe which is 12 km away ';
            app.CombattofreetheairstripebySFMARCOSAMPHForceMenu = uimenu(app.ConflictPointMenu);
            app.CombattofreetheairstripebySFMARCOSAMPHForceMenu.Text = 'Combat to free the airstripe by SF+MARCOS+AMPH Force';
            app.RemovingtheAAgunMenu = uimenu(app.ConflictPointMenu);
            app.RemovingtheAAgunMenu.Text = 'Removing the AA gun';
            app.SecuringthePortandAirstripeMenu = uimenu(app.ConflictPointMenu);
            app.SecuringthePortandAirstripeMenu.Text = 'Securing the Port and Airstripe';
            app.FreetheHostagesandkilltheANEfromGovtResidenceMenu = uimenu(app.ConflictPointMenu);
            app.FreetheHostagesandkilltheANEfromGovtResidenceMenu.Text = 'Free the Hostages and kill the ANE from Govt Residence';
            app.IntelligenceMenu_2 = uimenu(app.CombatPotentialComputationUIFigure);
            app.IntelligenceMenu_2.Text = 'Intelligence';
            app.LocationofPortAirstripeMenu = uimenu(app.IntelligenceMenu_2);
            app.LocationofPortAirstripeMenu.Text = 'Location of Port & Airstripe';
            app.LocationofAAgunsMenu = uimenu(app.IntelligenceMenu_2);
            app.LocationofAAgunsMenu.Text = 'Location of AA guns';
            app.CoordinationMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.CoordinationMenu.Text = 'Coordination';
            app.ScenarioDescriptionMenu = uimenu(app.CombatPotentialComputationUIFigure);
            app.ScenarioDescriptionMenu.Text = 'Scenario Description';
            app.ObjOwnForcesPresentSituationMenu = uimenu(app.ScenarioDescriptionMenu);
            app.ObjOwnForcesPresentSituationMenu.MenuSelectedFcn = createCallbackFcn(app, @ObjOwnForcesPresentSituationMenuSelected, true);
            app.ObjOwnForcesPresentSituationMenu.Text = 'Obj / Own Forces / Present Situation';
            app.CoursesofActionMenu = uimenu(app.ScenarioDescriptionMenu);
            app.CoursesofActionMenu.MenuSelectedFcn = createCallbackFcn(app, @CoursesofActionMenuSelected, true);
            app.CoursesofActionMenu.Text = 'Courses of Action';
            app.MapMenu = uimenu(app.ScenarioDescriptionMenu);
            app.MapMenu.MenuSelectedFcn = createCallbackFcn(app, @MapMenuSelected, true);
            app.MapMenu.Text = 'Map';
            % Show the figure after all components are created
            app.CombatPotentialComputationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Multi_Resolution_Combat_Potential(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CombatPotentialComputationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CombatPotentialComputationUIFigure)
        end
    end
end