classdef QJM_mobile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OLICalculationofMobileWeaponsLabel  matlab.ui.control.Label
        WeaponsFirePowerWLabel          matlab.ui.control.Label
        WeaponsFirePowerWEditField      matlab.ui.control.NumericEditField
        MobilityFactorMOFLabel          matlab.ui.control.Label
        MobilityFactorMOFEditField      matlab.ui.control.NumericEditField
        RadiusOfActionLabel             matlab.ui.control.Label
        RadiusOfActionEditField         matlab.ui.control.NumericEditField
        PunishmentFactorLabel           matlab.ui.control.Label
        PunishmentFactor                matlab.ui.control.NumericEditField
        RapidityofFireEffectRFELabel    matlab.ui.control.Label
        RFE                             matlab.ui.control.NumericEditField
        FireControlEffectFCELabel       matlab.ui.control.Label
        FCE                             matlab.ui.control.NumericEditField
        AmmunitionSupplyEffectASELabel  matlab.ui.control.Label
        ASE                             matlab.ui.control.NumericEditField
        AircraftMountEffectAMELabel     matlab.ui.control.Label
        AME                             matlab.ui.control.NumericEditField
        CeilingFactorCLLabel            matlab.ui.control.Label
        CL                              matlab.ui.control.NumericEditField
        ComputeOLIButton                matlab.ui.control.Button
        OLIWEditFieldLabel              matlab.ui.control.Label
        OLIWEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp; % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: ComputeOLIButton
        function ComputeOLIButtonPushed(app, event)
           W=app.WeaponsFirePowerWEditField.Value;
           MOF=app.MobilityFactorMOFEditField.Value;
           RA=app.RadiusOfActionEditField.Value;
           PF=app.PunishmentFactor.Value;
           RFE=app.RFE.Value;
           FCE=app.FCE.Value;
           ASE=app.ASE.Value;
           AME=app.AME.Value;
           CL=app.CL.Value;
          
            W=((W*MOF*RA)+PF)*RFE*FCE*ASE*AME*CL;
              app.OLIWEditField.Value=W;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OLICalculationofMobileWeaponsLabel = uilabel(app.UIFigure);
            app.OLICalculationofMobileWeaponsLabel.Text = 'OLI Calculation of Mobile Weapons';
            app.WeaponsFirePowerWLabel = uilabel(app.UIFigure);
            app.WeaponsFirePowerWLabel.Text = 'Weapons Fire Power (W) =';
            app.WeaponsFirePowerWEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeaponsFirePowerWEditField.Value = 0.1;
            app.MobilityFactorMOFLabel = uilabel(app.UIFigure);
            app.MobilityFactorMOFLabel.Text = 'Mobility Factor (MOF) =';
            app.MobilityFactorMOFEditField = uieditfield(app.UIFigure, 'numeric');
            app.MobilityFactorMOFEditField.Value = 0.2;
            app.RadiusOfActionLabel = uilabel(app.UIFigure);
            app.RadiusOfActionLabel.Text = 'Radius of Action (RA) =';
            app.RadiusOfActionEditField = uieditfield(app.UIFigure, 'numeric');
            app.RadiusOfActionEditField.Value = 0.3;
            app.PunishmentFactorLabel = uilabel(app.UIFigure);
            app.PunishmentFactorLabel.Text = 'Punishment Factor (PF) =';
            app.PunishmentFactor = uieditfield(app.UIFigure, 'numeric');
            app.PunishmentFactor.Value = 0.4;
            app.RapidityofFireEffectRFELabel = uilabel(app.UIFigure);
            app.RapidityofFireEffectRFELabel.Text = 'Rapidity of Fire Effect (RFE) =';
            app.RFE = uieditfield(app.UIFigure, 'numeric');
            app.RFE.Value = 0.5;
            app.FireControlEffectFCELabel = uilabel(app.UIFigure);
            app.FireControlEffectFCELabel.Text = 'Fire Control Effect (FCE) =';
            app.FCE = uieditfield(app.UIFigure, 'numeric');
            app.FCE.Value = 0.6;
            app.AmmunitionSupplyEffectASELabel = uilabel(app.UIFigure);
            app.AmmunitionSupplyEffectASELabel.Text = 'Ammunition Supply Effect (ASE) =';
            app.ASE = uieditfield(app.UIFigure, 'numeric');
            app.ASE.Value = 0.7;
            app.AircraftMountEffectAMELabel = uilabel(app.UIFigure);
            app.AircraftMountEffectAMELabel.Text = 'Aircraft Mount Effect (AME) =';
            app.AME = uieditfield(app.UIFigure, 'numeric');
            app.AME.Value = 0.8;
            app.CeilingFactorCLLabel = uilabel(app.UIFigure);
            app.CeilingFactorCLLabel.Text = 'Ceiling Factor (CL) =';
            app.CL = uieditfield(app.UIFigure, 'numeric');
            app.CL.Value = 0.9;
            app.ComputeOLIButton = uibutton(app.UIFigure, 'push');
            app.ComputeOLIButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeOLIButtonPushed, true);
            app.ComputeOLIButton.Text = 'Compute OLI';
            app.OLIWEditFieldLabel = uilabel(app.UIFigure);
            app.OLIWEditFieldLabel.Text = 'OLI (W)';
            app.OLIWEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QJM_mobile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end