classdef QJM_nonmobile < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OLICalculationofNonMobileWeaponsLabel  matlab.ui.control.Label
        RateOfFireRFLabel               matlab.ui.control.Label
        RateOfFireRFEditField           matlab.ui.control.NumericEditField
        PotentialTargetsperStrikePTSLabel  matlab.ui.control.Label
        PotentialTargetsperStrikePTSEditField  matlab.ui.control.NumericEditField
        RelativeIncapacitatingEffectRIEEditFieldLabel  matlab.ui.control.Label
        RelativeIncapacitatingEffectRIEEditField  matlab.ui.control.NumericEditField
        RangeFactorRForMVEditFieldLabel  matlab.ui.control.Label
        RangeFactorRForMVEditField      matlab.ui.control.NumericEditField
        AntiAircraftArtilleryAALabel    matlab.ui.control.Label
        AntiAircraftArtilleryAAEditField  matlab.ui.control.NumericEditField
        ReliabilityRNLabel              matlab.ui.control.Label
        ReliabilityRNEditField          matlab.ui.control.NumericEditField
        SelfPropelledMobilityEffectSMELabel  matlab.ui.control.Label
        SelfPropelledMobilityEffectSMEEditField  matlab.ui.control.NumericEditField
        GuidanceEffectGELabel           matlab.ui.control.Label
        GuidanceEffectGEEditField       matlab.ui.control.NumericEditField
        MultipleChargeEffectMCELabel    matlab.ui.control.Label
        MultipleChargeEffectMCEEditField  matlab.ui.control.NumericEditField
        MultipleBarrelEffectMBELabel    matlab.ui.control.Label
        MultipleBarrelEffectMBEEditField  matlab.ui.control.NumericEditField
        WheelHalfTrackEffectWHTLabel    matlab.ui.control.Label
        WheelHalfTrackEffectWHTEditField  matlab.ui.control.NumericEditField
        AircraftmountEffectAELabel      matlab.ui.control.Label
        AircraftmountEffectAEEditField  matlab.ui.control.NumericEditField
        DispersionFactorDiLabel         matlab.ui.control.Label
        DispersionFactorDiEditField     matlab.ui.control.NumericEditField
        ComputeOLIButton                matlab.ui.control.Button
        OLIWEditFieldLabel              matlab.ui.control.Label
        OLIWEditField                   matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: ComputeOLIButton
        function ComputeOLIButtonPushed(app, event)
            RF=app.RateOfFireRFEditField.Value;
            PTS=app.PotentialTargetsperStrikePTSEditField.Value;
            RIE=app.RelativeIncapacitatingEffectRIEEditField.Value;
            RF=app.RangeFactorRForMVEditField.Value;
            AA=app.AntiAircraftArtilleryAAEditField.Value;
            RN=app.ReliabilityRNEditField.Value;
            SME=app.SelfPropelledMobilityEffectSMEEditField.Value;
            GE=app.GuidanceEffectGEEditField.Value;
            MCE=app.MultipleChargeEffectMCEEditField.Value;
            MBE=app.MultipleBarrelEffectMBEEditField.Value;
            WHT=app.WheelHalfTrackEffectWHTEditField.Value;
            AE=app.AircraftmountEffectAEEditField.Value;
            Di=app.DispersionFactorDiEditField.Value;
          
            W=(RF*PTS*RIE*RF*AA*RN*SME*GE*MCE*MBE*WHT*AE)/Di;
              app.OLIWEditField.Value=W;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OLICalculationofNonMobileWeaponsLabel = uilabel(app.UIFigure);
            app.OLICalculationofNonMobileWeaponsLabel.Text = 'OLI Calculation of Non-Mobile Weapons';
            app.RateOfFireRFLabel = uilabel(app.UIFigure);
            app.RateOfFireRFLabel.Text = 'Rate Of Fire (RF) =';
            app.RateOfFireRFEditField = uieditfield(app.UIFigure, 'numeric');
            app.RateOfFireRFEditField.Value = 0.1;
            app.PotentialTargetsperStrikePTSLabel = uilabel(app.UIFigure);
            app.PotentialTargetsperStrikePTSLabel.Text = 'Potential Targets per Strike (PTS) =';
            app.PotentialTargetsperStrikePTSEditField = uieditfield(app.UIFigure, 'numeric');
            app.PotentialTargetsperStrikePTSEditField.Value = 0.2;
            app.RelativeIncapacitatingEffectRIEEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeIncapacitatingEffectRIEEditFieldLabel.Text = 'Relative Incapacitating Effect (RIE) =';
            app.RelativeIncapacitatingEffectRIEEditField = uieditfield(app.UIFigure, 'numeric');
            app.RelativeIncapacitatingEffectRIEEditField.Value = 0.3;
            app.RangeFactorRForMVEditFieldLabel = uilabel(app.UIFigure);
            app.RangeFactorRForMVEditFieldLabel.Text = 'Range Factor (RF or MV) =';
            app.RangeFactorRForMVEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeFactorRForMVEditField.Value = 0.4;
            app.AntiAircraftArtilleryAALabel = uilabel(app.UIFigure);
            app.AntiAircraftArtilleryAALabel.Text = 'AntiAircraft Artillery (AA) =';
            app.AntiAircraftArtilleryAAEditField = uieditfield(app.UIFigure, 'numeric');
            app.AntiAircraftArtilleryAAEditField.Value = 0.5;
            app.ReliabilityRNLabel = uilabel(app.UIFigure);
            app.ReliabilityRNLabel.Text = 'Reliability (RN) =';
            app.ReliabilityRNEditField = uieditfield(app.UIFigure, 'numeric');
            app.ReliabilityRNEditField.Value = 0.6;
            app.SelfPropelledMobilityEffectSMELabel = uilabel(app.UIFigure);
            app.SelfPropelledMobilityEffectSMELabel.Text = 'Self Propelled Mobility Effect (SME) =';
            app.SelfPropelledMobilityEffectSMEEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelfPropelledMobilityEffectSMEEditField.Value = 0.7;
            app.GuidanceEffectGELabel = uilabel(app.UIFigure);
            app.GuidanceEffectGELabel.Text = 'Guidance Effect (GE) =';
            app.GuidanceEffectGEEditField = uieditfield(app.UIFigure, 'numeric');
            app.GuidanceEffectGEEditField.Value = 0.8;
            app.MultipleChargeEffectMCELabel = uilabel(app.UIFigure);
            app.MultipleChargeEffectMCELabel.Text = 'Multiple Charge Effect (MCE) =';
            app.MultipleChargeEffectMCEEditField = uieditfield(app.UIFigure, 'numeric');
            app.MultipleChargeEffectMCEEditField.Value = 0.9;
            app.MultipleBarrelEffectMBELabel = uilabel(app.UIFigure);
            app.MultipleBarrelEffectMBELabel.Text = 'Multiple Barrel Effect (MBE) =';
            app.MultipleBarrelEffectMBEEditField = uieditfield(app.UIFigure, 'numeric');
            app.MultipleBarrelEffectMBEEditField.Value = 0.1;
            app.WheelHalfTrackEffectWHTLabel = uilabel(app.UIFigure);
            app.WheelHalfTrackEffectWHTLabel.Text = 'Wheel Half-Track Effect (WHT) =';
            app.WheelHalfTrackEffectWHTEditField = uieditfield(app.UIFigure, 'numeric');
            app.WheelHalfTrackEffectWHTEditField.Value = 0.2;
            app.AircraftmountEffectAELabel = uilabel(app.UIFigure);
            app.AircraftmountEffectAELabel.Text = 'Aircraft mount Effect (AE) =';
            app.AircraftmountEffectAEEditField = uieditfield(app.UIFigure, 'numeric');
            app.AircraftmountEffectAEEditField.Value = 0.3;
            app.DispersionFactorDiLabel = uilabel(app.UIFigure);
            app.DispersionFactorDiLabel.Text = 'Dispersion Factor (Di) =';
            app.DispersionFactorDiEditField = uieditfield(app.UIFigure, 'numeric');
            app.DispersionFactorDiEditField.Value = 0.4;
            app.ComputeOLIButton = uibutton(app.UIFigure, 'push');
            app.ComputeOLIButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeOLIButtonPushed, true);
            app.ComputeOLIButton.Text = 'Compute OLI';
            app.OLIWEditFieldLabel = uilabel(app.UIFigure);
            app.OLIWEditFieldLabel.Text = 'OLI (W)';
            app.OLIWEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QJM_nonmobile(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end