classdef ShCM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OkButton                  matlab.ui.control.Button
        ShCM_mult                 matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel  matlab.ui.control.Label
        ShortagesDropDown         matlab.ui.control.DropDown
        ShortagesDropDownLabel    matlab.ui.control.Label
        ShortageCategoryMultiplierLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Value changed function: ShortagesDropDown
        function ShortagesDropDownValueChanged(app, event)
            value = app.ShortagesDropDown.Value;
            if(strcmp(value,"Desert"))
                app.ShCM_mult.Value=1;
            elseif(strcmp(value,"Semi-Desert"))
                app.ShCM_mult.Value=0.9;
            elseif(strcmp(value,"Mountains"))
                app.ShCM_mult.Value=0.7;
            elseif(strcmp(value,"Hills"))
                app.ShCM_mult.Value=0.6;
            elseif(strcmp(value,"Jungle"))
                 app.ShCM_mult.Value=0.5;
            elseif(strcmp(value,"Coastal"))
                app.ShCM_mult.Value=1.5;
            end


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            multiplier=app.ShCM_mult.Value;
            update_Shortage_Category_Multiplier(app.CallingApp,multiplier);
            delete(app);        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ShortageCategoryMultiplierLabel = uilabel(app.UIFigure);
            app.ShortageCategoryMultiplierLabel.Text = 'Shortage Category Multiplier';
            app.ShortagesDropDownLabel = uilabel(app.UIFigure);
            app.ShortagesDropDownLabel.Text = 'Shortages';
            app.ShortagesDropDown = uidropdown(app.UIFigure);
            app.ShortagesDropDown.Items = {'Desert', 'Semi-Desert', 'Mountains', 'Hills', 'Jungle', 'Coastal'};
            app.ShortagesDropDown.ValueChangedFcn = createCallbackFcn(app, @ShortagesDropDownValueChanged, true);
            app.ShortagesDropDown.Value = 'Desert';
            app.MultiplierEditFieldLabel = uilabel(app.UIFigure);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.ShCM_mult = uieditfield(app.UIFigure, 'numeric');
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ShCM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end