classdef SiCM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OkButton                  matlab.ui.control.Button
        SiCM_mult                 matlab.ui.control.NumericEditField
        MultiplierEditFieldLabel  matlab.ui.control.Label
        SituationsDropDown        matlab.ui.control.DropDown
        SituationsDropDownLabel   matlab.ui.control.Label
        SituationalCategoryMultiplierLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Value changed function: SituationsDropDown
        function SituationsDropDownValueChanged(app, event)
            value = app.SituationsDropDown.Value;
            if(strcmp(value,"Favourable Situation"))
                app.SiCM_mult.Value=1;
            elseif(strcmp(value,"Adverse Situation"))
                app.SiCM_mult.Value=0.5;
            end
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
           multiplier=app.SiCM_mult.Value;
           update_Situational_Category_Multiplier(app.CallingApp,multiplier);
            delete(app);
                       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SituationalCategoryMultiplierLabel = uilabel(app.UIFigure);
            app.SituationalCategoryMultiplierLabel.Text = 'Situational Category Multiplier';
            app.SituationsDropDownLabel = uilabel(app.UIFigure);
            app.SituationsDropDownLabel.Text = 'Situations';
            app.SituationsDropDown = uidropdown(app.UIFigure);
            app.SituationsDropDown.Items = {'Favourable Situation', 'Adverse Situation'};
            app.SituationsDropDown.ValueChangedFcn = createCallbackFcn(app, @SituationsDropDownValueChanged, true);
            app.SituationsDropDown.Value = 'Favourable Situation';
            app.MultiplierEditFieldLabel = uilabel(app.UIFigure);
            app.MultiplierEditFieldLabel.Text = 'Multiplier';
            app.SiCM_mult = uieditfield(app.UIFigure, 'numeric');
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SiCM(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end