classdef UAV_intel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Image             matlab.ui.control.Image
        UAVDropDownLabel  matlab.ui.control.Label
        UAVDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Value changed function: UAVDropDown
        function UAVDropDownValueChanged(app, event)
            value = app.UAVDropDown.Value;
           if(strcmp(value,'RQ Raven'))
               app.Image.ImageSource='UAV1_image_nancowry_island.jpg';
           end
           if(strcmp(value,'Wasp'))
               app.Image.ImageSource='UAV2_image_nancowry_island.jpg';
           end
       
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'UAV1_image_nancowry_island.jpg';
            app.UAVDropDownLabel = uilabel(app.UIFigure);
            app.UAVDropDownLabel.Text = 'UAV';
            app.UAVDropDown = uidropdown(app.UIFigure);
            app.UAVDropDown.Items = {'RQ Raven ', 'Wasp', 'RQ-7 Shadow', 'MQ-1 Predator'};
            app.UAVDropDown.ValueChangedFcn = createCallbackFcn(app, @UAVDropDownValueChanged, true);
            app.UAVDropDown.Value = 'RQ Raven ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UAV_intel(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end