classdef Variable_resolution_model4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        JointAirLandNavalWarfareModelUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CalculateAttritionratesButton   matlab.ui.control.Button
        outputA                         matlab.ui.control.NumericEditField
        outputB                         matlab.ui.control.NumericEditField
        A                               matlab.ui.control.Label
        B                               matlab.ui.control.Label
        JointWarfareModelsDropDownLabel  matlab.ui.control.Label
        JointWarfareModelsDropDown      matlab.ui.control.DropDown
        DefenderUIAxes                  matlab.ui.control.UIAxes
        AttackerUIAxes                  matlab.ui.control.UIAxes
        InitialREDJointForceLabel       matlab.ui.control.Label
        EqAirCompLabel                  matlab.ui.control.Label
        EqLandCompLabel                 matlab.ui.control.Label
        EqNavalCompLabel                matlab.ui.control.Label
        FinalREDJointForceLabel         matlab.ui.control.Label
        InitialBLUEJointForceLabel      matlab.ui.control.Label
        FinalBLUEJointForceLabel        matlab.ui.control.Label
        BattleTimetEditFieldLabel       matlab.ui.control.Label
        BattleTimetEditField            matlab.ui.control.NumericEditField
        x10                             matlab.ui.control.NumericEditField
        x20                             matlab.ui.control.NumericEditField
        x30                             matlab.ui.control.NumericEditField
        x0CP                            matlab.ui.control.NumericEditField
        CombatPotentialButton_5         matlab.ui.control.Button
        x11                             matlab.ui.control.NumericEditField
        x21                             matlab.ui.control.NumericEditField
        x31                             matlab.ui.control.NumericEditField
        x1CP                            matlab.ui.control.NumericEditField
        CombatPotentialButton_6         matlab.ui.control.Button
        y10                             matlab.ui.control.NumericEditField
        y20                             matlab.ui.control.NumericEditField
        y30                             matlab.ui.control.NumericEditField
        y0CP                            matlab.ui.control.NumericEditField
        CombatPotentialButton_7         matlab.ui.control.Button
        y11                             matlab.ui.control.NumericEditField
        y21                             matlab.ui.control.NumericEditField
        y31                             matlab.ui.control.NumericEditField
        y1CP                            matlab.ui.control.NumericEditField
        CombatPotentialButton_8         matlab.ui.control.Button
        PercentageContributionofJointComponentsAirEditFieldLabel  matlab.ui.control.Label
        PercentageContributionofJointComponentsAirEditField  matlab.ui.control.NumericEditField
        LandEditFieldLabel              matlab.ui.control.Label
        LandEditField                   matlab.ui.control.NumericEditField
        NavalEditFieldLabel             matlab.ui.control.Label
        NavalEditField                  matlab.ui.control.NumericEditField
        SituationalScoreValuesofJointComponentsAirEditFieldLabel  matlab.ui.control.Label
        SituationalScoreValuesofJointComponentsAirEditField  matlab.ui.control.NumericEditField
        LandEditField_2Label            matlab.ui.control.Label
        LandEditField_2                 matlab.ui.control.NumericEditField
        NavalEditField_2Label           matlab.ui.control.Label
        NavalEditField_2                matlab.ui.control.NumericEditField
        MovementSpeedAirEditFieldLabel  matlab.ui.control.Label
        MovementSpeedAirEditField       matlab.ui.control.NumericEditField
        machLandEditFieldLabel          matlab.ui.control.Label
        machLandEditField               matlab.ui.control.NumericEditField
        kmhNavalEditFieldLabel          matlab.ui.control.Label
        kmhNavalEditField               matlab.ui.control.NumericEditField
        mileshrLabel                    matlab.ui.control.Label
        DistanceEditFieldLabel          matlab.ui.control.Label
        DistanceEditField               matlab.ui.control.NumericEditField
        kmLabel                         matlab.ui.control.Label
        PkButton                        matlab.ui.control.Button
        ED1                             matlab.ui.control.NumericEditField
        ED2                             matlab.ui.control.NumericEditField
        ED3                             matlab.ui.control.NumericEditField
        ED4                             matlab.ui.control.NumericEditField
        EDLabel                         matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        CallingApp; % Description
      
    end


%     methods (Access = public)
%         
%         function updatevalue(app,x)
% %               app.EditField.Value=x;
% %             app.FirstDialogEditField.Value=x;
% %             app.OKButton.Enable='on'
%             app.x0CP.Value=x
%         end
%     end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, x, y, a, b, time, q, r, s, m, n, o)
             % RED SIDE
           
            app.CallingApp=mainapp;
             app.x10.Value=m;
             app.x20.Value=n;
             app.x30.Value=o;
              app.x11.Value=m*(1-y/100);
             app.x21.Value=n*(1-y/100);
             app.x31.Value=o*(1-y/100);
             app.x0CP.Value=x;
             app.x1CP.Value=x*(1-y/100);
             % RED SIDE
             
             % BLUE SIDE             
             app.y10.Value=q;
             app.y20.Value=r;
             app.y30.Value=s;
              app.y11.Value=q*(1-b/100);
             app.y21.Value=r*(1-b/100);
             app.y31.Value=s*(1-b/100);
             app.y0CP.Value=a;
             app.y1CP.Value=a*(1-b/100);
             % BLUE SIDE
             
             app.BattleTimetEditField.Value=time;
             
             
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.JointAirLandNavalWarfareModelUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {950, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Callback function: CalculateAttritionratesButton, x0CP
        function CalculateAttritionratesButtonPushed(app, event)
                       
            % Calculate the monthly payment
%             x10=app.x10.Value;
%             x20=app.x20.Value;
%             x30=app.x30.Value;
%             Red_initial_CP=100*x10+50*x20+80*x30;
%             app.x0CP.Value=Red_initial_CP;
            
%             x0 = app.InitialStrengthofREDEditField.Value;
%             xt = app.StrengthofREDaftertminEditField.Value;
%             y0=app.InitialStrengthofBLUEEditField.Value;
%             yt=app.StrengthofBLUEaftertminEditField.Value;
            
%             x0=app.x0CP.Value;
%             xt=app.x1CP.Value;
%             y0=app.y0CP.Value;
%             yt=app.y1CP.Value;
            
            
             x0=app.ED1.Value;
            xt=app.ED2.Value;
            y0=app.ED3.Value;
            yt=app.ED4.Value;
            
            
            F=(xt*sqrt(y0^2-yt^2)+yt*sqrt(x0^2-xt^2))/(x0*sqrt(y0^2-yt^2)+y0*sqrt(x0^2-xt^2))
            t=app.BattleTimetEditField.Value;
%             t=4;
            app.outputA.Value=((sqrt(x0^2-xt^2))./(t*sqrt(y0^2-yt^2)))*log(F);
            app.outputB.Value=((sqrt(y0^2-yt^2))./(t*sqrt(x0^2-xt^2)))*log(F);
            A=app.outputA.Value;
            B=app.outputB.Value;
%             nper = 12*app.InitialStrengthofBLUEEditField.Value;
%             payment = (amount*rate)/(1-(1+rate)^-nper);
%             app.outputA.Value = payment;
%             
            % pre allocating and initializing variables 
%             interest = zeros(1,nper);
%             principal = zeros(1,nper);
%             balance = zeros (1,nper);
%             
%             balance(1) = amount;
            
            % Calculate the principal and interest over time
%             for i = 1:nper
%                 interest(i)  = balance(i)*rate;
%                 principal(i) = payment - interest(i);
%                 balance(i+1) = balance(i) - principal(i);
%             end
xx=[];
yy=[];
FR=[];
for i=1:.5:t
    x=x0*cosh(sqrt(A*B)*i)-y0*sqrt(A/B)*sinh(sqrt(A*B)*i);
    xx=[xx;x];
    y=y0*cosh(sqrt(A*B)*i)-x0*sqrt(B/A)*sinh(sqrt(A*B)*i);
    yy=[yy;y];
    FR=[FR;x/y];
    
    
end
xx
yy
for i=2:1:size(xx)
    cas_x(i)=xx(i-1)-xx(i);
    cas_y(i)=yy(i-1)-yy(i);
end
% cas'
temp=[xx,cas_x',yy,cas_y',FR]
 plot(app.DefenderUIAxes,(1:.5:t)',xx,'r',(1:.5:t)',yy,'b')
%  plot(app.DefenderUIAxes,(1:.5:t)',yy,(1:.5:t)',xx)
%  legend(app.DefenderUIAxes,{'Blue','Red'},'Location','Best')
            xlim(app.DefenderUIAxes,[1 t]); 
            
              if(FR(1)>FR(end))
               plot(app.AttackerUIAxes,(1:.5:t)',FR,'b')
                legend(app.AttackerUIAxes,{'Blue Wining'},'Location','Best')
            elseif(FR(1)==FR(end))
               plot(app.AttackerUIAxes,(1:.5:t)',FR,'g')
               legend(app.AttackerUIAxes,{'Draw'},'Location','Best')
            else
                plot(app.AttackerUIAxes,(1:.5:t)',FR,'r')
                legend(app.AttackerUIAxes,{'Red Wining'},'Location','Best')
            end
            
%     plot(app.AttackerUIAxes,(1:.5:t)',FR,'b')
%     legend(app.AttackerUIAxes,{'Attacker'},'Location','Best')
            xlim(app.DefenderUIAxes,[1 t]); 
            
%           clear all
% clc
% x=[655411 0		1451343	0
% 625650	29761	1413129	38214
% 611445	14205	1346195	66934
% 627694	-16249	1286167	60028
% 613434	14260	1229207	56960
% 616504	-3070	1188213	40994
% 615880	624	    1157136	31077
% 605665	10215	1073863	83273
% 604944	721	    1049286	24577
% 600305	4639	1022034	27252
% 593042	7263	1014374	7660
% 600011	-6969	1013401	973
% 606450	-6439	1011386	2015
% 607780	-1330	1000033	11353
% 
% ];
x=temp(:,1:4);
B=x(:,1);
dB=x(:,2);
R=x(:,3);
dR=x(:,4);
% k=9;k1=21;
% store=[];
% store1=[];
% for a=4E-9:(1.2E-8-4E-9)/k:1.2E-8
%     for b=4E-9:(1.2E-8-4E-9)/k:1.2E-8
%         for p=0:(2-0)/k1:2
%           for q=0:(2-0)/k1:2  
%               for d=0.6:(1.4-.6)/k:1.4
% SSR=sum(sum((x(1:8,2)-a*d*(x(1:8,3).^p).*(x(1:8,1).^q)).^2)+sum((x(1:8,4)-b*(1/d)*(x(1:8,1).^p).*(x(1:8,3).^q)).^2)+sum((x(9:15,2)-a*(1/d)*(x(9:15,3).^p).*(x(9:15,1).^q)).^2)+sum((x(9:15,4)-b*(d)*(x(9:15,1).^p).*(x(9:15,3).^q)).^2));
% store1=[a,b,p,q,d,SSR];
% store=[store;store1];
% 
% 
%               end
%           end
%         end
%     end
% end
% 
% [SSRmin,I]=min(store(:,6))
% (store(I,:));

 
% [p_mesh,q_mesh]=meshgrid((p_est-p_est/2):(p_est+p_est/2),(q_est-q_est/2):(q_est+q_est/2));
% SSR_est=sum(sum((x(1:8,2)-a_est*d_est*(x(1:8,3).^p_mesh).*(x(1:8,1).^q_mesh)).^2)+sum((x(1:8,4)-b_est*(1/d_est)*(x(1:8,1).^p_mesh).*(x(1:8,3).^q_mesh)).^2)+sum((x(9:15,2)-a_est*(1/d_est)*(x(9:15,3).^p_mesh).*(x(9:15,1).^q_mesh)).^2)+sum((x(9:15,4)-b_est*(d_est)*(x(9:15,1).^p_mesh).*(x(9:15,3).^q_mesh)).^2));
% countour(p_mesh,q_mesh,SSR_est)
% a=-15;
% b=-15;
% [P Q]=meshgrid(a:.099:15,b:.099:15);
% [ka kb]=size(P);
% 
%  SSR_est=zeros(ka,kb);
%  temp=zeros(ka,kb);
% d_est=1;
%  for k1=1:1:ka
%      for l1=1:1:kb
%          
%         p=(a+.1*(k1-1));
%         q=(b+.1*(l1-1));
%          a_est=sum(dB.*((R.^p).*(B.^q)))/sum(((R.^p).*(B.^q)).^2);
%             b_est=sum(dR.*((B.^p).*(R.^q)))/sum(((B.^p).*(R.^q)).^2);
% a_est=8E-9;
% b_est=1E-8;
%         a_est=sum(dB)/sum((R.^p).*(B.^q)) ;
%         b_est=sum(dR)/sum((B.^p).*(R.^q)) ;
%         a_est=-1.308E-5;
%         b_est=1.3008E-5;
        
%  a_est=sum(dB)./sum((R.^p).*(B.^q));
%         a_est=mean(a_est);
%         
%         b_est=sum(dB)./sum((R.^q).*(B.^p));
%         b_est=mean(b_est);
%         
%             temp2=0; 
%             temp1=0;
%         for i=1:1:14
%             
% %            if(i<=8)
%             temp1=(dB(i)-a_est*(R(i)^p)*(B(i)^q))^2+(dR(i)-b_est*(1/d_est)*(B(i)^p)*(R(i).^q))^2 ;
% % temp1=(dB(i)-a_est*(R(i)^p)*(B(i)^q))^2 ;
% %            else
% %             temp(k1,l1)=(x(i,2)-a_est*(1/d_est)*(x(i,3)^P(k1,l1))*(x(i,1)^Q(k1,l1))).^2+(x(i,4)-b_est*(x(i,1)^P(k1,l1))*(x(i,3)^Q(k1,l1))).^2 ;   
% %            end
%            
%           temp2=temp2+temp1;
%         end
%         SSR_est(k1,l1)=temp2;
% %         P(k1,l1)=p;
% %         Q(k1,l1)=q;
%      end
%  end
% % SSR_est=SSR_est1+SSR_est2;
% figure,mesh(P,Q,SSR_est);
% figure,[C,h]=contourf(P,Q,SSR_est);
% text_handle = clabel(C,h);


%             % Plot the principal and interest
%             plot(app.DefenderUIAxes,(1:nper)',principal, ...
%                 (1:nper)',interest);
%             legend(app.DefenderUIAxes,{'Principal','Interest'},'Location','Best')
%             xlim(app.DefenderUIAxes,[0 nper]); 
%             
        end

        % Button pushed function: CombatPotentialButton_5
        function CombatPotentialButton_5Pushed(app, event)
           CP_Initial=app.x0CP.Value;
            s1=app.PercentageContributionofJointComponentsAirEditField.Value;
            s2=app.PercentageContributionofJointComponentsAirEditField.Value;
            s3=app.PercentageContributionofJointComponentsAirEditField.Value;
             score_air=app.SituationalScoreValuesofJointComponentsAirEditField.Value;
            score_land=app.LandEditField_2.Value;
            score_naval=app.NavalEditField_2.Value;
            x10=app.x10.Value;
            x20=app.x20.Value;
            x30=app.x30.Value;
            Red_initial_CP=s1*x10*(score_air)+s2*x20*(score_land)+s3*x30*(score_naval);
            app.x0CP.Value=Red_initial_CP;
            ED_factor=app.x0CP.Value/CP_Initial;
%            updatevalue(app.CallingApp,app.x0CP.Value)
            app.ED1.Value=app.x0CP.Value/(ED_factor*5000);
        end

        % Button pushed function: CombatPotentialButton_6
        function CombatPotentialButton_6Pushed(app, event)
            CP_Initial1=app.x1CP.Value;
            s1=app.PercentageContributionofJointComponentsAirEditField.Value;
            s2=app.PercentageContributionofJointComponentsAirEditField.Value;
            s3=app.PercentageContributionofJointComponentsAirEditField.Value;
            score_air=app.SituationalScoreValuesofJointComponentsAirEditField.Value;
            score_land=app.LandEditField_2.Value;
            score_naval=app.NavalEditField_2.Value;
            x11=app.x11.Value;
            x21=app.x21.Value;
            x31=app.x31.Value;
            Red_Final_CP=s1*x11*(score_air)+s2*x21*(score_land)+s3*x31*(score_naval);
            app.x1CP.Value=Red_Final_CP;
             ED_factor1=app.x1CP.Value/CP_Initial1;
            app.ED2.Value=app.x1CP.Value/(ED_factor1*5000);
        end

        % Button pushed function: CombatPotentialButton_7
        function CombatPotentialButton_7Pushed(app, event)
              CP_Initial=app.y0CP.Value;
            s1=app.PercentageContributionofJointComponentsAirEditField.Value;
            s2=app.PercentageContributionofJointComponentsAirEditField.Value;
            s3=app.PercentageContributionofJointComponentsAirEditField.Value;
            score_air=app.SituationalScoreValuesofJointComponentsAirEditField.Value;
            score_land=app.LandEditField_2.Value;
            score_naval=app.NavalEditField_2.Value;
            y10=app.y10.Value;
            y20=app.y20.Value;
            y30=app.y30.Value;
            Blue_initial_CP=s1*y10*(score_air)+s2*y20*(score_land)+s3*y30*(score_naval);
            app.y0CP.Value=Blue_initial_CP;
             ED_factor=app.y0CP.Value/CP_Initial;
            app.ED3.Value=app.y0CP.Value/(ED_factor*5000);
        end

        % Button pushed function: CombatPotentialButton_8
        function CombatPotentialButton_8Pushed(app, event)
            CP_Initial=app.y1CP.Value;
            s1=app.PercentageContributionofJointComponentsAirEditField.Value;
            s2=app.PercentageContributionofJointComponentsAirEditField.Value;
            s3=app.PercentageContributionofJointComponentsAirEditField.Value;
            score_air=app.SituationalScoreValuesofJointComponentsAirEditField.Value;
            score_land=app.LandEditField_2.Value;
            score_naval=app.NavalEditField_2.Value;
            y11=app.y11.Value;
            y21=app.y21.Value;
            y31=app.y31.Value;
            Blue_Final_CP=s1*y11*(score_air)+s2*y21*(score_land)+s3*y31*(score_naval);
            app.y1CP.Value=Blue_Final_CP;
            ED_factor=app.y1CP.Value/CP_Initial;
            app.ED4.Value=app.y1CP.Value/(ED_factor*5000);
        end

        % Value changed function: JointWarfareModelsDropDown, 
        % PercentageContributionofJointComponentsAirEditField
        function JointWarfareModelsDropDownValueChanged(app, event)
            value = app.JointWarfareModelsDropDown.Value;
            if(strcmp(value,'Air Operation'))
                app.PercentageContributionofJointComponentsAirEditField.Value=90;
                app.LandEditField.Value=5;
                app.NavalEditField.Value=5;
            end
            if(strcmp(value,'Naval Operation'))
                app.PercentageContributionofJointComponentsAirEditField.Value=5;
                app.LandEditField.Value=5;
                app.NavalEditField.Value=90;
            end
            if(strcmp(value,'Land Operation'))
                app.PercentageContributionofJointComponentsAirEditField.Value=5;
                app.LandEditField.Value=90;
                app.NavalEditField.Value=5;
            end
             if(strcmp(value,'Amphibious Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=10;
                            app.LandEditField.Value=30;
                            app.NavalEditField.Value=60;
                        end
            if(strcmp(value,'Joint Air-Land Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=40;
                            app.LandEditField.Value=40;
                            app.NavalEditField.Value=20;
                        end
            if(strcmp(value,'Joint Air-Land-Naval Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=30;
                            app.LandEditField.Value=30;
                            app.NavalEditField.Value=30;
                        end
            if(strcmp(value,'Cyber Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=10;
                            app.LandEditField.Value=10;
                            app.NavalEditField.Value=10;
                        end
            if(strcmp(value,'Information Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=10;
                            app.LandEditField.Value=10;
                            app.NavalEditField.Value=10;
                        end
            if(strcmp(value,'Special Operation'))
                            app.PercentageContributionofJointComponentsAirEditField.Value=10;
                            app.LandEditField.Value=10;
                            app.NavalEditField.Value=10;
            end


        end

        % Callback function
        function s1EditFieldValueChanged(app, event)
            app.PercentageContributionofJointComponentsAirEditField.Value=100;
            
        end

        % Button pushed function: PkButton
        function PkButtonPushed(app, event)
            figure,
            for range=0:.1:10
                pk_at=1-.2*range;
                pk_def=1-.1*range;
                plot(range,pk_at,'.b')
                hold on
                plot(range,pk_def,'.r')
                hold on
                xlim([0 20]);xlabel('range (in Km)');
                ylim([0 1]);ylabel('pk');
                legend({'Attacker','Defender'},'Location','Best')
            end
               
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.JointAirLandNavalWarfareModelUIFigure = uifigure('Visible', 'off');
            app.JointAirLandNavalWarfareModelUIFigure.Name = 'Joint Air Land Naval Warfare Models';
            app.JointAirLandNavalWarfareModelUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.JointAirLandNavalWarfareModelUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Constant Coefficient Lanchester Model';
            app.CalculateAttritionratesButton = uibutton(app.LeftPanel, 'push');
            app.CalculateAttritionratesButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateAttritionratesButtonPushed, true);
            app.CalculateAttritionratesButton.Text = 'Calculate Attrition rates';
            app.outputA = uieditfield(app.LeftPanel, 'numeric');
            app.outputA.ValueDisplayFormat = '%7.5f';
            app.outputA.Editable = 'off';
            app.outputB = uieditfield(app.LeftPanel, 'numeric');
            app.outputB.ValueDisplayFormat = '%7.5f';
            app.outputB.Editable = 'off';
            app.A = uilabel(app.LeftPanel);
            app.A.Text = 'RED Attrition Rate (A)';
            app.B = uilabel(app.LeftPanel);
            app.B.Text = 'BLUE Attrition Rate (B)';
            app.JointWarfareModelsDropDownLabel = uilabel(app.LeftPanel);
            app.JointWarfareModelsDropDownLabel.Text = 'Joint Warfare Models';
            app.JointWarfareModelsDropDown = uidropdown(app.LeftPanel);
            app.JointWarfareModelsDropDown.Items = {'Air Operation', 'Naval Operation', 'Land Operation', 'Amphibious Operation', 'Joint Air-Land Operation', 'Joint Air-Land-Naval Operation', 'Cyber Operation', 'Information Operation', 'Special Operation'};
            app.JointWarfareModelsDropDown.ValueChangedFcn = createCallbackFcn(app, @JointWarfareModelsDropDownValueChanged, true);
            app.JointWarfareModelsDropDown.Value = 'Air Operation';
            app.DefenderUIAxes = uiaxes(app.LeftPanel);
            title(app.DefenderUIAxes, 'Red verses Blue')
            xlabel(app.DefenderUIAxes, 'time(in min)')
            ylabel(app.DefenderUIAxes, 'ED')
            app.DefenderUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.DefenderUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.AttackerUIAxes = uiaxes(app.LeftPanel);
            title(app.AttackerUIAxes, 'Force Ratio')
            xlabel(app.AttackerUIAxes, 'Time (in Min)')
            ylabel(app.AttackerUIAxes, 'Force Ratio (RED/BLUE)')
            app.AttackerUIAxes.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.AttackerUIAxes.XTickLabel = {'1'; '2'; '3'; '4'; '5'; '6'; '1'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.InitialREDJointForceLabel = uilabel(app.LeftPanel);
            app.InitialREDJointForceLabel.Text = 'Initial RED Joint Force';
            app.EqAirCompLabel = uilabel(app.LeftPanel);
            app.EqAirCompLabel.Text = 'Eq Air Comp';
            app.EqLandCompLabel = uilabel(app.LeftPanel);
            app.EqLandCompLabel.Text = 'Eq Land Comp';
            app.EqNavalCompLabel = uilabel(app.LeftPanel);
            app.EqNavalCompLabel.Text = 'Eq Naval Comp';
            app.FinalREDJointForceLabel = uilabel(app.LeftPanel);
            app.FinalREDJointForceLabel.Text = 'Final RED Joint Force';
            app.InitialBLUEJointForceLabel = uilabel(app.LeftPanel);
            app.InitialBLUEJointForceLabel.Text = 'Initial BLUE Joint Force';
            app.FinalBLUEJointForceLabel = uilabel(app.LeftPanel);
            app.FinalBLUEJointForceLabel.Text = 'Final BLUE Joint Force';
            app.BattleTimetEditFieldLabel = uilabel(app.LeftPanel);
            app.BattleTimetEditFieldLabel.Text = 'Battle Time (t)';
            app.BattleTimetEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BattleTimetEditField.Limits = [0 150];
            app.BattleTimetEditField.ValueDisplayFormat = '%.0f';
            app.BattleTimetEditField.Value = 14;
            app.x10 = uieditfield(app.LeftPanel, 'numeric');
            app.x10.Value = 100;
            app.x20 = uieditfield(app.LeftPanel, 'numeric');
            app.x20.Value = 1000;
            app.x30 = uieditfield(app.LeftPanel, 'numeric');
            app.x30.Value = 20;
            app.x0CP = uieditfield(app.LeftPanel, 'numeric');
            app.x0CP.ValueDisplayFormat = '%5.2f';
            app.x0CP.ValueChangedFcn = createCallbackFcn(app, @CalculateAttritionratesButtonPushed, true);
            app.x0CP.Value = 61600;
            app.CombatPotentialButton_5 = uibutton(app.LeftPanel, 'push');
            app.CombatPotentialButton_5.ButtonPushedFcn = createCallbackFcn(app, @CombatPotentialButton_5Pushed, true);
            app.CombatPotentialButton_5.Text = 'Combat Potential';
            app.x11 = uieditfield(app.LeftPanel, 'numeric');
            app.x11.Value = 50;
            app.x21 = uieditfield(app.LeftPanel, 'numeric');
            app.x21.Value = 500;
            app.x31 = uieditfield(app.LeftPanel, 'numeric');
            app.x31.Value = 10;
            app.x1CP = uieditfield(app.LeftPanel, 'numeric');
            app.x1CP.ValueDisplayFormat = '%5.2f';
            app.x1CP.Value = 30800;
            app.CombatPotentialButton_6 = uibutton(app.LeftPanel, 'push');
            app.CombatPotentialButton_6.ButtonPushedFcn = createCallbackFcn(app, @CombatPotentialButton_6Pushed, true);
            app.CombatPotentialButton_6.Text = 'Combat Potential';
            app.y10 = uieditfield(app.LeftPanel, 'numeric');
            app.y10.Value = 300;
            app.y20 = uieditfield(app.LeftPanel, 'numeric');
            app.y20.Value = 5000;
            app.y30 = uieditfield(app.LeftPanel, 'numeric');
            app.y30.Value = 30;
            app.y0CP = uieditfield(app.LeftPanel, 'numeric');
            app.y0CP.ValueDisplayFormat = '%5.2f';
            app.y0CP.Value = 282400;
            app.CombatPotentialButton_7 = uibutton(app.LeftPanel, 'push');
            app.CombatPotentialButton_7.ButtonPushedFcn = createCallbackFcn(app, @CombatPotentialButton_7Pushed, true);
            app.CombatPotentialButton_7.Text = 'Combat Potential';
            app.y11 = uieditfield(app.LeftPanel, 'numeric');
            app.y11.Value = 50;
            app.y21 = uieditfield(app.LeftPanel, 'numeric');
            app.y21.Value = 1000;
            app.y31 = uieditfield(app.LeftPanel, 'numeric');
            app.y31.Value = 10;
            app.y1CP = uieditfield(app.LeftPanel, 'numeric');
            app.y1CP.ValueDisplayFormat = '%5.2f';
            app.y1CP.Value = 55800;
            app.CombatPotentialButton_8 = uibutton(app.LeftPanel, 'push');
            app.CombatPotentialButton_8.ButtonPushedFcn = createCallbackFcn(app, @CombatPotentialButton_8Pushed, true);
            app.CombatPotentialButton_8.Text = 'Combat Potential';
            app.PercentageContributionofJointComponentsAirEditFieldLabel = uilabel(app.LeftPanel);
            app.PercentageContributionofJointComponentsAirEditFieldLabel.Text = 'Percentage Contribution of Joint Components:  Air ';
            app.PercentageContributionofJointComponentsAirEditField = uieditfield(app.LeftPanel, 'numeric');
            app.PercentageContributionofJointComponentsAirEditField.ValueChangedFcn = createCallbackFcn(app, @JointWarfareModelsDropDownValueChanged, true);
            app.PercentageContributionofJointComponentsAirEditField.Value = 90;
            app.LandEditFieldLabel = uilabel(app.LeftPanel);
            app.LandEditFieldLabel.Text = 'Land ';
            app.LandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LandEditField.Value = 5;
            app.NavalEditFieldLabel = uilabel(app.LeftPanel);
            app.NavalEditFieldLabel.Text = 'Naval';
            app.NavalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NavalEditField.Value = 5;
            app.SituationalScoreValuesofJointComponentsAirEditFieldLabel = uilabel(app.LeftPanel);
            app.SituationalScoreValuesofJointComponentsAirEditFieldLabel.Text = 'Situational Score Values of Joint Components :  Air ';
            app.SituationalScoreValuesofJointComponentsAirEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SituationalScoreValuesofJointComponentsAirEditField.Value = 100;
            app.LandEditField_2Label = uilabel(app.LeftPanel);
            app.LandEditField_2Label.Text = 'Land ';
            app.LandEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.LandEditField_2.Value = 80;
            app.NavalEditField_2Label = uilabel(app.LeftPanel);
            app.NavalEditField_2Label.Text = 'Naval';
            app.NavalEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.NavalEditField_2.Value = 50;
            app.MovementSpeedAirEditFieldLabel = uilabel(app.LeftPanel);
            app.MovementSpeedAirEditFieldLabel.Text = 'Movement Speed :  Air ';
            app.MovementSpeedAirEditField = uieditfield(app.LeftPanel, 'numeric');
            app.MovementSpeedAirEditField.Value = 2;
            app.machLandEditFieldLabel = uilabel(app.LeftPanel);
            app.machLandEditFieldLabel.Text = '      mach    Land ';
            app.machLandEditField = uieditfield(app.LeftPanel, 'numeric');
            app.machLandEditField.Value = 45;
            app.kmhNavalEditFieldLabel = uilabel(app.LeftPanel);
            app.kmhNavalEditFieldLabel.Text = '         km/h     Naval';
            app.kmhNavalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.kmhNavalEditField.Value = 2;
            app.mileshrLabel = uilabel(app.LeftPanel);
            app.mileshrLabel.Text = 'miles/hr';
            app.DistanceEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanceEditFieldLabel.Text = 'Distance';
            app.DistanceEditField = uieditfield(app.LeftPanel, 'numeric');
            app.DistanceEditField.Value = 300;
            app.kmLabel = uilabel(app.LeftPanel);
            app.kmLabel.Text = 'km';
            app.PkButton = uibutton(app.LeftPanel, 'push');
            app.PkButton.ButtonPushedFcn = createCallbackFcn(app, @PkButtonPushed, true);
            app.PkButton.Text = 'Pk';
            app.ED1 = uieditfield(app.LeftPanel, 'numeric');
            app.ED1.ValueDisplayFormat = '%.2f';
            app.ED2 = uieditfield(app.LeftPanel, 'numeric');
            app.ED2.ValueDisplayFormat = '%.2f';
            app.ED3 = uieditfield(app.LeftPanel, 'numeric');
            app.ED3.ValueDisplayFormat = '%.0f';
            app.ED4 = uieditfield(app.LeftPanel, 'numeric');
            app.ED4.ValueDisplayFormat = '%.0f';
            app.EDLabel = uilabel(app.LeftPanel);
            app.EDLabel.Text = 'ED';
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.JointAirLandNavalWarfareModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Variable_resolution_model4(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.JointAirLandNavalWarfareModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.JointAirLandNavalWarfareModelUIFigure)
        end
    end
end