classdef msg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        OKButton                  matlab.ui.control.Button
        PercentageofOverlapBetweenREDandBLUEForcesLabel  matlab.ui.control.Label
        P                         matlab.ui.control.NumericEditField
        Label                     matlab.ui.control.Label
        ContactDistanceInKmLabel  matlab.ui.control.Label
        Dist                      matlab.ui.control.NumericEditField
        SholderStrengthofREDandBLUELabel  matlab.ui.control.Label
        strength                  matlab.ui.control.NumericEditField
        SoldiersLabel             matlab.ui.control.Label
        defattLabel               matlab.ui.control.Label
        strength_2                matlab.ui.control.NumericEditField
        delactattLabel            matlab.ui.control.Label
        strength_3                matlab.ui.control.NumericEditField
        attattLabel               matlab.ui.control.Label
        strength_4                matlab.ui.control.NumericEditField
        attdefLabel               matlab.ui.control.Label
        strength_5                matlab.ui.control.NumericEditField
        defattLabel_2             matlab.ui.control.Label
        strength_6                matlab.ui.control.NumericEditField
        delactattLabel_2          matlab.ui.control.Label
        strength_7                matlab.ui.control.NumericEditField
        attattLabel_2             matlab.ui.control.Label
        strength_8                matlab.ui.control.NumericEditField
        attdefLabel_2             matlab.ui.control.Label
        strength_9                matlab.ui.control.NumericEditField
        KmLabel                   matlab.ui.control.Label
        DivisDefendingBrigadesAttackingLabel  matlab.ui.control.Label
        DivisDefendingBrigadesAttackingLabel_2  matlab.ui.control.Label
        DivisDefendingBrigadesAttackingLabel_3  matlab.ui.control.Label
        DivisDefendingBrigadesAttackingLabel_4  matlab.ui.control.Label
    end

    
    properties (Access = private)
        DialogApp; % Description
        CallingApp;
    end


    methods (Access = public)
        
        function  update_percentage(app,p)
            app.P.Value=p;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, perc, dist, strength)
            app.CallingApp=mainapp;
             app.P.Value=perc;
             app.Dist.Value=dist;
             app.strength.Value=round(100*dist);
             app.strength_2.Value=round(.05*app.strength.Value);
             app.strength_3.Value=round(0*app.strength.Value);
             app.strength_4.Value=round(.1*app.strength.Value);
             app.strength_5.Value=round(.85*app.strength.Value);
              app.strength_6.Value=round(.7*app.strength.Value);
             app.strength_7.Value=round(.2*app.strength.Value);
             app.strength_8.Value=round(.05*app.strength.Value);
             app.strength_9.Value=round(.05*app.strength.Value);


%             update_percentage(app,P)
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
%             app.Text=app.Text+"Percentage in Contact";

% update_percentage(app.CallingApp,value)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PercentageofOverlapBetweenREDandBLUEForcesLabel = uilabel(app.UIFigure);
            app.PercentageofOverlapBetweenREDandBLUEForcesLabel.Text = 'Percentage of  Overlap Between RED and BLUE Forces';
            app.P = uieditfield(app.UIFigure, 'numeric');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.ContactDistanceInKmLabel = uilabel(app.UIFigure);
            app.ContactDistanceInKmLabel.Text = 'Contact Distance In Km';
            app.Dist = uieditfield(app.UIFigure, 'numeric');
            app.SholderStrengthofREDandBLUELabel = uilabel(app.UIFigure);
            app.SholderStrengthofREDandBLUELabel.Text = 'Sholder Strength of RED and BLUE';
            app.strength = uieditfield(app.UIFigure, 'numeric');
            app.SoldiersLabel = uilabel(app.UIFigure);
            app.SoldiersLabel.Text = 'Soldiers';
            app.defattLabel = uilabel(app.UIFigure);
            app.defattLabel.Text = 'def-att';
            app.strength_2 = uieditfield(app.UIFigure, 'numeric');
            app.delactattLabel = uilabel(app.UIFigure);
            app.delactattLabel.Text = 'del act-att';
            app.strength_3 = uieditfield(app.UIFigure, 'numeric');
            app.attattLabel = uilabel(app.UIFigure);
            app.attattLabel.Text = 'att-att';
            app.strength_4 = uieditfield(app.UIFigure, 'numeric');
            app.attdefLabel = uilabel(app.UIFigure);
            app.attdefLabel.Text = 'att-def';
            app.strength_5 = uieditfield(app.UIFigure, 'numeric');
            app.defattLabel_2 = uilabel(app.UIFigure);
            app.defattLabel_2.Text = 'def-att';
            app.strength_6 = uieditfield(app.UIFigure, 'numeric');
            app.delactattLabel_2 = uilabel(app.UIFigure);
            app.delactattLabel_2.Text = 'del act-att';
            app.strength_7 = uieditfield(app.UIFigure, 'numeric');
            app.attattLabel_2 = uilabel(app.UIFigure);
            app.attattLabel_2.Text = 'att-att';
            app.strength_8 = uieditfield(app.UIFigure, 'numeric');
            app.attdefLabel_2 = uilabel(app.UIFigure);
            app.attdefLabel_2.Text = 'att-def';
            app.strength_9 = uieditfield(app.UIFigure, 'numeric');
            app.KmLabel = uilabel(app.UIFigure);
            app.KmLabel.Text = 'Km';
            app.DivisDefendingBrigadesAttackingLabel = uilabel(app.UIFigure);
            app.DivisDefendingBrigadesAttackingLabel.Text = 'No. of Soldiers attacking while Div is ';
            app.DivisDefendingBrigadesAttackingLabel_2 = uilabel(app.UIFigure);
            app.DivisDefendingBrigadesAttackingLabel_2.Text = ' Defending against various opponents ';
            app.DivisDefendingBrigadesAttackingLabel_3 = uilabel(app.UIFigure);
            app.DivisDefendingBrigadesAttackingLabel_3.Text = 'No. of Soldiers defending while Div is ';
            app.DivisDefendingBrigadesAttackingLabel_4 = uilabel(app.UIFigure);
            app.DivisDefendingBrigadesAttackingLabel_4.Text = ' Attacking against various opponents ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = msg(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end