classdef next_move < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        NOButton       matlab.ui.control.Button
        YESButton      matlab.ui.control.Button
        NextMoveLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        DialogApp; % Description
        x0;
        y0;
        x1;
        y1;
        gx;
        perc;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, gx, Lat1, Lon1, Lat2, Lon2)
            app.x0=Lat1;
            app.y0=Lon1;
            app.x1=Lat2;
            app.y1=Lon2;
            app.gx=gx;
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            s(138).Lat=app.x0;
            s(138).Lon=app.y0;
            s(117).Lat=app.x1;
            s(117).Lon=app.y1;
            s(117).side=1;
            s(138).side=2;
            s(117).type=1;
            s(138).type=1;
            [x y]=ginput(1);
            geoplot([s(138).Lat x],[s(138).Lon y],'g-*')
            earthRadiusInMeters = 6371000;
            distInMeters = distance(s(138).Lat, s(138).Lon,x,y,earthRadiusInMeters)
            distInKilometers = distInMeters/1000;   
            speed=30;
            time_required=round(distInKilometers/speed) % @30 km /hour
            d1=(x-s(138).Lat)/time_required;
            d2=(y-s(138).Lon)/time_required;
            theta=rad2deg(atan(d2/d1));
            gtext("Distance:"+distInKilometers+"km"+""+theta+"app.x0"+app.x0,'Color','black','FontSize',10,"BackgroundColor","white")
            hold on
for j=1:1:time_required
    s(138).Lat=s(138).Lat+d1;
    s(138).Lon=s(138).Lon+d2;
    temp=[];
    ar=[];
    for i=[117,138]
    if(s(i).side==1)
        if(s(i).type==1)
            geoplot(s(i).Lat,s(i).Lon,'bo','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[0 0 1]);
            roi1=drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1],"RotationAngle",theta)
            temp=[temp;roi1.Vertices]
            ar=[ar;roi1.Position]
            hold on
             for n=1:1:100
              pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
                geoplot(app.gx,pers_x,pers_y,'r.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(s(i).Lat,s(i).Lon,'bs','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arm Div")
            hold on   
        elseif(s(i).type==3)
            geoplot(s(i).Lat,s(i).Lon,'b*','MarkerFaceColor','blue','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[0 0 1 ]);
             drawrectangle(app.gx,"Position",[s(i).Lat-1,s(i).Lon-0.5,2,1],"RotationAngle",theta)
            hold on  
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'g^','MarkerFaceColor','green','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")
            hold on   
        end
        
    elseif(s(i).side==2)
        if(s(i).type==1)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'ro','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  inf Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",.2,"Color",[1 1 0]);
            roi2=drawrectangle(app.gx,"Position",[s(i).Lat-.1,s(i).Lon-0.05,.2,.1],"RotationAngle",theta)
            hold on
            temp=[temp;roi2.Vertices]
             ar=[ar;roi2.Position]
             for n=1:1:100
            pers_x=(s(i).Lat-0.1)+0.2*rand();
                pers_y=(s(i).Lon-0.05)+0.1*rand();
                hold on
                geoplot(app.gx,pers_x,pers_y,'r.')
                hold on
            end
        elseif(s(i).type==2)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'rs','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arm Div")
            hold on   
        elseif(s(i).type==3)
            geoplot(app.gx,s(i).Lat,s(i).Lon,'r*','MarkerFaceColor','red','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon,"  Arty Div")
            drawcircle(app.gx,"Center",[s(i).Lat,s(i).Lon],"Radius",1,"Color",[1 0 0]);
            hold on 
        elseif(s(i).type==6)
            geoplot(s(i).Lat,s(i).Lon,'y^','MarkerFaceColor','yellow','MarkerEdgeColor','black')
            text(app.gx,s(i).Lat,s(i).Lon," Air Base")  
        end
    end

end
A=ar(1,:);
drawrectangle(app.gx,'Position',A,"RotationAngle",theta)
hold on
B=ar(2,:);
drawrectangle(app.gx,'Position', B,"RotationAngle",theta)
hold on
dd=rectint(A,B);
app.perc=(dd/.2*.1)*100;
x1=[temp(1:4,1);temp(1,1)];
y1=[temp(1:4,2);temp(1,2)];
x2=[temp(5:8,1);temp(5,1)];
y2=[temp(5:8,2);temp(5,2)];
[xi,yi]=polyxpoly(x1,y1,x2,y2)
geoplot(app.gx,xi,yi,'gs','MarkerFaceColor','green','MarkerEdgeColor','black','MarkerSize',30)
hold on
            if(xi~=0)
            earthRadiusInMeters = 6371000;
            distInMeters = distance(xi(1),yi(1),xi(2),yi(2),earthRadiusInMeters)
            distInKilometers = distInMeters/1000;
            if(distInKilometers>0)
                app.DialogApp=msg(app,app.perc,distInKilometers);
                app.DialogApp=next_move(app,app.gx,s(138).Lat,s(138).Lon,s(117).Lat,s(117).Lon)
                break;
            end
            end
if(j==time_required)
     app.DialogApp=next_move(app,app.gx,s(138).Lat,s(138).Lon,s(117).Lat,s(117).Lon)
    break;
end
pause(1)
cla
end
delete(app)
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NextMoveLabel = uilabel(app.UIFigure);
            app.NextMoveLabel.Text = 'Next Move';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Text = 'NO';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = next_move(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end