classdef posture_factor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OkButton                        matlab.ui.control.Button
        VulnerabilityLabel              matlab.ui.control.Label
        ForceStrengthLabel              matlab.ui.control.Label
        v6                              matlab.ui.control.NumericEditField
        v5                              matlab.ui.control.NumericEditField
        v4                              matlab.ui.control.NumericEditField
        v3                              matlab.ui.control.NumericEditField
        v2                              matlab.ui.control.NumericEditField
        v1                              matlab.ui.control.NumericEditField
        u6                              matlab.ui.control.NumericEditField
        DelayEditFieldLabel             matlab.ui.control.Label
        u5                              matlab.ui.control.NumericEditField
        WithdrawalEditFieldLabel        matlab.ui.control.Label
        u4                              matlab.ui.control.NumericEditField
        DefenseFortifiedEditFieldLabel  matlab.ui.control.Label
        u3                              matlab.ui.control.NumericEditField
        DefensePreparedEditFieldLabel   matlab.ui.control.Label
        u2                              matlab.ui.control.NumericEditField
        DefenseHastyEditFieldLabel      matlab.ui.control.Label
        u1                              matlab.ui.control.NumericEditField
        AttackEditFieldLabel            matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
    end

    
    properties (Access = private)
        CallingApp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           app.CallingApp=mainapp;   % ai angsho ta kora hoyni bole error dichillo; mainapp, app.CallingApp 
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)

             label=["Attack","Defence(Hasty)","Defence (Prepared)","Defence (Fortified)","Withdrawal", "Delay"]
             u=[app.u1.Value,app.u2.Value,app.u3.Value,app.u4.Value,app.u5.Value,app.u6.Value];
             v=[app.v1.Value,app.v2.Value,app.v3.Value,app.v4.Value,app.v5.Value,app.v6.Value];
             x=[label', u',v']
             update_posture_factor_matrix(app.CallingApp,x);
             delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AttackEditFieldLabel = uilabel(app.UIFigure);
            app.AttackEditFieldLabel.Text = 'Attack';
            app.u1 = uieditfield(app.UIFigure, 'numeric');
            app.u1.ValueDisplayFormat = '%5.2f';
            app.u1.Value = 1;
            app.DefenseHastyEditFieldLabel = uilabel(app.UIFigure);
            app.DefenseHastyEditFieldLabel.Text = 'Defense (Hasty)';
            app.u2 = uieditfield(app.UIFigure, 'numeric');
            app.u2.ValueDisplayFormat = '%5.2f';
            app.u2.Value = 1.3;
            app.DefensePreparedEditFieldLabel = uilabel(app.UIFigure);
            app.DefensePreparedEditFieldLabel.Text = 'Defense (Prepared)';
            app.u3 = uieditfield(app.UIFigure, 'numeric');
            app.u3.ValueDisplayFormat = '%5.2f';
            app.u3.Value = 1.5;
            app.DefenseFortifiedEditFieldLabel = uilabel(app.UIFigure);
            app.DefenseFortifiedEditFieldLabel.Text = 'Defense (Fortified)';
            app.u4 = uieditfield(app.UIFigure, 'numeric');
            app.u4.ValueDisplayFormat = '%5.2f';
            app.u4.Value = 1.6;
            app.WithdrawalEditFieldLabel = uilabel(app.UIFigure);
            app.WithdrawalEditFieldLabel.Text = 'Withdrawal';
            app.u5 = uieditfield(app.UIFigure, 'numeric');
            app.u5.ValueDisplayFormat = '%5.2f';
            app.u5.Value = 1.15;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.u6 = uieditfield(app.UIFigure, 'numeric');
            app.u6.ValueDisplayFormat = '%5.2f';
            app.u6.Value = 1.2;
            app.v1 = uieditfield(app.UIFigure, 'numeric');
            app.v1.ValueDisplayFormat = '%5.2f';
            app.v1.Value = 1;
            app.v2 = uieditfield(app.UIFigure, 'numeric');
            app.v2.ValueDisplayFormat = '%5.2f';
            app.v2.Value = 0.7;
            app.v3 = uieditfield(app.UIFigure, 'numeric');
            app.v3.ValueDisplayFormat = '%5.2f';
            app.v3.Value = 0.6;
            app.v4 = uieditfield(app.UIFigure, 'numeric');
            app.v4.ValueDisplayFormat = '%5.2f';
            app.v4.Value = 0.5;
            app.v5 = uieditfield(app.UIFigure, 'numeric');
            app.v5.ValueDisplayFormat = '%5.2f';
            app.v5.Value = 0.85;
            app.v6 = uieditfield(app.UIFigure, 'numeric');
            app.v6.ValueDisplayFormat = '%5.2f';
            app.v6.Value = 0.65;
            app.ForceStrengthLabel = uilabel(app.UIFigure);
            app.ForceStrengthLabel.Text = 'Force Strength';
            app.VulnerabilityLabel = uilabel(app.UIFigure);
            app.VulnerabilityLabel.Text = 'Vulnerability';
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.u3.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = posture_factor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end