classdef sat_intell < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NoofActiveAirFieldsEditFieldLabel  matlab.ui.control.Label
        NoofActiveAirFieldsEditField   matlab.ui.control.NumericEditField
        longitudeofAirfieldxEditFieldLabel  matlab.ui.control.Label
        longitudeofAirfieldxEditField  matlab.ui.control.NumericEditField
        latitudeofAirfieldyEditFieldLabel  matlab.ui.control.Label
        latitudeofAirfieldyEditField   matlab.ui.control.NumericEditField
        NoofAAGunsEditFieldLabel       matlab.ui.control.Label
        NoofAAGunsEditField            matlab.ui.control.NumericEditField
        locationofAAGunsxEditFieldLabel  matlab.ui.control.Label
        locationofAAGunsxEditField     matlab.ui.control.NumericEditField
        locationofAAGunsyEditFieldLabel  matlab.ui.control.Label
        locationofAAGunsyEditField     matlab.ui.control.NumericEditField
        SatelliteIntelligenceLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
           app.CallingApp=mainapp;
            app.NoofActiveAirFieldsEditField.Value=round(5+10*rand(1));
            if(round(app.NoofActiveAirFieldsEditField.Value)>0)
            app.longitudeofAirfieldxEditField.Value=(5+10*rand(1));
            app.latitudeofAirfieldyEditField.Value=(15+20*rand(1));
            else
                app.longitudeofAirfieldxEditField.Value=0;
            app.latitudeofAirfieldyEditField.Value=0;
            end
            app.NoofAAGunsEditField.Value=round(5+10*rand(1));
            app.locationofAAGunsxEditField.Value=(5+10*rand(1));
            app.locationofAAGunsyEditField.Value=(15+20*rand(1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NoofActiveAirFieldsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofActiveAirFieldsEditFieldLabel.Text = 'No of Active Air Fields';
            app.NoofActiveAirFieldsEditField = uieditfield(app.UIFigure, 'numeric');
            app.longitudeofAirfieldxEditFieldLabel = uilabel(app.UIFigure);
            app.longitudeofAirfieldxEditFieldLabel.Text = 'longitude of Airfield (x)';
            app.longitudeofAirfieldxEditField = uieditfield(app.UIFigure, 'numeric');
            app.latitudeofAirfieldyEditFieldLabel = uilabel(app.UIFigure);
            app.latitudeofAirfieldyEditFieldLabel.Text = 'latitude of Airfield (y)';
            app.latitudeofAirfieldyEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofAAGunsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofAAGunsEditFieldLabel.Text = 'No. of AA Guns';
            app.NoofAAGunsEditField = uieditfield(app.UIFigure, 'numeric');
            app.locationofAAGunsxEditFieldLabel = uilabel(app.UIFigure);
            app.locationofAAGunsxEditFieldLabel.Text = 'location of AA Guns (x)';
            app.locationofAAGunsxEditField = uieditfield(app.UIFigure, 'numeric');
            app.locationofAAGunsyEditFieldLabel = uilabel(app.UIFigure);
            app.locationofAAGunsyEditFieldLabel.Text = 'location of AA Guns (y)';
            app.locationofAAGunsyEditField = uieditfield(app.UIFigure, 'numeric');
            app.SatelliteIntelligenceLabel = uilabel(app.UIFigure);
            app.SatelliteIntelligenceLabel.Text = 'Satellite Intelligence';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sat_intell(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end