classdef score_tank_dropdown < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MobilityPanel               matlab.ui.container.Panel
        SpeedkmphDropDownLabel      matlab.ui.control.Label
        SpeedkmphDropDown           matlab.ui.control.DropDown
        RangeKmDropDownLabel        matlab.ui.control.Label
        RangeKmDropDown             matlab.ui.control.DropDown
        LengthmDropDownLabel        matlab.ui.control.Label
        LengthmDropDown             matlab.ui.control.DropDown
        SurvivabilityPanel          matlab.ui.container.Panel
        CrewnoDropDownLabel         matlab.ui.control.Label
        CrewnoDropDown              matlab.ui.control.DropDown
        MassKgDropDownLabel         matlab.ui.control.Label
        MassKgDropDown              matlab.ui.control.DropDown
        FuelCapacityLDropDownLabel  matlab.ui.control.Label
        FuelCapacityLDropDown       matlab.ui.control.DropDown
        FirepowerPanel              matlab.ui.container.Panel
        GunsnoDropDownLabel         matlab.ui.control.Label
        GunsnoDropDown              matlab.ui.control.DropDown
        PowerWeighthptonneDropDownLabel  matlab.ui.control.Label
        PowerWeighthptonneDropDown  matlab.ui.control.DropDown
        SCOREofTANKLabel            matlab.ui.control.Label
        EVALUATEButton              matlab.ui.control.Button
        ScoreofTANKEditFieldLabel   matlab.ui.control.Label
        ScoreofTANKEditField        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Button pushed function: EVALUATEButton
        function EVALUATEButtonPushed(app, event)
            % SCORE for Tank
            
            Score=[];
            
            % SURVIVABILITY
            
            % Crew-size is LOW (1-2)=0.25, MEDIUM (3-5)=0.60 & HIGH (>5)=0.85
            if strcmp(app.CrewnoDropDown.Value,'Low')==1
                crew=0.25;
            elseif strcmp(app.CrewnoDropDown.Value,'Medium')==1
                crew=0.60;
            else
                crew=0.85;
            end
            
            % Mass (in Kg) is LOW (0-20000)=0.25, MEDIUM (20001-40000)=0.60 & HIGH (>40000)=0.85
            if strcmp(app.MassKgDropDown.Value,'Low')==1
                mass=0.25;
            elseif strcmp(app.MassKgDropDown.Value,'Medium')==1
                mass=0.60;
            else
                mass=0.85;
            end
            
            % Endurance (Fuel Capacity in Litres) is LOW (1-750)=0.25, MEDIUM (751-1500)=0.60 & HIGH (>1500)=0.85
            if strcmp(app.FuelCapacityLDropDown.Value,'Low')==1
                fuel=0.25;
            elseif strcmp(app.FuelCapacityLDropDown.Value,'Medium')==1
                fuel=0.60;
            else
                fuel=0.85;
            end
            
            % MOBILITY
            
            % Speed (in kmph) is LOW (0-40)=0.25, MEDIUM (41-60)=0.60 & HIGH (>60)=0.85
            if strcmp(app.SpeedkmphDropDown.Value,'Low')==1
                speed=0.25;
            elseif strcmp(app.SpeedkmphDropDown.Value,'Medium')==1
                speed=0.60;
            else
                speed=0.85;
            end
            
            % Range (in Km) is LOW (0-400)=0.25, MEDIUM (401-600)=0.60 & HIGH (>600)=0.85
            if strcmp(app.RangeKmDropDown.Value,'Low')==1
                range=0.25;
            elseif strcmp(app.RangeKmDropDown.Value,'Medium')==1
                range=0.60;
            else
                range=0.85;
            end
            
            % Length (in m) is LOW (0-5)=0.25, MEDIUM (6-10)=0.60 & HIGH (>10)=0.85
            if strcmp(app.LengthmDropDown.Value,'Low')==1
                Length=0.25;
            elseif strcmp(app.LengthmDropDown.Value,'Medium')==1
                Length=0.60;
            else
                Length=0.85;
            end
            
            %     % CAPACITY
            %
            %     % Ground Clearance (in m) is LOW (0-0.40)=0.25, MEDIUM (0.40-0.45)=0.60 & HIGH (>0.45)=0.85
            %     if table_tank.GroundClearance_m_(i)>0 && table_tank.GroundClearance_m_(i)<=0.40
            %         capacity=0.25;
            %     elseif table_tank.GroundClearance_m_(i)>0.40 && table_tank.GroundClearance_m_(i)<=0.45
            %         capacity=0.60;
            %     elseif table_tank.GroundClearance_m_(i)>0.45
            %         capacity=0.85;
            %     else
            %         capacity=0;
            %     end
            
            % FIREPOWER
            
            % Power/Weight (in hp/tonne) is LOW (0-20)=0.25, MEDIUM (20-30)=0.60 & HIGH (>30)=0.85
            if strcmp(app.PowerWeighthptonneDropDown.Value,'Low')==1
                power=0.25;
            elseif strcmp(app.PowerWeighthptonneDropDown.Value,'Medium')==1
                power=0.60;
            else
                power=0.85;
            end
            
            % No. of Guns is LOW (1)=0.25, MEDIUM (2)=0.60 & HIGH (>2)=0.85
            if strcmp(app.GunsnoDropDown.Value,'Low')==1
                guns=0.25;
            elseif strcmp(app.GunsnoDropDown.Value,'Medium')==1
                guns=0.60;
            else
                guns=0.85;
            end
            
            % SCORE CALCULATION
            
            score=(crew*(1/3) + mass*(1/3) + fuel*(1/3))*(1/3) + (speed*(1/3) + range*(1/3) + Length*(1/3))*(1/3) + (power*(1/2) + guns*(1/2))*(1/3);
            app.ScoreofTANKEditField.Value=score;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MobilityPanel = uipanel(app.UIFigure);
            app.MobilityPanel.Title = 'Mobility';
            app.SpeedkmphDropDownLabel = uilabel(app.MobilityPanel);
            app.SpeedkmphDropDownLabel.Text = 'Speed (kmph)';
            app.SpeedkmphDropDown = uidropdown(app.MobilityPanel);
            app.SpeedkmphDropDown.Items = {'High', 'Medium', 'Low'};
            app.SpeedkmphDropDown.Value = 'Medium';
            app.RangeKmDropDownLabel = uilabel(app.MobilityPanel);
            app.RangeKmDropDownLabel.Text = 'Range (Km)';
            app.RangeKmDropDown = uidropdown(app.MobilityPanel);
            app.RangeKmDropDown.Items = {'High', 'Medium', 'Low'};
            app.RangeKmDropDown.Value = 'Medium';
            app.LengthmDropDownLabel = uilabel(app.MobilityPanel);
            app.LengthmDropDownLabel.Text = 'Length (m)';
            app.LengthmDropDown = uidropdown(app.MobilityPanel);
            app.LengthmDropDown.Items = {'High', 'Medium', 'Low'};
            app.LengthmDropDown.Value = 'Medium';
            app.SurvivabilityPanel = uipanel(app.UIFigure);
            app.SurvivabilityPanel.Title = 'Survivability';
            app.CrewnoDropDownLabel = uilabel(app.SurvivabilityPanel);
            app.CrewnoDropDownLabel.Text = 'Crew (no.)';
            app.CrewnoDropDown = uidropdown(app.SurvivabilityPanel);
            app.CrewnoDropDown.Items = {'High', 'Medium', 'Low'};
            app.CrewnoDropDown.Value = 'Medium';
            app.MassKgDropDownLabel = uilabel(app.SurvivabilityPanel);
            app.MassKgDropDownLabel.Text = 'Mass (Kg)';
            app.MassKgDropDown = uidropdown(app.SurvivabilityPanel);
            app.MassKgDropDown.Items = {'High', 'Medium', 'Low'};
            app.MassKgDropDown.Value = 'Medium';
            app.FuelCapacityLDropDownLabel = uilabel(app.SurvivabilityPanel);
            app.FuelCapacityLDropDownLabel.Text = 'Fuel Capacity (L)';
            app.FuelCapacityLDropDown = uidropdown(app.SurvivabilityPanel);
            app.FuelCapacityLDropDown.Items = {'High', 'Medium', 'Low'};
            app.FuelCapacityLDropDown.Value = 'Medium';
            app.FirepowerPanel = uipanel(app.UIFigure);
            app.FirepowerPanel.Title = 'Firepower';
            app.GunsnoDropDownLabel = uilabel(app.FirepowerPanel);
            app.GunsnoDropDownLabel.Text = 'Guns (no.)';
            app.GunsnoDropDown = uidropdown(app.FirepowerPanel);
            app.GunsnoDropDown.Items = {'High', 'Medium', 'Low'};
            app.GunsnoDropDown.Value = 'Medium';
            app.PowerWeighthptonneDropDownLabel = uilabel(app.FirepowerPanel);
            app.PowerWeighthptonneDropDownLabel.Text = {'Power / Weight'; '(hp / tonne)'};
            app.PowerWeighthptonneDropDown = uidropdown(app.FirepowerPanel);
            app.PowerWeighthptonneDropDown.Items = {'High', 'Medium', 'Low'};
            app.PowerWeighthptonneDropDown.Value = 'Medium';
            app.SCOREofTANKLabel = uilabel(app.UIFigure);
            app.SCOREofTANKLabel.Text = 'SCORE of TANK';
            app.EVALUATEButton = uibutton(app.UIFigure, 'push');
            app.EVALUATEButton.ButtonPushedFcn = createCallbackFcn(app, @EVALUATEButtonPushed, true);
            app.EVALUATEButton.Text = 'EVALUATE';
            app.ScoreofTANKEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreofTANKEditFieldLabel.Text = 'Score of TANK';
            app.ScoreofTANKEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score_tank_dropdown(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end