classdef terrain_effect_on_defense_posture < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        OkButton                        matlab.ui.control.Button
        DefensepostureEditField         matlab.ui.control.NumericEditField
        DefensepostureEditFieldLabel    matlab.ui.control.Label
        TerrainCharacteristicsDropDown  matlab.ui.control.DropDown
        TerrainCharacteristicsDropDownLabel  matlab.ui.control.Label
        TerrainEffectOnDefensePostureLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
        end

        % Value changed function: TerrainCharacteristicsDropDown
        function TerrainCharacteristicsDropDownValueChanged(app, event)
            value = app.TerrainCharacteristicsDropDown.Value;
            if(strcmp(value,"Rugged Heavily -Wooded"))
                app.DefensepostureEditField.Value=1.5;
            elseif(strcmp(value,"Rugged-mixed(extra rugged or bare)"))
                app.DefensepostureEditField.Value=1.55;
            elseif(strcmp(value,"Rugged Bare"))
                app.DefensepostureEditField.Value=1.45;
            elseif(strcmp(value,"Rooling Heavily -Wooded"))
                app.DefensepostureEditField.Value=1.35;
            end
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
             multiplier=app.DefensepostureEditField.Value;
            update_terrain_effect_on_defense_posture_Multiplier(app.CallingApp,multiplier);
            delete(app);  
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TerrainEffectOnDefensePostureLabel = uilabel(app.UIFigure);
            app.TerrainEffectOnDefensePostureLabel.Text = 'Terrain Effect On Defense Posture';
            app.TerrainCharacteristicsDropDownLabel = uilabel(app.UIFigure);
            app.TerrainCharacteristicsDropDownLabel.Text = 'Terrain Characteristics';
            app.TerrainCharacteristicsDropDown = uidropdown(app.UIFigure);
            app.TerrainCharacteristicsDropDown.Items = {'Rugged Heavily -Wooded', 'Rugged-mixed(extra rugged or bare)', 'Rugged Bare', 'Rooling Heavily -Wooded'};
            app.TerrainCharacteristicsDropDown.ValueChangedFcn = createCallbackFcn(app, @TerrainCharacteristicsDropDownValueChanged, true);
            app.TerrainCharacteristicsDropDown.Value = 'Rugged Heavily -Wooded';
            app.DefensepostureEditFieldLabel = uilabel(app.UIFigure);
            app.DefensepostureEditFieldLabel.Text = 'Defense posture';
            app.DefensepostureEditField = uieditfield(app.UIFigure, 'numeric');
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = terrain_effect_on_defense_posture(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end