classdef whict_unit_to_move < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        OKButton              matlab.ui.control.Button
        UnitId                matlab.ui.control.DropDown
        UnitIdDropDownLabel   matlab.ui.control.Label
        WhichUnitToMoveLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        CallingApp;
        DialogApp; % Description
        x;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp=mainapp;
            
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            update_id(app.CallingApp,app.x)
            delete(app)
        end

        % Value changed function: UnitId
        function UnitIdValueChanged(app, event)
            value = app.UnitId.Value;
             if(strcmp(value,'124'))
                 app.x=124;
             elseif(strcmp(value,'125'))
                 app.x=125;
             elseif(strcmp(value,'126'))
                 app.x=126; 
             elseif(strcmp(value,'138'))
                 app.x=138; 
             elseif(strcmp(value,'139'))
                 app.x=139;
             elseif(strcmp(value,'140'))
                 app.x=140;
             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WhichUnitToMoveLabel = uilabel(app.UIFigure);
            app.WhichUnitToMoveLabel.Text = 'Which Unit To Move';
            app.UnitIdDropDownLabel = uilabel(app.UIFigure);
            app.UnitIdDropDownLabel.Text = 'Unit Id';
            app.UnitId = uidropdown(app.UIFigure);
            app.UnitId.Items = {'124', '125', '126', '138', '139', '140'};
            app.UnitId.ValueChangedFcn = createCallbackFcn(app, @UnitIdValueChanged, true);
            app.UnitId.Value = '124';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = whict_unit_to_move(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end